classdef App_v16 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FEMUIFigure                     matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        FESettingsTab                   matlab.ui.container.Tab
        SingleslopePanel                matlab.ui.container.Panel
        NumberofnodesLabel              matlab.ui.control.Label
        NumberofnodesEditField          matlab.ui.control.NumericEditField
        NumberofstepsLabel              matlab.ui.control.Label
        NumberofstepsEditField          matlab.ui.control.NumericEditField
        TotaltimehrLabel                matlab.ui.control.Label
        TotaltimehrEditField            matlab.ui.control.NumericEditField
        NumberofoutputsEditFieldLabel   matlab.ui.control.Label
        NumberofoutputsEditField        matlab.ui.control.NumericEditField
        NodesEditField                  matlab.ui.control.NumericEditField
        LocationButton                  matlab.ui.control.Button
        EditField_6                     matlab.ui.control.NumericEditField
        Image3                          matlab.ui.control.Image
        NumberofobservationnodesLabel   matlab.ui.control.Label
        NumberofobservationnodesKnob    matlab.ui.control.DiscreteKnob
        NodesLabel                      matlab.ui.control.Label
        NodesEditField_2                matlab.ui.control.NumericEditField
        NodesEditField_3                matlab.ui.control.NumericEditField
        NodesEditField_4                matlab.ui.control.NumericEditField
        NodesEditField_5                matlab.ui.control.NumericEditField
        EditField_17                    matlab.ui.control.NumericEditField
        EditField_18                    matlab.ui.control.NumericEditField
        EditField_19                    matlab.ui.control.NumericEditField
        EditField_20                    matlab.ui.control.NumericEditField
        HeightmLabel                    matlab.ui.control.Label
        HeightmEditField                matlab.ui.control.NumericEditField
        DistancetothesurfaceLabel       matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        mLabel_4                        matlab.ui.control.Label
        mLabel_5                        matlab.ui.control.Label
        SlopeangleLabel                 matlab.ui.control.Label
        SlopeangleEditField             matlab.ui.control.NumericEditField
        Image4                          matlab.ui.control.Image
        RegionalPanel                   matlab.ui.container.Panel
        NumberofnodesEditField_2Label   matlab.ui.control.Label
        NumberofnodesEditField_2        matlab.ui.control.NumericEditField
        NumberofstepsEditField_2Label   matlab.ui.control.Label
        NumberofstepsEditField_2        matlab.ui.control.NumericEditField
        TotaltimehrEditField_2Label     matlab.ui.control.Label
        TotaltimehrEditField_2          matlab.ui.control.NumericEditField
        InputfilenamePanel              matlab.ui.container.Panel
        ThicknessButton                 matlab.ui.control.Button
        SlopeButton                     matlab.ui.control.Button
        RockButton                      matlab.ui.control.Button
        EditField_21                    matlab.ui.control.NumericEditField
        EditField_22                    matlab.ui.control.NumericEditField
        toLabel                         matlab.ui.control.Label
        EditField_23                    matlab.ui.control.NumericEditField
        DEMvisualizationOptionalPanel   matlab.ui.container.Panel
        ViewDEMButton                   matlab.ui.control.Button
        ViewDEM                         matlab.ui.control.UIAxes
        Panel_11                        matlab.ui.container.Panel
        Switch_6                        matlab.ui.control.Switch
        LayersTab                       matlab.ui.container.Tab
        LayersettingsPanel              matlab.ui.container.Panel
        CoordinateoflayersinputrequiredPanel  matlab.ui.container.Panel
        FirstlayerEditFieldLabel        matlab.ui.control.Label
        FirstlayerEditField             matlab.ui.control.NumericEditField
        SecondlayerEditFieldLabel       matlab.ui.control.Label
        SecondlayerEditField            matlab.ui.control.NumericEditField
        ThirdlayerEditFieldLabel        matlab.ui.control.Label
        ThirdlayerEditField             matlab.ui.control.NumericEditField
        FourthlayerEditFieldLabel       matlab.ui.control.Label
        FourthlayerEditField            matlab.ui.control.NumericEditField
        FifthlayerEditFieldLabel        matlab.ui.control.Label
        FifthlayerEditField             matlab.ui.control.NumericEditField
        HeightLabel                     matlab.ui.control.Label
        HeightLabel_2                   matlab.ui.control.Label
        HeightLabel_3                   matlab.ui.control.Label
        HeightLabel_4                   matlab.ui.control.Label
        HeightLabel_5                   matlab.ui.control.Label
        NumberoflayerKnobLabel          matlab.ui.control.Label
        NumberoflayerKnob               matlab.ui.control.DiscreteKnob
        GeometryPanel                   matlab.ui.container.Panel
        Image8                          matlab.ui.control.Image
        Image9                          matlab.ui.control.Image
        Image10                         matlab.ui.control.Image
        Image11                         matlab.ui.control.Image
        Image12                         matlab.ui.control.Image
        HomogeneoussoilbydefautLabel    matlab.ui.control.Label
        MaterialsTab                    matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        HydraulicsTab                   matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        stPanel                         matlab.ui.container.Panel
        VGmodelPanel                    matlab.ui.container.Panel
        theta_resEditFieldLabel         matlab.ui.control.Label
        theta_resEditField              matlab.ui.control.NumericEditField
        theta_satEditFieldLabel         matlab.ui.control.Label
        theta_satEditField              matlab.ui.control.NumericEditField
        K_satEditFieldLabel             matlab.ui.control.Label
        K_satEditField                  matlab.ui.control.NumericEditField
        alpha_SWCCEditFieldLabel        matlab.ui.control.Label
        alpha_SWCCEditField             matlab.ui.control.NumericEditField
        alpha_HCFEditFieldLabel         matlab.ui.control.Label
        alpha_HCFEditField              matlab.ui.control.NumericEditField
        n_SWCCEditFieldLabel            matlab.ui.control.Label
        n_SWCCEditField                 matlab.ui.control.NumericEditField
        n_HCFEditFieldLabel             matlab.ui.control.Label
        n_HCFEditField                  matlab.ui.control.NumericEditField
        m_SWCCEditFieldLabel            matlab.ui.control.Label
        m_SWCCEditField                 matlab.ui.control.NumericEditField
        m_HCFEditFieldLabel             matlab.ui.control.Label
        m_HCFEditField                  matlab.ui.control.NumericEditField
        Image7                          matlab.ui.control.Image
        BCmodelPanel                    matlab.ui.container.Panel
        theta_resEditField_2Label       matlab.ui.control.Label
        theta_resEditField_2            matlab.ui.control.NumericEditField
        theta_satEditField_2Label       matlab.ui.control.Label
        theta_satEditField_2            matlab.ui.control.NumericEditField
        K_satEditField_2Label           matlab.ui.control.Label
        K_satEditField_2                matlab.ui.control.NumericEditField
        lambdaEditFieldLabel            matlab.ui.control.Label
        lambdaEditField                 matlab.ui.control.NumericEditField
        hsEditFieldLabel                matlab.ui.control.Label
        hsEditField                     matlab.ui.control.NumericEditField
        Image5                          matlab.ui.control.Image
        GardnermodelPanel               matlab.ui.container.Panel
        theta_resEditField_3Label       matlab.ui.control.Label
        theta_resEditField_3            matlab.ui.control.NumericEditField
        theta_satEditField_3Label       matlab.ui.control.Label
        theta_satEditField_3            matlab.ui.control.NumericEditField
        K_satEditField_3Label           matlab.ui.control.Label
        K_satEditField_3                matlab.ui.control.NumericEditField
        alpha_SWCCEditField_2Label      matlab.ui.control.Label
        alpha_SWCCEditField_2           matlab.ui.control.NumericEditField
        alpha_HCFEditField_2Label       matlab.ui.control.Label
        alpha_HCFEditField_2            matlab.ui.control.NumericEditField
        Image6                          matlab.ui.control.Image
        HydraulicmodelDropDownLabel     matlab.ui.control.Label
        HydraulicmodelDropDown          matlab.ui.control.DropDown
        Image14                         matlab.ui.control.Image
        Image15                         matlab.ui.control.Image
        Image20                         matlab.ui.control.Image
        Image17                         matlab.ui.control.Image
        Image24                         matlab.ui.control.Image
        ndPanel                         matlab.ui.container.Panel
        VGmodelPanel_2                  matlab.ui.container.Panel
        theta_resEditField_4Label       matlab.ui.control.Label
        theta_resEditField_4            matlab.ui.control.NumericEditField
        theta_satEditField_4Label       matlab.ui.control.Label
        theta_satEditField_4            matlab.ui.control.NumericEditField
        K_satEditField_4Label           matlab.ui.control.Label
        K_satEditField_4                matlab.ui.control.NumericEditField
        alpha_SWCCEditField_3Label      matlab.ui.control.Label
        alpha_SWCCEditField_3           matlab.ui.control.NumericEditField
        alpha_HCFEditField_3Label       matlab.ui.control.Label
        alpha_HCFEditField_3            matlab.ui.control.NumericEditField
        n_SWCCEditField_2Label          matlab.ui.control.Label
        n_SWCCEditField_2               matlab.ui.control.NumericEditField
        n_HCFEditField_2Label           matlab.ui.control.Label
        n_HCFEditField_2                matlab.ui.control.NumericEditField
        m_SWCCEditField_2Label          matlab.ui.control.Label
        m_SWCCEditField_2               matlab.ui.control.NumericEditField
        m_HCFEditField_2Label           matlab.ui.control.Label
        m_HCFEditField_2                matlab.ui.control.NumericEditField
        Image7_2                        matlab.ui.control.Image
        BCmodelPanel_2                  matlab.ui.container.Panel
        theta_resEditField_5Label       matlab.ui.control.Label
        theta_resEditField_5            matlab.ui.control.NumericEditField
        theta_satEditField_5Label       matlab.ui.control.Label
        theta_satEditField_5            matlab.ui.control.NumericEditField
        K_satEditField_5Label           matlab.ui.control.Label
        K_satEditField_5                matlab.ui.control.NumericEditField
        lambdaEditField_2Label          matlab.ui.control.Label
        lambdaEditField_2               matlab.ui.control.NumericEditField
        hsEditField_2Label              matlab.ui.control.Label
        hsEditField_2                   matlab.ui.control.NumericEditField
        Image5_2                        matlab.ui.control.Image
        GardnermodelPanel_2             matlab.ui.container.Panel
        theta_resEditField_6Label       matlab.ui.control.Label
        theta_resEditField_6            matlab.ui.control.NumericEditField
        theta_satEditField_6Label       matlab.ui.control.Label
        theta_satEditField_6            matlab.ui.control.NumericEditField
        K_satEditField_6Label           matlab.ui.control.Label
        K_satEditField_6                matlab.ui.control.NumericEditField
        alpha_SWCCEditField_4Label      matlab.ui.control.Label
        alpha_SWCCEditField_4           matlab.ui.control.NumericEditField
        alpha_HCFEditField_4Label       matlab.ui.control.Label
        alpha_HCFEditField_4            matlab.ui.control.NumericEditField
        Image6_2                        matlab.ui.control.Image
        HydraulicmodelDropDown_2Label   matlab.ui.control.Label
        HydraulicmodelDropDown_2        matlab.ui.control.DropDown
        Image16                         matlab.ui.control.Image
        Image18                         matlab.ui.control.Image
        Image21                         matlab.ui.control.Image
        Image25                         matlab.ui.control.Image
        thPanel                         matlab.ui.container.Panel
        VGmodelPanel_4                  matlab.ui.container.Panel
        theta_resEditField_10Label      matlab.ui.control.Label
        theta_resEditField_10           matlab.ui.control.NumericEditField
        theta_satEditField_10Label      matlab.ui.control.Label
        theta_satEditField_10           matlab.ui.control.NumericEditField
        K_satEditField_10Label          matlab.ui.control.Label
        K_satEditField_10               matlab.ui.control.NumericEditField
        alpha_SWCCEditField_7Label      matlab.ui.control.Label
        alpha_SWCCEditField_7           matlab.ui.control.NumericEditField
        alpha_HCFEditField_7Label       matlab.ui.control.Label
        alpha_HCFEditField_7            matlab.ui.control.NumericEditField
        n_SWCCEditField_4Label          matlab.ui.control.Label
        n_SWCCEditField_4               matlab.ui.control.NumericEditField
        n_HCFEditField_4Label           matlab.ui.control.Label
        n_HCFEditField_4                matlab.ui.control.NumericEditField
        m_SWCCEditField_4Label          matlab.ui.control.Label
        m_SWCCEditField_4               matlab.ui.control.NumericEditField
        m_HCFEditField_4Label           matlab.ui.control.Label
        m_HCFEditField_4                matlab.ui.control.NumericEditField
        Image7_4                        matlab.ui.control.Image
        BCmodelPanel_4                  matlab.ui.container.Panel
        theta_resEditField_11Label      matlab.ui.control.Label
        theta_resEditField_11           matlab.ui.control.NumericEditField
        theta_satEditField_11Label      matlab.ui.control.Label
        theta_satEditField_11           matlab.ui.control.NumericEditField
        K_satEditField_11Label          matlab.ui.control.Label
        K_satEditField_11               matlab.ui.control.NumericEditField
        lambdaEditField_4Label          matlab.ui.control.Label
        lambdaEditField_4               matlab.ui.control.NumericEditField
        hsEditField_4Label              matlab.ui.control.Label
        hsEditField_4                   matlab.ui.control.NumericEditField
        Image5_4                        matlab.ui.control.Image
        GardnermodelPanel_4             matlab.ui.container.Panel
        theta_resEditField_12Label      matlab.ui.control.Label
        theta_resEditField_12           matlab.ui.control.NumericEditField
        theta_satEditField_12Label      matlab.ui.control.Label
        theta_satEditField_12           matlab.ui.control.NumericEditField
        K_satEditField_12Label          matlab.ui.control.Label
        K_satEditField_12               matlab.ui.control.NumericEditField
        alpha_SWCCEditField_8Label      matlab.ui.control.Label
        alpha_SWCCEditField_8           matlab.ui.control.NumericEditField
        alpha_HCFEditField_8Label       matlab.ui.control.Label
        alpha_HCFEditField_8            matlab.ui.control.NumericEditField
        Image6_4                        matlab.ui.control.Image
        HydraulicmodelDropDown_4Label   matlab.ui.control.Label
        HydraulicmodelDropDown_4        matlab.ui.control.DropDown
        Image23                         matlab.ui.control.Image
        Image27                         matlab.ui.control.Image
        thPanel_2                       matlab.ui.container.Panel
        VGmodelPanel_5                  matlab.ui.container.Panel
        theta_resEditField_13Label      matlab.ui.control.Label
        theta_resEditField_13           matlab.ui.control.NumericEditField
        theta_satEditField_13Label      matlab.ui.control.Label
        theta_satEditField_13           matlab.ui.control.NumericEditField
        K_satEditField_13Label          matlab.ui.control.Label
        K_satEditField_13               matlab.ui.control.NumericEditField
        alpha_SWCCEditField_9Label      matlab.ui.control.Label
        alpha_SWCCEditField_9           matlab.ui.control.NumericEditField
        alpha_HCFEditField_9Label       matlab.ui.control.Label
        alpha_HCFEditField_9            matlab.ui.control.NumericEditField
        n_SWCCEditField_5Label          matlab.ui.control.Label
        n_SWCCEditField_5               matlab.ui.control.NumericEditField
        n_HCFEditField_5Label           matlab.ui.control.Label
        n_HCFEditField_5                matlab.ui.control.NumericEditField
        m_SWCCEditField_5Label          matlab.ui.control.Label
        m_SWCCEditField_5               matlab.ui.control.NumericEditField
        m_HCFEditField_5Label           matlab.ui.control.Label
        m_HCFEditField_5                matlab.ui.control.NumericEditField
        Image7_5                        matlab.ui.control.Image
        BCmodelPanel_5                  matlab.ui.container.Panel
        theta_resEditField_14Label      matlab.ui.control.Label
        theta_resEditField_14           matlab.ui.control.NumericEditField
        theta_satEditField_14Label      matlab.ui.control.Label
        theta_satEditField_14           matlab.ui.control.NumericEditField
        K_satEditField_14Label          matlab.ui.control.Label
        K_satEditField_14               matlab.ui.control.NumericEditField
        lambdaEditField_5Label          matlab.ui.control.Label
        lambdaEditField_5               matlab.ui.control.NumericEditField
        hsEditField_5Label              matlab.ui.control.Label
        hsEditField_5                   matlab.ui.control.NumericEditField
        Image5_5                        matlab.ui.control.Image
        GardnermodelPanel_5             matlab.ui.container.Panel
        theta_resEditField_15Label      matlab.ui.control.Label
        theta_resEditField_15           matlab.ui.control.NumericEditField
        theta_satEditField_15Label      matlab.ui.control.Label
        theta_satEditField_15           matlab.ui.control.NumericEditField
        K_satEditField_15Label          matlab.ui.control.Label
        K_satEditField_15               matlab.ui.control.NumericEditField
        alpha_SWCCEditField_10Label     matlab.ui.control.Label
        alpha_SWCCEditField_10          matlab.ui.control.NumericEditField
        alpha_HCFEditField_10Label      matlab.ui.control.Label
        alpha_HCFEditField_10           matlab.ui.control.NumericEditField
        Image6_5                        matlab.ui.control.Image
        HydraulicmodelDropDown_5Label   matlab.ui.control.Label
        HydraulicmodelDropDown_5        matlab.ui.control.DropDown
        Image28                         matlab.ui.control.Image
        rdPanel                         matlab.ui.container.Panel
        VGmodelPanel_3                  matlab.ui.container.Panel
        theta_resEditField_7Label       matlab.ui.control.Label
        theta_resEditField_7            matlab.ui.control.NumericEditField
        theta_satEditField_7Label       matlab.ui.control.Label
        theta_satEditField_7            matlab.ui.control.NumericEditField
        K_satEditField_7Label           matlab.ui.control.Label
        K_satEditField_7                matlab.ui.control.NumericEditField
        alpha_SWCCEditField_5Label      matlab.ui.control.Label
        alpha_SWCCEditField_5           matlab.ui.control.NumericEditField
        alpha_HCFEditField_5Label       matlab.ui.control.Label
        alpha_HCFEditField_5            matlab.ui.control.NumericEditField
        n_SWCCEditField_3Label          matlab.ui.control.Label
        n_SWCCEditField_3               matlab.ui.control.NumericEditField
        n_HCFEditField_3Label           matlab.ui.control.Label
        n_HCFEditField_3                matlab.ui.control.NumericEditField
        m_SWCCEditField_3Label          matlab.ui.control.Label
        m_SWCCEditField_3               matlab.ui.control.NumericEditField
        m_HCFEditField_3Label           matlab.ui.control.Label
        m_HCFEditField_3                matlab.ui.control.NumericEditField
        Image7_3                        matlab.ui.control.Image
        BCmodelPanel_3                  matlab.ui.container.Panel
        theta_resEditField_8Label       matlab.ui.control.Label
        theta_resEditField_8            matlab.ui.control.NumericEditField
        theta_satEditField_8Label       matlab.ui.control.Label
        theta_satEditField_8            matlab.ui.control.NumericEditField
        K_satEditField_8Label           matlab.ui.control.Label
        K_satEditField_8                matlab.ui.control.NumericEditField
        lambdaEditField_3Label          matlab.ui.control.Label
        lambdaEditField_3               matlab.ui.control.NumericEditField
        hsEditField_3Label              matlab.ui.control.Label
        hsEditField_3                   matlab.ui.control.NumericEditField
        Image5_3                        matlab.ui.control.Image
        GardnermodelPanel_3             matlab.ui.container.Panel
        theta_resEditField_9Label       matlab.ui.control.Label
        theta_resEditField_9            matlab.ui.control.NumericEditField
        theta_satEditField_9Label       matlab.ui.control.Label
        theta_satEditField_9            matlab.ui.control.NumericEditField
        K_satEditField_9Label           matlab.ui.control.Label
        K_satEditField_9                matlab.ui.control.NumericEditField
        alpha_SWCCEditField_6Label      matlab.ui.control.Label
        alpha_SWCCEditField_6           matlab.ui.control.NumericEditField
        alpha_HCFEditField_6Label       matlab.ui.control.Label
        alpha_HCFEditField_6            matlab.ui.control.NumericEditField
        Image6_3                        matlab.ui.control.Image
        HydraulicmodelDropDown_3Label   matlab.ui.control.Label
        HydraulicmodelDropDown_3        matlab.ui.control.DropDown
        Image19                         matlab.ui.control.Image
        Image26                         matlab.ui.control.Image
        Image22                         matlab.ui.control.Image
        AssignpropertytolayersButtonGroup  matlab.ui.container.ButtonGroup
        Layer1Button                    matlab.ui.control.RadioButton
        Layer2Button                    matlab.ui.control.RadioButton
        Layer3Button                    matlab.ui.control.RadioButton
        Layer4Button                    matlab.ui.control.RadioButton
        Layer5Button                    matlab.ui.control.RadioButton
        RootWaterUptakeModelPanel       matlab.ui.container.Panel
        h1EditFieldLabel                matlab.ui.control.Label
        h1EditField                     matlab.ui.control.NumericEditField
        h2EditFieldLabel                matlab.ui.control.Label
        h2EditField                     matlab.ui.control.NumericEditField
        h3EditFieldLabel                matlab.ui.control.Label
        h3EditField                     matlab.ui.control.NumericEditField
        h4EditFieldLabel                matlab.ui.control.Label
        h4EditField                     matlab.ui.control.NumericEditField
        d_rEditFieldLabel               matlab.ui.control.Label
        d_rEditField                    matlab.ui.control.NumericEditField
        T_pEditFieldLabel               matlab.ui.control.Label
        T_pEditField                    matlab.ui.control.NumericEditField
        Image13                         matlab.ui.control.Image
        prescribedwaterheadLabel        matlab.ui.control.Label
        Thresholdvaluehw2EditFieldLabel  matlab.ui.control.Label
        Thresholdvaluehw2EditField      matlab.ui.control.NumericEditField
        flowrate                        matlab.ui.control.Switch
        CouplingSwitchLabel_4           matlab.ui.control.Label
        MechanicsTab                    matlab.ui.container.Tab
        Panel_10                        matlab.ui.container.Panel
        AssignpropertytolayersButtonGroup_2  matlab.ui.container.ButtonGroup
        Layer1Button_2                  matlab.ui.control.RadioButton
        Layer2Button_2                  matlab.ui.control.RadioButton
        Layer3Button_2                  matlab.ui.control.RadioButton
        Layer4Button_2                  matlab.ui.control.RadioButton
        Layer5Button_2                  matlab.ui.control.RadioButton
        Image14_2                       matlab.ui.control.Image
        Image15_2                       matlab.ui.control.Image
        Image16_2                       matlab.ui.control.Image
        Image17_2                       matlab.ui.control.Image
        Image18_2                       matlab.ui.control.Image
        Image19_2                       matlab.ui.control.Image
        Image20_2                       matlab.ui.control.Image
        Image21_2                       matlab.ui.control.Image
        Image22_2                       matlab.ui.control.Image
        Image23_2                       matlab.ui.control.Image
        Image24_2                       matlab.ui.control.Image
        Image25_2                       matlab.ui.control.Image
        Image26_2                       matlab.ui.control.Image
        Image27_2                       matlab.ui.control.Image
        Image28_2                       matlab.ui.control.Image
        stPanel_2                       matlab.ui.container.Panel
        StressapproachPanel             matlab.ui.container.Panel
        Switch                          matlab.ui.control.Switch
        EffectivestressparameterButtonGroup  matlab.ui.container.ButtonGroup
        Option1Button                   matlab.ui.control.RadioButton
        Option2Button                   matlab.ui.control.RadioButton
        Option3Button                   matlab.ui.control.RadioButton
        EditField_12                    matlab.ui.control.NumericEditField
        Image29                         matlab.ui.control.Image
        AirentrysuctionSeEditFieldLabel  matlab.ui.control.Label
        AirentrysuctionSeEditField      matlab.ui.control.NumericEditField
        PaLabel_4                       matlab.ui.control.Label
        ElasticmodulusPanel             matlab.ui.container.Panel
        EEditFieldLabel                 matlab.ui.control.Label
        EEditField                      matlab.ui.control.NumericEditField
        GEditFieldLabel                 matlab.ui.control.Label
        GEditField                      matlab.ui.control.NumericEditField
        FEditFieldLabel                 matlab.ui.control.Label
        FEditField                      matlab.ui.control.NumericEditField
        PaLabel                         matlab.ui.control.Label
        PaLabel_2                       matlab.ui.control.Label
        PaLabel_3                       matlab.ui.control.Label
        StrengthparametersPanel         matlab.ui.container.Panel
        cLabel                          matlab.ui.control.Label
        cEditField                      matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        EditField_7                     matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        bEditField                      matlab.ui.control.NumericEditField
        PaLabel_8                       matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        UnitweightofsoilPanel           matlab.ui.container.Panel
        sNm3EditFieldLabel              matlab.ui.control.Label
        sNm3EditField                   matlab.ui.control.NumericEditField
        ndPanel_2                       matlab.ui.container.Panel
        StressapproachPanel_2           matlab.ui.container.Panel
        Switch_2                        matlab.ui.control.Switch
        EffectivestressparameterButtonGroup_2  matlab.ui.container.ButtonGroup
        Option1Button_2                 matlab.ui.control.RadioButton
        Option2Button_2                 matlab.ui.control.RadioButton
        Option3Button_2                 matlab.ui.control.RadioButton
        EditField_13                    matlab.ui.control.NumericEditField
        Image29_2                       matlab.ui.control.Image
        AirentrysuctionSeEditField_2Label  matlab.ui.control.Label
        AirentrysuctionSeEditField_2    matlab.ui.control.NumericEditField
        PaLabel_21                      matlab.ui.control.Label
        ElasticmodulusPanel_2           matlab.ui.container.Panel
        EEditField_2Label               matlab.ui.control.Label
        EEditField_2                    matlab.ui.control.NumericEditField
        GEditField_2Label               matlab.ui.control.Label
        GEditField_2                    matlab.ui.control.NumericEditField
        FEditField_2Label               matlab.ui.control.Label
        FEditField_2                    matlab.ui.control.NumericEditField
        PaLabel_12                      matlab.ui.control.Label
        PaLabel_13                      matlab.ui.control.Label
        PaLabel_14                      matlab.ui.control.Label
        StrengthparametersPanel_2       matlab.ui.container.Panel
        cEditField_2Label               matlab.ui.control.Label
        cEditField_2                    matlab.ui.control.NumericEditField
        EditField_8Label                matlab.ui.control.Label
        EditField_8                     matlab.ui.control.NumericEditField
        bEditField_2Label               matlab.ui.control.Label
        bEditField_2                    matlab.ui.control.NumericEditField
        PaLabel_25                      matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        UnitweightofsoilPanel_2         matlab.ui.container.Panel
        sNm3EditField_2Label            matlab.ui.control.Label
        sNm3EditField_2                 matlab.ui.control.NumericEditField
        rdPanel_2                       matlab.ui.container.Panel
        StressapproachPanel_3           matlab.ui.container.Panel
        Switch_3                        matlab.ui.control.Switch
        EffectivestressparameterButtonGroup_3  matlab.ui.container.ButtonGroup
        Option1Button_3                 matlab.ui.control.RadioButton
        Option2Button_3                 matlab.ui.control.RadioButton
        Option3Button_3                 matlab.ui.control.RadioButton
        EditField_14                    matlab.ui.control.NumericEditField
        Image29_3                       matlab.ui.control.Image
        AirentrysuctionSeEditField_3Label  matlab.ui.control.Label
        AirentrysuctionSeEditField_3    matlab.ui.control.NumericEditField
        PaLabel_22                      matlab.ui.control.Label
        ElasticmodulusPanel_3           matlab.ui.container.Panel
        EEditField_3Label               matlab.ui.control.Label
        EEditField_3                    matlab.ui.control.NumericEditField
        GEditField_3Label               matlab.ui.control.Label
        GEditField_3                    matlab.ui.control.NumericEditField
        FEditField_3Label               matlab.ui.control.Label
        FEditField_3                    matlab.ui.control.NumericEditField
        PaLabel_15                      matlab.ui.control.Label
        PaLabel_16                      matlab.ui.control.Label
        PaLabel_17                      matlab.ui.control.Label
        StrengthparametersPanel_3       matlab.ui.container.Panel
        cEditField_3Label               matlab.ui.control.Label
        cEditField_3                    matlab.ui.control.NumericEditField
        EditField_9Label                matlab.ui.control.Label
        EditField_9                     matlab.ui.control.NumericEditField
        bEditField_3Label               matlab.ui.control.Label
        bEditField_3                    matlab.ui.control.NumericEditField
        PaLabel_26                      matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        UnitweightofsoilPanel_3         matlab.ui.container.Panel
        sNm3EditField_3Label            matlab.ui.control.Label
        sNm3EditField_3                 matlab.ui.control.NumericEditField
        thPanel_3                       matlab.ui.container.Panel
        StressapproachPanel_4           matlab.ui.container.Panel
        Switch_4                        matlab.ui.control.Switch
        EffectivestressparameterButtonGroup_4  matlab.ui.container.ButtonGroup
        Option1Button_4                 matlab.ui.control.RadioButton
        Option2Button_4                 matlab.ui.control.RadioButton
        Option3Button_4                 matlab.ui.control.RadioButton
        EditField_15                    matlab.ui.control.NumericEditField
        Image29_4                       matlab.ui.control.Image
        AirentrysuctionSeEditField_4Label  matlab.ui.control.Label
        AirentrysuctionSeEditField_4    matlab.ui.control.NumericEditField
        PaLabel_23                      matlab.ui.control.Label
        ElasticmodulusPanel_4           matlab.ui.container.Panel
        EEditField_4Label               matlab.ui.control.Label
        EEditField_4                    matlab.ui.control.NumericEditField
        GEditField_4Label               matlab.ui.control.Label
        GEditField_4                    matlab.ui.control.NumericEditField
        FEditField_4Label               matlab.ui.control.Label
        FEditField_4                    matlab.ui.control.NumericEditField
        PaLabel_18                      matlab.ui.control.Label
        PaLabel_19                      matlab.ui.control.Label
        PaLabel_20                      matlab.ui.control.Label
        StrengthparametersPanel_4       matlab.ui.container.Panel
        cEditField_4Label               matlab.ui.control.Label
        cEditField_4                    matlab.ui.control.NumericEditField
        EditField_10Label               matlab.ui.control.Label
        EditField_10                    matlab.ui.control.NumericEditField
        bEditField_4Label               matlab.ui.control.Label
        bEditField_4                    matlab.ui.control.NumericEditField
        PaLabel_27                      matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        UnitweightofsoilPanel_4         matlab.ui.container.Panel
        sNm3EditField_4Label            matlab.ui.control.Label
        sNm3EditField_4                 matlab.ui.control.NumericEditField
        thPanel_4                       matlab.ui.container.Panel
        StressapproachPanel_5           matlab.ui.container.Panel
        Switch_5                        matlab.ui.control.Switch
        EffectivestressparameterButtonGroup_5  matlab.ui.container.ButtonGroup
        Option1Button_5                 matlab.ui.control.RadioButton
        Option2Button_5                 matlab.ui.control.RadioButton
        Option3Button_5                 matlab.ui.control.RadioButton
        EditField_16                    matlab.ui.control.NumericEditField
        Image29_5                       matlab.ui.control.Image
        AirentrysuctionSeEditField_5Label  matlab.ui.control.Label
        AirentrysuctionSeEditField_5    matlab.ui.control.NumericEditField
        PaLabel_24                      matlab.ui.control.Label
        ElasticmodulusPanel_5           matlab.ui.container.Panel
        EEditField_5Label               matlab.ui.control.Label
        EEditField_5                    matlab.ui.control.NumericEditField
        GEditField_5Label               matlab.ui.control.Label
        GEditField_5                    matlab.ui.control.NumericEditField
        FEditField_5Label               matlab.ui.control.Label
        FEditField_5                    matlab.ui.control.NumericEditField
        PaLabel_9                       matlab.ui.control.Label
        PaLabel_10                      matlab.ui.control.Label
        PaLabel_11                      matlab.ui.control.Label
        StrengthparametersPanel_5       matlab.ui.container.Panel
        cEditField_5Label               matlab.ui.control.Label
        cEditField_5                    matlab.ui.control.NumericEditField
        EditField_11Label               matlab.ui.control.Label
        EditField_11                    matlab.ui.control.NumericEditField
        bEditField_5Label               matlab.ui.control.Label
        bEditField_5                    matlab.ui.control.NumericEditField
        PaLabel_28                      matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        UnitweightofsoilPanel_5         matlab.ui.container.Panel
        sNm3EditField_5Label            matlab.ui.control.Label
        sNm3EditField_5                 matlab.ui.control.NumericEditField
        SimulationConditionsTab         matlab.ui.container.Tab
        InitialconditionwaterheadprofileButtonGroup  matlab.ui.container.ButtonGroup
        FunctionButton                  matlab.ui.control.RadioButton
        ConstantButton                  matlab.ui.control.RadioButton
        constantEditField               matlab.ui.control.EditField
        functionEditField               matlab.ui.control.EditField
        ViewinitialconditonPanel        matlab.ui.container.Panel
        PlotInitialButton               matlab.ui.control.Button
        UIInitial                       matlab.ui.control.UIAxes
        FlowBCButtonGroup               matlab.ui.container.ButtonGroup
        ButtonGroup                     matlab.ui.container.ButtonGroup
        FluxonthetopButton              matlab.ui.control.RadioButton
        PressureonthetopButton          matlab.ui.control.RadioButton
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        FluxinthebottomButton           matlab.ui.control.RadioButton
        PressureinthebottomButton       matlab.ui.control.RadioButton
        Image30                         matlab.ui.control.Image
        Image31                         matlab.ui.control.Image
        prescribedwaterheadhw1EditFieldLabel  matlab.ui.control.Label
        prescribedwaterheadhw1EditField  matlab.ui.control.NumericEditField
        StressBCPanel                   matlab.ui.container.Panel
        Tree                            matlab.ui.container.Tree
        HydraulogicloadingNode          matlab.ui.container.TreeNode
        Constant_WNode                  matlab.ui.container.TreeNode
        Timehistory_WNode               matlab.ui.container.TreeNode
        NormalstressNode                matlab.ui.container.TreeNode
        Constant_VNode                  matlab.ui.container.TreeNode
        Timehistory_VNode               matlab.ui.container.TreeNode
        TangentialstressNode            matlab.ui.container.TreeNode
        Constant_HNode                  matlab.ui.container.TreeNode
        Timehistory_HNode_2             matlab.ui.container.TreeNode
        LoadingtypeLabel                matlab.ui.control.Label
        InputloadingLabel               matlab.ui.control.Label
        ConstantloadingPanel            matlab.ui.container.Panel
        RainfallIntensitymsEditField_3Label  matlab.ui.control.Label
        RainfallIntensitymsEditField_2  matlab.ui.control.NumericEditField
        NormalStresskPaEditField_2Label  matlab.ui.control.Label
        NormalStresskPaEditField        matlab.ui.control.NumericEditField
        ShearStresskPaEditField_2Label  matlab.ui.control.Label
        ShearStresskPaEditField         matlab.ui.control.NumericEditField
        TimehistoryloadingPanel         matlab.ui.container.Panel
        LoadtimehistdataButton_2        matlab.ui.control.StateButton
        RI_Table2                       matlab.ui.control.Table
        ResultsTab                      matlab.ui.container.Tab
        SingleslopePanel_2              matlab.ui.container.Panel
        CouplingSwitchLabel             matlab.ui.control.Label
        Tree2                           matlab.ui.container.Tree
        LoadNode                        matlab.ui.container.TreeNode
        ProfilevariationNode            matlab.ui.container.TreeNode
        TimehistoryNode                 matlab.ui.container.TreeNode
        FactorofsafetyNode              matlab.ui.container.TreeNode
        CouplingSwitch                  matlab.ui.control.Switch
        Panel_13                        matlab.ui.container.Panel
        PlotButton                      matlab.ui.control.Button
        VariablesDropDown_3             matlab.ui.control.DropDown
        VariablesDropDown_4             matlab.ui.control.DropDown
        VariablesDropDown_5             matlab.ui.control.DropDown
        VariablesDropDown_6             matlab.ui.control.DropDown
        VariablesDropDownLabel          matlab.ui.control.Label
        VariablesDropDown               matlab.ui.control.DropDown
        Time_hist                       matlab.ui.control.UIAxes
        ClearfigureButton_3             matlab.ui.control.Button
        SaveresultsasmfileButton        matlab.ui.control.Button
        Panel_14                        matlab.ui.container.Panel
        FS                              matlab.ui.control.UIAxes
        PlotButtonFS_SLIP               matlab.ui.control.Button
        ClearfigureButton_2             matlab.ui.control.Button
        Panel_15                        matlab.ui.container.Panel
        PlotButton_2                    matlab.ui.control.Button
        Rainfall                        matlab.ui.control.UIAxes
        Panel_16                        matlab.ui.container.Panel
        Profile_variation               matlab.ui.control.UIAxes
        PlotButton_3                    matlab.ui.control.Button
        VariablesDropDown_2Label        matlab.ui.control.Label
        VariablesDropDown_2             matlab.ui.control.DropDown
        ClearfigureButton               matlab.ui.control.Button
        Panel_17                        matlab.ui.container.Panel
        LoaddataButton                  matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        XDropDownLabel                  matlab.ui.control.Label
        XDropDown                       matlab.ui.control.DropDown
        YDropDownLabel                  matlab.ui.control.Label
        YDropDown                       matlab.ui.control.DropDown
        FiguresDropDown                 matlab.ui.control.DropDown
        ClicktostartButton              matlab.ui.control.Button
        LoadresultsButton_2             matlab.ui.control.Button
        TerminateButton                 matlab.ui.control.Button
        CouplingSwitchLabel_3           matlab.ui.control.Label
        vegetation                      matlab.ui.control.Switch
        RegionalPanel_2                 matlab.ui.container.Panel
        DEM                             matlab.ui.control.UIAxes
        Panel_18                        matlab.ui.container.Panel
        ClicktostartButton_2            matlab.ui.control.Button
        LoadresultsButton               matlab.ui.control.Button
        SaveresultsButton               matlab.ui.control.Button
        Panel_19                        matlab.ui.container.Panel
        TransfertorasterfileButton      matlab.ui.control.Button
        ExtractheaderButton             matlab.ui.control.Button
        ConvertasciitorasterLabel       matlab.ui.control.Label
        FileDropDownLabel               matlab.ui.control.Label
        FileDropDown                    matlab.ui.control.DropDown
        Panel_20                        matlab.ui.container.Panel
        LoadDEMButton                   matlab.ui.control.Button
        LoadsourceareaButton            matlab.ui.control.Button
        LoadFailuretimeButton           matlab.ui.control.Button
        LoadFailuredepthButton          matlab.ui.control.Button
        LoadgeorasterLabel              matlab.ui.control.Label
        LoadCumu_IRButton               matlab.ui.control.Button
        LoadCumu_RunoffButton           matlab.ui.control.Button
        ViewmapButton                   matlab.ui.control.Button
        CalculationLabel                matlab.ui.control.Label
        VisualizationLabel              matlab.ui.control.Label
        DropDownLabel                   matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        ClearmapButton                  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        thickness_re
        slope_re
        property_re
        geom_nodes
        geom_nodes_re
        flag_slope
        val
        CC     % coupling_coeff
       
        FE_data
        RESULTS
        num_steps
        num_steps_re
        output_times        
        time_final
        time_final_re        
        i_q
        i_py
        i_px
        IC_index              %%%%IC func
        BC_top_flag
        BC_bottom_flag
        BC_flag        
        flag_stress
        flag_chi       
        flag_hydro_1
        flag_hydro_2
        flag_hydro_3
        flag_hydro_4
        flag_hydro_5
        flag_mech
        n_layer
        chi
        VG   
        GN  
        BC
    
        EM      % Elastic modulus
        SP      % Strength parameter
        gamma_s
        Se      % Air entry suction
          
        Loading_Index
        q_r
        q_px
        q_py
        
        flow_flag
        prof_flag
        hist_flag
        ST_flag
        ST
        FS_flag
        flag_node
        resutls_re
        resutls_sin
        Depth_f
        Time_f        
        IR_re
        Run_re
        Accu_Run_re
        Accu_IR_re
        transtoraster_flag
        map_flag
        headername
        
        X_DEM
        Y_DEM
        Z_DEM
        
        X_Poly
        Y_Poly 
        Z_Poly 
        
        X_FT  %%% failure time
        Y_FT
        Z_FT    

        X_FD  %%% failure depth
        Y_FD
        Z_FD      
        
        zz_cumu_run 
        R1_cumu_run    
        
        zz_cumu_IR 
        R1_cumu_IR 
        
        results_filepath_re
    end


    methods (Access = private)
        
        function  enableForm1(app)
            % Enable form elements
            app.RainfallIntensitymsEditField_2.Enable = 'on';
            app.NormalStresskPaEditField.Enable = 'on';
            app.ShearStresskPaEditField.Enable = 'on';
            

        end
        
        function  disableForm1(app)
            % Set form fields to default values
            app.RainfallIntensitymsEditField_2.Value = 0;
            app.NormalStresskPaEditField.Value = 0;
            app.ShearStresskPaEditField.Value = 0;
            
            % Disable form fields
            app.RainfallIntensitymsEditField_2.Enable = 'off';
            app.NormalStresskPaEditField.Enable = 'off';
            app.ShearStresskPaEditField.Enable = 'off';

            
        end
        function  enableForm2(app)
            
            app.LoadtimehistdataButton_2.Enable = 'on';
            app.RI_Table2.Enable = 'on';
        end
        
        function  disableForm2(app)
            
            app.LoadtimehistdataButton_2.Enable = 'off';
            app.RI_Table2.Enable = 'off';
            
        end

      end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

%               screenSize = get(groot,'ScreenSize');
%               screenWidth = screenSize(3);
%               screenHeight = screenSize(4);
%               left = screenWidth*0.1;
%               bottom = screenHeight*0.1;
%               width = screenWidth*0.8;
%               height = screenHeight*0.8;
%               drawnow;
%               app.FEMUIFigure.Position = [left bottom width height]; 
         
        
        app.flag_node =1;
        app.n_layer =1;
                    
             app.FirstlayerEditField.Value = 1;
             app.SecondlayerEditField.Value = 0;
             app.ThirdlayerEditField.Value = 0;
             app.FourthlayerEditField.Value = 0;
             app.FifthlayerEditField.Value = 0;                    
                
                
                app.Loading_Index = 1;
                app.flag_slope = 1;             
                
                app.SingleslopePanel_2.Visible = 'on';
                app.RegionalPanel_2.Visible = 'off';  
                
      %%%%%%%%%%%%%%%%%%%Disable regional part%%%%%%%%%%%%%%%              
                    app.NumberofnodesEditField_2.Enable = 'off';
                    app.NumberofstepsEditField_2.Enable = 'off'; 
                    app.ThicknessButton.Enable = 'off';
                    app.SlopeButton.Enable = 'off';                    
                    app.RockButton.Enable = 'off';
                    app.ViewDEMButton.Enable = 'off'; 
                    app.TotaltimehrEditField_2.Enable = 'off';
                    app.EditField_21.Enable = 'off';
                    app.EditField_22.Enable = 'off';
                    app.EditField_23.Enable = 'off';                 
      %%%%%%%%%%%%%%%%%%%Enable single slope part%%%%%%%%%%%%%%%              
                    app.NumberofnodesEditField.Enable = 'on';
                    app.NumberofstepsEditField.Enable = 'on'; 
                    app.TotaltimehrEditField.Enable = 'on';
                    app.NumberofoutputsEditField.Enable = 'on';                    
                    app.NodesEditField.Enable = 'on';
                    app.LocationButton.Enable = 'on'; 
                    app.EditField_6.Enable = 'on';   
                    app.Image3.Enable = 'on';
                    app.NumberofobservationnodesKnob.Enable = 'on'; 
                    app.NodesLabel.Enable = 'on';
                    app.NodesEditField_2.Enable = 'on';                    
                    app.NodesEditField_3.Enable = 'on';
                    app.NodesEditField_4.Enable = 'on'; 
                    app.NodesEditField_5.Enable = 'on';                      
                    app.EditField_17.Enable = 'on';
                    app.EditField_18.Enable = 'on'; 
                    app.EditField_19.Enable = 'on';
                    app.EditField_20.Enable = 'on';                    
                    app.HeightmEditField.Enable = 'on';
                    app.DistancetothesurfaceLabel.Enable = 'on';   
                                  
                
                app.NodesEditField.Visible = "on";   	    
                app.NodesEditField_2.Visible = "off";
                app.NodesEditField_3.Visible = "off";
                app.NodesEditField_4.Visible = "off";
                app.NodesEditField_5.Visible = "off";   
                
                app.mLabel.Visible = "on";
                app.mLabel_2.Visible = "off";
                app.mLabel_3.Visible = "off";                
                app.mLabel_4.Visible = "off" ;               
                app.mLabel_5.Visible = "off";  
                
                app.EditField_6.Visible = "on";   	    
                app.EditField_17.Visible = "off";
                app.EditField_18.Visible = "off";
                app.EditField_19.Visible = "off";
                app.EditField_20.Visible = "off";             
            
        
            app.SecondlayerEditField.Enable = "off";
            app.SecondlayerEditFieldLabel.Enable = "off";
            app.ThirdlayerEditFieldLabel.Enable = "off";
            app.ThirdlayerEditField.Enable = "off";
            app.FourthlayerEditFieldLabel.Enable = "off";
            app.FourthlayerEditField.Enable = "off";
            app.FifthlayerEditFieldLabel.Enable = "off";
            app.FifthlayerEditField.Enable = "off";

                     app.Layer2Button.Enable = "off";
                     app.Layer3Button.Enable = "off";                     
                     app.Layer4Button.Enable = "off";
                     app.Layer5Button.Enable = "off";   
                     app.Layer2Button_2.Enable = "off";
                     app.Layer3Button_2.Enable = "off";                     
                     app.Layer4Button_2.Enable = "off";
                     app.Layer5Button_2.Enable = "off";  
                     
                     app.Image8.Visible = "on";
                     app.Image9.Visible = "off";        
                     app.Image10.Visible = "off";
                     app.Image11.Visible = "off";
                     app.Image12.Visible = "off";
                     
                     app.Image14.Visible = "on";
                     app.Image15.Visible = "off";        
                     app.Image16.Visible = "off";
                     app.Image17.Visible = "off";
                     app.Image18.Visible = "off";                     
                     app.Image19.Visible = "off";
                     app.Image20.Visible = "off";        
                     app.Image21.Visible = "off";
                     app.Image22.Visible = "off";
                     app.Image23.Visible = "off";                     
                     app.Image24.Visible = "off";
                     app.Image25.Visible = "off";        
                     app.Image26.Visible = "off";
                     app.Image27.Visible = "off";
                     app.Image28.Visible = "off";                     
                     
                     app.Image14_2.Visible = "on";
                     app.Image15_2.Visible = "off";        
                     app.Image16_2.Visible = "off";
                     app.Image17_2.Visible = "off";
                     app.Image18_2.Visible = "off";                     
                     app.Image19_2.Visible = "off";
                     app.Image20_2.Visible = "off";        
                     app.Image21_2.Visible = "off";
                     app.Image22_2.Visible = "off";
                     app.Image23_2.Visible = "off";                     
                     app.Image24_2.Visible = "off";
                     app.Image25_2.Visible = "off";        
                     app.Image26_2.Visible = "off";
                     app.Image27_2.Visible = "off";
                     app.Image28_2.Visible = "off";                       


           app.VGmodelPanel.Visible = 'off';
           app.GardnermodelPanel.Visible = 'off';
           app.BCmodelPanel.Visible = 'off';

           app.i_q = 0;
           app.i_py = 0;
           app.i_px = 0;


                   app.stPanel.Visible = 'on'; 
                   app.ndPanel.Visible = 'off';
                   app.rdPanel.Visible = 'off';
                   app.thPanel.Visible = 'off';
                   app.thPanel_2.Visible = 'off'; 

                   app.stPanel_2.Visible = 'on';                   
                   app.ndPanel_2.Visible = 'off';
                   app.rdPanel_2.Visible = 'off';
                   app.thPanel_3.Visible = 'off';
                   app.thPanel_4.Visible = 'off';
                   
                app.FEditField.Enable = 'off';
                app.bEditField.Enable = 'off';
                app.FEditField_2.Enable = 'off';
                app.bEditField_2.Enable = 'off';
                app.FEditField_3.Enable = 'off';
                app.bEditField_3.Enable = 'off';
                app.FEditField_4.Enable = 'off';
                app.bEditField_4.Enable = 'off';
                app.FEditField_5.Enable = 'off';
                app.bEditField_5.Enable = 'off';
                
                app.flag_stress.layer_1 = 1;               
                app.flag_stress.layer_2 = 1; 
                app.flag_stress.layer_3 = 1;                 
                app.flag_stress.layer_4 = 1;                
                app.flag_stress.layer_5 = 1;                 
                
                app.flag_chi.layer_1 = 1;
                app.flag_chi.layer_2 = 1;
                app.flag_chi.layer_3 = 1;                
                app.flag_chi.layer_4 = 1;                
                app.flag_chi.layer_5 = 1;     
                
                app.EditField_12.Enable = 'off';
                app.EditField_13.Enable = 'off';  
                app.EditField_14.Enable = 'off'; 
                app.EditField_15.Enable = 'off';
                app.EditField_16.Enable = 'off';
                
                
                app.AirentrysuctionSeEditField.Enable = 'off';
                app.AirentrysuctionSeEditField_2.Enable = 'off';
                app.AirentrysuctionSeEditField_3.Enable = 'off';
                app.AirentrysuctionSeEditField_4.Enable = 'off';
                app.AirentrysuctionSeEditField_5.Enable = 'off';


            app.ndPanel.Position = [1 -1 899 447];
            app.rdPanel.Position = [1 -1 899 447];
            app.thPanel.Position = [1 -1 899 447];
            app.thPanel_2.Position = [1 -1 899 447];
            
            app.VGmodelPanel_2.Position = [250 36 627 316];            
            app.VGmodelPanel_4.Position = [250 36 627 316];
            app.VGmodelPanel_5.Position = [250 36 627 316];
            app.VGmodelPanel_3.Position = [250 36 627 316];
            
            app.BCmodelPanel_2.Position = [253 107 627 243];
            app.BCmodelPanel_3.Position = [253 107 627 243];
            app.BCmodelPanel_4.Position = [253 107 627 243];
            app.BCmodelPanel_5.Position = [253 107 627 243];
            
           app.GardnermodelPanel_2.Position = [251 128 627 222]; 
           app.GardnermodelPanel_3.Position = [251 128 627 222]; 
           app.GardnermodelPanel_4.Position = [251 128 627 222]; 
           app.GardnermodelPanel_5.Position = [251 128 627 222];
           
           app.HydraulicmodelDropDown_2.Position = [378 373 100 22];
           app.HydraulicmodelDropDown_3.Position = [378 373 100 22];
           app.HydraulicmodelDropDown_4.Position = [378 373 100 22];
           app.HydraulicmodelDropDown_5.Position = [378 373 100 22];
           
           app.VGmodelPanel_2.Visible = 'off';
           app.GardnermodelPanel_2.Visible = 'off';
           app.BCmodelPanel_2.Visible = 'off';

           app.VGmodelPanel_3.Visible = 'off';
           app.GardnermodelPanel_3.Visible = 'off';
           app.BCmodelPanel_3.Visible = 'off';

           app.VGmodelPanel_4.Visible = 'off';
           app.GardnermodelPanel_4.Visible = 'off';
           app.BCmodelPanel_4.Visible = 'off';
           
           app.VGmodelPanel_5.Visible = 'off';
           app.GardnermodelPanel_5.Visible = 'off';
           app.BCmodelPanel_5.Visible = 'off';  
                       
            app.flag_hydro_1 = 0;
            app.flag_hydro_2 = 0;
            app.flag_hydro_3 = 0;
            app.flag_hydro_4 = 0;
            app.flag_hydro_5 = 0;

%%%%%%%%%%%%%%%%%%%%%%%%%%Boundary con%%%%%%%%%%%%%%%%%    
            app.constantEditField.Enable = 'on';                 
            app.functionEditField.Enable = 'off';  


            app.BC_top_flag = 1;
            app.BC_bottom_flag = 1;        
            
            app.prescribedwaterheadhw1EditField.Enable = "off";  
            app.FluxonthetopButton.Enable = "on";
            app.PressureonthetopButton.Enable = "on";  
            app.FluxinthebottomButton.Enable = "on";
            app.PressureinthebottomButton.Enable = "on";   
                    
            app.RainfallIntensitymsEditField_2.Enable = 'off';         
            app.NormalStresskPaEditField.Enable = 'off'; 
            app.ShearStresskPaEditField.Enable = 'off';     
            
        app.Panel_16.Visible = 'off';
        app.Panel_15.Visible = 'off';
        app.Panel_14.Visible = 'off';
        app.Panel_13.Visible = 'off';
        
            app.CC = 0;
            app.FS_flag = 1;
            app.ST_flag = 2; 
            
           app.h1EditField.Enable = 'off' ;
           app.h2EditField.Enable = 'off' ;
           app.h3EditField.Enable = 'off' ;                   
           app.h4EditField.Enable = 'off' ;                   
           app.d_rEditField.Enable = 'off' ;                   
           app.T_pEditField.Enable = 'off' ; 
           app.Image13.Enable = 'off' ; 
                   
           app.flow_flag = 0;
           app.Thresholdvaluehw2EditField.Enable = "off";
           
            app.IC_index = 1;   
                   app.ST.h1 = 0;
                   app.ST.h2 = 0;
                   app.ST.h3 = 0;
                   app.ST.h4 = 0;
                   app.ST.d_r = 0;
                   app.ST.T_p = 0; 
                   
         app.q_r = 1e-6;
         app.q_py = 0;
         app.q_py = 0;
         %%%%%%%plot%%%%%
         app.hist_flag = 1;
         app.prof_flag = 1;
         
                app.NodesEditField.Visible = "on";   	    
                app.NodesEditField_2.Visible = "off";
                app.NodesEditField_3.Visible = "off";
                app.NodesEditField_4.Visible = "off";
                app.NodesEditField_5.Visible = "off";   
                
                app.EditField_6.Visible = "on";   	    
                app.EditField_17.Visible = "off";
                app.EditField_18.Visible = "off";
                app.EditField_19.Visible = "off";
                app.EditField_20.Visible = "off";       
                
                app.VariablesDropDown.Visible = "on"; 
                app.VariablesDropDown_3.Visible = "off"; 
                app.VariablesDropDown_4.Visible = "off";                 
                app.VariablesDropDown_5.Visible = "off"; 
                app.VariablesDropDown_6.Visible = "off"; 
                
        app.map_flag = 0;
        app.transtoraster_flag = 0;
                
        end

        % Button pushed function: PlotButton_3
        function PlotButtonPushed(app, event)
    cla(app.Profile_variation) 
    A = app.output_times;
    B = app.RESULTS;
    for j = 1:(app.output_times+1)  
        
        switch app.prof_flag
            case 1
             app.Profile_variation.FontSize = 20;
             app.Profile_variation.Position = [92,64,616,360];
             app.Profile_variation.LineWidth = 1;
             
             hold(app.Profile_variation, 'on')                 
             plot(app.Profile_variation,app.RESULTS.profiles_hw(:,j),(app.FE_data.Z),'LineWidth',1.5,'color','k',"LineStyle","-")
             app.Profile_variation.XLim = [-2,0];
             app.Profile_variation.BackgroundColor = 'white';          
             app.Profile_variation.XLabel.String = 'Water head, hw(m)';
             
%              exp_hw_5h = [-1.88E+00	-2.51E+00	-3.21E+00	-3.78E+00	-4.01E+00	-3.92E+00	-3.74E+00	-3.55E+00	-3.36E+00	-3.16E+00	-2.96E+00	-2.76E+00	-2.56E+00	-2.36E+00	-2.15E+00	-1.96E+00	-1.76E+00	-1.56E+00	-1.33E+00	-1.14E+00	-9.33E-01	-7.28E-01	-5.33E-01	-3.39E-01	-1.56E-01	-2.78E-02];
%              exp_Z_5h = [4.99E+00	4.80E+00	4.61E+00	4.42E+00	4.18E+00	3.99E+00	3.79E+00	3.59E+00	3.39E+00	3.20E+00	2.99E+00	2.80E+00	2.61E+00	2.40E+00	2.20E+00	2.00E+00	1.81E+00	1.61E+00	1.39E+00	1.18E+00	9.93E-01	7.89E-01	5.99E-01	4.01E-01	2.04E-01	2.78E-02];
%              exp_hw_30h = [-1.09E+00	-1.31E+00	-1.54E+00	-1.80E+00	-2.09E+00	-2.34E+00	-2.56E+00	-2.71E+00	-2.78E+00	-2.76E+00	-2.67E+00	-2.53E+00	-2.36E+00	-2.17E+00	-1.98E+00	-1.78E+00	-1.59E+00	-1.39E+00	-1.18E+00	-9.94E-01	-8.06E-01	-6.28E-01	-4.50E-01	-2.89E-01	-1.28E-01	0.00E+00];
%              exp_Z_30h = [5.00E+00	4.82E+00	4.61E+00	4.42E+00	4.20E+00	4.00E+00	3.80E+00	3.60E+00	3.40E+00	3.20E+00	3.01E+00	2.81E+00	2.62E+00	2.42E+00	2.22E+00	2.02E+00	1.82E+00	1.62E+00	1.40E+00	1.20E+00	1.01E+00	8.03E-01	6.13E-01	4.23E-01	2.11E-01	0.00E+00];
%             
%              com_hw_5h = [-1.1322	-1.4089	-1.6833	-1.9444	-2.2556	-2.5111	-2.7278	-2.8667	-2.9278	-2.8544	-2.7444	-2.5389	-2.3611	-2.1778	-1.9833	-1.7889	-1.5944	-1.3944	-1.1778	-0.9944	-0.8056	-0.6278	-0.4611	-0.2889	-0.1333];
%              com_Z_5h = [5.00E+00	4.82E+00	4.61E+00	4.42E+00	4.19E+00	3.99E+00	3.80E+00	3.60E+00	3.40E+00	3.20E+00	3.01E+00	2.81E+00	2.61E+00	2.42E+00	2.23E+00	2.01E+00	1.82E+00	1.63E+00	1.40E+00	1.20E+00	1.01E+00	8.03E-01	6.13E-01	4.23E-01	2.18E-01];
%              com_hw_30h = [-0.4511	-0.5211	-0.6033	-0.6578	-0.7500	-0.8111	-0.8767	-0.9278	-0.9778	-1.0167	-1.0500	-1.0722	-1.09778	-1.1	-1.0600	-1.011	-0.9456	-0.8689	-0.7644	-0.6656	-0.5556	-0.4556	-0.3400	-0.2144	-0.0878];
%              com_Z_30h = [4.99E+00	4.82E+00	4.61E+00	4.43E+00	4.19E+00	4.00E+00	3.79E+00	3.60E+00	3.39E+00	3.21E+00	3.00E+00	2.81E+00	2.61E+00	2.42E+00	2.23E+00	2.02E+00	1.82E+00	1.63E+00	1.41E+00	1.20E+00	1.01E+00	8.10E-01	6.13E-01	4.23E-01	2.11E-01];
% 
%              rig_hw_5h = [-1.64E+00	-2.11E+00	-2.63E+00	-3.16E+00	-3.59E+00	-3.73E+00	-3.67E+00	-3.51E+00	-3.32E+00	-3.13E+00	-2.92E+00	-2.72E+00	-2.53E+00	-2.33E+00	-2.13E+00	-1.93E+00	-1.73E+00	-1.53E+00	-1.31E+00	-1.11E+00	-9.11E-01	-7.06E-01	-5.11E-01	-3.33E-01	-1.56E-01];
%              rig_Z_5h = [4.99E+00	4.80E+00	4.61E+00	4.41E+00	4.18E+00	3.99E+00	3.80E+00	3.59E+00	3.39E+00	3.20E+00	3.00E+00	2.80E+00	2.61E+00	2.42E+00	2.21E+00	2.01E+00	1.82E+00	1.61E+00	1.39E+00	1.19E+00	1.00E+00	7.96E-01	5.99E-01	4.01E-01	2.04E-01];
%              rig_hw_30h = [-8.72E-01	-1.03E+00	-1.18E+00	-1.35E+00	-1.56E+00	-1.74E+00	-1.90E+00	-2.05E+00	-2.17E+00	-2.23E+00	-2.24E+00	-2.19E+00	-2.09E+00	-1.97E+00	-1.82E+00	-1.63E+00	-1.47E+00	-1.28E+00	-1.08E+00	-9.11E-01	-7.44E-01	-5.78E-01	-4.22E-01	-2.67E-01	-1.17E-01];
%              rig_Z_30h = [5.00E+00	4.81E+00	4.61E+00	4.42E+00	4.19E+00	3.99E+00	3.80E+00	3.61E+00	3.39E+00	3.20E+00	3.01E+00	2.81E+00	2.62E+00	2.42E+00	2.22E+00	2.01E+00	1.82E+00	1.63E+00	1.39E+00	1.20E+00	1.01E+00	8.03E-01	6.13E-01	4.15E-01	2.11E-01];


% %         plot (app.Profile_variation,exp_hw_5h, exp_Z_5h ,'o','Markersize',7,'Color','k')   %%%%z=-0.6
% %         plot (app.Profile_variation,exp_hw_30h, exp_Z_30h ,'o','Markersize',7,'Color','k')    %%%%z=-1.0
%         plot (app.Profile_variation,com_hw_5h, com_Z_5h ,'s','Markersize',7,'Color','k')    %%%%z=-1.4            
%         plot (app.Profile_variation,com_hw_30h, com_Z_30h ,'s','Markersize',7,'Color','k')    %%%%z=-1.4
% %         plot (app.Profile_variation,rig_hw_5h, rig_Z_5h ,'d','Markersize',7,'Color','k')    %%%%z=-1.4           
% %         plot (app.Profile_variation,rig_hw_30h, rig_Z_30h ,'d','Markersize',7,'Color','k')    %%%%z=-1.4
%              hold(app.Profile_variation, 'off')        
            case 2
            plot(app.Profile_variation,app.RESULTS.profiles_Sr_0(:,j),(app.FE_data.Z),'LineWidth',1)
            app.Profile_variation.XLabel.String = 'Degree of saturation, Sr';       
            app.Profile_variation.XLim = [0,1];
            hold(app.Profile_variation, 'on')      
            
        end

        
    end
        end

        % Button pushed function: PlotButton
        function PlotButton_2Pushed(app, event)

        time_plot = linspace(0,app.time_final,(app.num_steps+1));

    switch app.hist_flag
       
        case 1
        hold(app.Time_hist, 'on')         
        plot(app.Time_hist,time_plot/3600,app.RESULTS.hw_obs_A,'LineWidth',1,'Color','k',"LineStyle","-") 
        app.Time_hist.YLabel.String = 'Water head, hw(m)';
        app.Time_hist.XLabel.String = 'Time (hrs)';         
        hold(app.Time_hist, 'on')
      
        case 2
        hold(app.Time_hist, 'on')    
        plot(app.Time_hist,time_plot/3600,app.RESULTS.hw_obs_B,'LineWidth',1,'Color','r',"LineStyle","-") 
        app.Time_hist.YLabel.String = 'Water head, hw(m)';
        app.Time_hist.XLabel.String = 'Time (hrs)';         
        hold(app.Time_hist, 'on')
        case 3
        hold(app.Time_hist, 'on')  
        plot(app.Time_hist,time_plot/3600,app.RESULTS.hw_obs_C,'LineWidth',1,'Color','b',"LineStyle","-") 
        app.Time_hist.YLabel.String = 'Water head, hw(m)';
        app.Time_hist.XLabel.String = 'Time (hrs)';         
        hold(app.Time_hist, 'on')
 
%         t1=[0.072463768	0.096618357	0.241545894	0.289855072	0.507246377	0.483091787	0.555555556	0.652173913	0.748792271	0.77294686	0.917874396	0.893719807	1.014492754	1.15942029	1.376811594	1.473429952	1.52173913	1.352657005	1.256038647	1.328502415	1.54589372	1.497584541	1.714975845	1.763285024	1.859903382	1.884057971	1.956521739	2.028985507	2.077294686	2.077294686	2.222222222	2.246376812	2.246376812	2.439613527	2.512077295	2.632850242	2.415458937	2.415458937	2.753623188	2.753623188	2.874396135	2.898550725	2.898550725	3.019323671	3.043478261	3.06763285	3.164251208	3.236714976	3.309178744	3.236714976	3.188405797	3.357487923	3.47826087	3.47826087	3.526570048	3.623188406	3.671497585	3.671497585	3.743961353	3.743961353	3.888888889	3.913043478	3.937198068	4.033816425	4.057971014	4.178743961	4.202898551	4.202898551	4.251207729	4.323671498	4.396135266	4.468599034	4.468599034	4.492753623	4.492753623	4.492753623	4.541062802	4.589371981	4.589371981	4.782608696	4.734299517	4.975845411	5.072463768	5.096618357	5.314009662	5.362318841	5.458937198	5.458937198	5.434782609	4.637681159	5.507246377	5.603864734	5.700483092	5.821256039	5.845410628	5.966183575	6.038647343	6.111111111	6.207729469	6.207729469	6.328502415	6.400966184	6.473429952	6.618357488	6.714975845	6.739130435	7.028985507	7.246376812	7.246376812	7.415458937	7.512077295	7.391304348	7.632850242	7.70531401	7.874396135	7.971014493	8.043478261	7.995169082	8.043478261	8.164251208	8.260869565	8.405797101	8.671497585	8.816425121	8.888888889	9.057971014	9.154589372	9.275362319	9.516908213	9.637681159	9.758454106	9.879227053	10.12077295	10.14492754	10.4589372	10.74879227	10.96618357	11.03864734	11.25603865	11.32850242	11.83574879	11.78743961	11.88405797	12.0531401	12.29468599	12.48792271	12.58454106	12.7294686	12.89855072	13.28502415	13.30917874	13.42995169	13.57487923	13.71980676	13.81642512	13.98550725	14.05797101	14.27536232	14.42028986	14.42028986	14.42028986	14.22705314	14.63768116	14.56521739	14.90338164	14.95169082	14.87922705	14.73429952	15.07246377	15.24154589	15.36231884	15.43478261	15.4589372	15.24154589	15.57971014	15.65217391	15.79710145	15.94202899	16.08695652	16.20772947	16.25603865	16.47342995	16.28019324	16.37681159	16.54589372	16.52173913	16.40096618	16.5942029	16.5942029	16.8115942	16.8115942	16.90821256	16.95652174	17.19806763	17.2705314	17.31884058	17.31884058	17.17391304	17.39130435	17.53623188	17.65700483	17.7294686	17.85024155	17.9468599	17.89855072	18.09178744	18.1884058	18.28502415	18.28502415	18.35748792	18.47826087	18.74396135	18.84057971	18.81642512	18.8647343	18.8647343	18.8647343	19.05797101	19.0821256	19.10628019];
%         suc1 = [1.03E+01	1.01E+01	1.01E+01	1.03E+01	1.05E+01	1.03E+01	1.03E+01	1.04E+01	1.05E+01	1.07E+01	1.08E+01	1.07E+01	1.07E+01	1.08E+01	1.06E+01	1.05E+01	1.04E+01	1.05E+01	1.06E+01	1.08E+01	1.07E+01	1.08E+01	1.09E+01	1.09E+01	1.10E+01	1.11E+01	1.10E+01	1.10E+01	1.10E+01	1.11E+01	1.11E+01	1.10E+01	1.09E+01	1.09E+01	1.10E+01	1.12E+01	1.11E+01	1.10E+01	1.11E+01	1.10E+01	1.12E+01	1.13E+01	1.13E+01	1.13E+01	1.13E+01	1.12E+01	1.11E+01	1.12E+01	1.13E+01	1.13E+01	1.13E+01	1.13E+01	1.12E+01	1.11E+01	1.10E+01	1.10E+01	1.11E+01	1.13E+01	1.13E+01	1.13E+01	1.14E+01	1.13E+01	1.13E+01	1.13E+01	1.13E+01	1.14E+01	1.14E+01	1.14E+01	1.13E+01	1.13E+01	1.12E+01	1.12E+01	1.11E+01	1.11E+01	1.10E+01	1.10E+01	1.10E+01	1.12E+01	1.13E+01	1.16E+01	1.16E+01	1.16E+01	1.16E+01	1.15E+01	1.17E+01	1.17E+01	1.16E+01	1.16E+01	1.15E+01	1.15E+01	1.15E+01	1.07E+01	9.50E+00	7.04E+00	5.31E+00	4.90E+00	3.33E+00	1.77E+00	2.15E+00	2.71E+00	3.23E+00	3.58E+00	3.88E+00	4.21E+00	4.38E+00	4.65E+00	5.27E+00	2.21E+00	2.50E+00	2.58E+00	2.75E+00	2.81E+00	3.04E+00	3.38E+00	4.19E+00	4.31E+00	4.50E+00	4.42E+00	4.67E+00	4.77E+00	5.13E+00	5.69E+00	6.15E+00	6.44E+00	6.63E+00	6.83E+00	7.04E+00	7.29E+00	7.52E+00	7.67E+00	7.83E+00	8.10E+00	8.44E+00	8.52E+00	8.75E+00	8.92E+00	9.13E+00	9.23E+00	9.48E+00	9.54E+00	9.88E+00	9.83E+00	9.90E+00	9.96E+00	1.01E+01	1.01E+01	1.01E+01	1.03E+01	1.06E+01	1.06E+01	1.05E+01	1.05E+01	1.08E+01	1.10E+01	1.11E+01	1.11E+01	1.10E+01	1.10E+01	1.09E+01	1.07E+01	1.06E+01	1.11E+01	1.13E+01	1.16E+01	1.15E+01	1.14E+01	1.14E+01	1.14E+01	1.15E+01	1.14E+01	1.14E+01	1.14E+01	1.11E+01	1.16E+01	1.16E+01	1.16E+01	1.18E+01	1.18E+01	1.18E+01	1.18E+01	1.19E+01	1.16E+01	1.15E+01	1.14E+01	1.13E+01	1.14E+01	1.12E+01	1.17E+01	1.24E+01	1.23E+01	1.24E+01	1.21E+01	1.20E+01	1.20E+01	1.20E+01	1.21E+01	1.22E+01	1.22E+01	1.21E+01	1.21E+01	1.23E+01	1.24E+01	1.24E+01	1.24E+01	1.22E+01	1.23E+01	1.24E+01	1.23E+01	1.17E+01	1.14E+01	1.07E+01	1.07E+01	1.06E+01	1.05E+01	1.03E+01	1.01E+01	1.00E+01	9.88E+00	9.67E+00	9.56E+00];
% 
%         t2=[0	0.024154589	0.241545894	0.169082126	0.241545894	0.434782609	0.458937198	0.797101449	0.603864734	0.990338164	0.748792271	0.845410628	1.280193237	1.400966184	1.666666667	1.763285024	1.54589372	1.54589372	1.90821256	2.053140097	2.149758454	2.439613527	2.487922705	2.68115942	2.753623188	2.971014493	3.309178744	3.188405797	3.236714976	3.526570048	3.45410628	3.599033816	3.47826087	3.671497585	3.671497585	3.671497585	3.84057971	3.961352657	4.057971014	4.275362319	4.444444444	4.492753623	4.541062802	4.806763285	4.758454106	4.806763285	5.072463768	5.193236715	5.38647343	5.579710145	5.724637681	5.893719807	5.990338164	6.038647343	6.062801932	6.038647343	6.15942029	7.149758454	7.004830918	6.90821256	6.787439614	6.666666667	6.594202899	6.497584541	7.246376812	7.367149758	7.584541063	7.70531401	7.995169082	8.06763285	8.188405797	8.405797101	8.45410628	8.623188406	8.695652174	8.816425121	8.8647343	8.937198068	9.057971014	9.22705314	9.396135266	9.565217391	9.758454106	9.927536232	10.09661836	10.36231884	10.50724638	10.70048309	10.84541063	11.23188406	11.44927536	11.54589372	11.64251208	11.93236715	12.22222222	12.65700483	12.65700483	12.65700483	13.14009662	13.04347826	13.30917874	13.59903382	13.21256039	13.01932367	13.52657005	13.74396135	13.88888889	14.10628019	14.49275362	14.27536232	13.93719807	13.93719807	14.68599034	15	15.36231884	15.38647343	15.2173913	15.14492754	14.7826087	14.66183575	15.53140097	15.96618357	16.1352657	16.42512077	16.47342995	16.47342995	16.32850242	16.25603865	16.8115942	16.76328502	16.85990338	16.93236715	17.29468599	17.48792271	16.8115942	16.83574879	17.17391304	17.53623188	17.63285024	17.87439614	18.14009662	18.23671498	18.26086957	18.55072464	18.67149758	18.8647343	19.17874396	19.39613527	19.56521739	19.39613527	19.29951691	19.05797101	19.3236715	19.7826087	19.66183575	19.75845411	8.47826087	8.309178744	1.449275362	0.38647343	7.149758454];
%         suc2 = [9.75E+00	9.88E+00	9.81E+00	9.79E+00	9.92E+00	9.88E+00	9.83E+00	9.90E+00	1.01E+01	1.00E+01	1.01E+01	1.00E+01	1.02E+01	1.01E+01	1.01E+01	1.02E+01	1.02E+01	9.92E+00	1.02E+01	1.03E+01	1.03E+01	1.03E+01	1.04E+01	1.04E+01	1.03E+01	1.04E+01	1.05E+01	1.04E+01	1.05E+01	1.05E+01	1.04E+01	1.03E+01	1.02E+01	1.04E+01	1.06E+01	1.06E+01	1.07E+01	1.07E+01	1.06E+01	1.06E+01	1.05E+01	1.06E+01	1.08E+01	1.09E+01	1.06E+01	1.06E+01	1.09E+01	1.09E+01	1.09E+01	1.08E+01	1.08E+01	1.06E+01	1.05E+01	1.05E+01	1.04E+01	1.01E+01	8.17E+00	6.69E+00	6.58E+00	6.44E+00	6.27E+00	6.13E+00	5.92E+00	5.79E+00	5.33E+00	5.19E+00	5.13E+00	5.42E+00	5.60E+00	5.92E+00	6.23E+00	6.46E+00	6.54E+00	6.71E+00	6.88E+00	7.02E+00	7.17E+00	7.25E+00	7.33E+00	7.52E+00	7.60E+00	7.77E+00	7.94E+00	8.10E+00	8.38E+00	8.44E+00	8.63E+00	8.79E+00	8.83E+00	8.94E+00	9.02E+00	9.02E+00	9.29E+00	9.46E+00	9.50E+00	9.44E+00	9.71E+00	9.77E+00	9.94E+00	9.96E+00	9.77E+00	9.77E+00	9.77E+00	9.69E+00	1.00E+01	1.02E+01	1.02E+01	1.01E+01	9.90E+00	1.01E+01	9.94E+00	1.00E+01	1.04E+01	1.06E+01	1.03E+01	1.00E+01	1.02E+01	1.03E+01	1.06E+01	1.05E+01	1.06E+01	1.08E+01	1.08E+01	1.06E+01	1.05E+01	1.02E+01	1.04E+01	1.05E+01	1.14E+01	1.11E+01	1.10E+01	1.09E+01	1.08E+01	1.08E+01	1.08E+01	1.08E+01	1.07E+01	1.09E+01	1.11E+01	1.12E+01	1.12E+01	1.12E+01	1.10E+01	1.10E+01	1.11E+01	1.11E+01	1.11E+01	1.10E+01	1.09E+01	1.08E+01	1.08E+01	1.09E+01	1.10E+01	1.09E+01	1.10E+01	1.12E+01	6.69E+00	6.50E+00	9.44E+00	9.35E+00	6.13E+00];
%         
%         t3= [0.169082126	0.338164251	0.096618357	0.410628019	0.434782609	0.458937198	0.724637681	0.652173913	0.579710145	0.676328502	0.77294686	0.869565217	0.990338164	1.014492754	1.328502415	1.1352657	1.086956522	1.54589372	1.54589372	1.54589372	1.449275362	1.93236715	1.618357488	1.690821256	1.835748792	1.859903382	1.980676329	2.29468599	2.342995169	2.149758454	2.415458937	2.512077295	2.608695652	2.801932367	2.995169082	2.826086957	3.115942029	3.260869565	3.285024155	3.236714976	3.429951691	3.526570048	3.671497585	3.888888889	3.961352657	3.937198068	3.84057971	3.768115942	3.743961353	3.623188406	3.937198068	4.22705314	4.323671498	4.396135266	4.22705314	4.130434783	4.589371981	4.758454106	4.879227053	4.879227053	4.710144928	4.951690821	4.951690821	5	5.193236715	5.241545894	5.314009662	5.338164251	5.434782609	5.628019324	5.483091787	5.289855072	5.289855072	5.603864734	5.652173913	5.77294686	5.869565217	5.845410628	5.821256039	5.821256039	5.917874396	5.942028986	6.1352657	6.207729469	6.256038647	6.256038647	6.256038647	6.231884058	5.990338164	6.086956522	6.183574879	6.376811594	6.376811594	6.449275362	6.52173913	6.618357488	6.690821256	6.714975845	6.835748792	6.93236715	7.125603865	7.222222222	7.270531401	7.29468599	7.342995169	7.415458937	7.487922705	7.584541063	7.560386473	7.536231884	7.632850242	7.68115942	7.826086957	7.898550725	8.09178744	8.260869565	8.333333333	8.309178744	8.236714976	8.502415459	8.816425121	8.8647343	8.961352657	9.178743961	9.22705314	9.275362319	9.347826087	9.371980676	9.468599034	9.589371981	9.710144928	9.830917874	9.927536232	10.04830918	10.14492754	10.26570048	10.38647343	10.4589372	10.53140097	10.60386473	10.74879227	10.82125604	10.96618357	11.15942029	11.20772947	11.30434783	11.44927536	11.54589372	11.64251208	11.64251208	11.8115942	11.88405797	12.29468599	12.14975845	12.29468599	12.39130435	12.68115942	12.48792271	12.34299517	12.70531401	12.87439614	12.92270531	13.01932367	13.04347826	13.06763285	13.11594203	13.16425121	13.21256039	13.30917874	13.33333333	13.45410628	13.45410628	13.45410628	13.45410628	13.59903382	13.647343	13.79227053	13.91304348	13.96135266	14.0821256	14.13043478	14.22705314	14.3236715	14.42028986	14.5410628	14.27536232	14.56521739	14.56521739	14.58937198	14.68599034	14.7826087	14.85507246	14.85507246	14.87922705	14.95169082	15.04830918	15.07246377	15.12077295	15.26570048	15.26570048	15.31400966	15.36231884	15.43478261	15.60386473	15.65217391	15.70048309	15.74879227	15.79710145	15.84541063	15.94202899	15.96618357	15.96618357	16.03864734	16.1352657	16.23188406	16.23188406	16.30434783	16.37681159	16.352657	16.47342995	16.73913043	17.17391304	17.19806763	16.83574879	17.02898551	17.75362319	17.82608696	17.60869565	17.99516908	18.23671498	18.47826087	18.62318841	18.50241546	18.59903382	18.93719807	19.29951691	19.61352657	19.73429952	19.75845411];
%         suc3 = [8.15E+00	8.33E+00	8.35E+00	8.35E+00	8.21E+00	8.52E+00	8.44E+00	8.35E+00	8.33E+00	8.63E+00	8.67E+00	8.46E+00	8.33E+00	8.27E+00	8.54E+00	8.46E+00	8.50E+00	8.50E+00	8.27E+00	8.19E+00	8.38E+00	8.75E+00	8.60E+00	8.69E+00	8.58E+00	8.50E+00	8.46E+00	8.58E+00	8.60E+00	8.65E+00	8.79E+00	8.81E+00	8.85E+00	8.75E+00	8.85E+00	8.94E+00	8.98E+00	8.98E+00	8.92E+00	8.85E+00	8.85E+00	8.85E+00	8.90E+00	8.98E+00	9.06E+00	9.13E+00	9.13E+00	9.04E+00	9.00E+00	8.98E+00	9.02E+00	9.02E+00	9.02E+00	9.10E+00	9.13E+00	9.06E+00	9.02E+00	9.10E+00	9.21E+00	9.27E+00	9.29E+00	9.19E+00	9.08E+00	9.00E+00	9.10E+00	9.17E+00	9.23E+00	9.25E+00	9.25E+00	9.23E+00	9.19E+00	9.29E+00	9.31E+00	9.29E+00	9.29E+00	9.29E+00	9.27E+00	9.19E+00	9.19E+00	9.23E+00	9.33E+00	9.33E+00	9.25E+00	9.19E+00	9.15E+00	9.10E+00	9.04E+00	9.02E+00	9.23E+00	9.17E+00	9.17E+00	8.63E+00	9.02E+00	8.10E+00	7.75E+00	7.52E+00	7.25E+00	7.13E+00	7.00E+00	6.85E+00	6.63E+00	6.54E+00	6.48E+00	6.38E+00	6.27E+00	6.10E+00	6.00E+00	6.00E+00	5.85E+00	5.75E+00	5.71E+00	5.69E+00	5.75E+00	5.58E+00	5.54E+00	5.46E+00	5.40E+00	5.67E+00	5.65E+00	5.98E+00	6.17E+00	6.21E+00	6.35E+00	6.46E+00	6.50E+00	6.56E+00	6.67E+00	6.71E+00	6.77E+00	6.83E+00	6.90E+00	6.94E+00	6.98E+00	7.02E+00	7.06E+00	7.15E+00	7.21E+00	7.25E+00	7.31E+00	7.35E+00	7.46E+00	7.52E+00	7.52E+00	7.58E+00	7.63E+00	7.75E+00	7.81E+00	7.83E+00	7.83E+00	7.73E+00	7.96E+00	7.98E+00	7.94E+00	8.00E+00	8.13E+00	8.19E+00	8.10E+00	8.02E+00	7.73E+00	8.23E+00	8.23E+00	8.29E+00	8.25E+00	8.19E+00	8.17E+00	8.13E+00	8.04E+00	8.00E+00	8.10E+00	8.15E+00	8.27E+00	8.31E+00	8.38E+00	8.46E+00	8.52E+00	8.54E+00	8.58E+00	8.58E+00	8.58E+00	8.58E+00	8.58E+00	8.56E+00	8.54E+00	8.52E+00	8.44E+00	8.33E+00	8.83E+00	8.81E+00	8.79E+00	8.75E+00	8.75E+00	8.79E+00	8.81E+00	8.83E+00	8.83E+00	8.83E+00	8.81E+00	8.79E+00	8.75E+00	8.81E+00	8.85E+00	8.88E+00	8.90E+00	8.92E+00	8.94E+00	8.96E+00	8.98E+00	9.00E+00	9.02E+00	8.98E+00	8.94E+00	8.90E+00	8.83E+00	8.81E+00	8.83E+00	8.88E+00	8.88E+00	8.85E+00	8.67E+00	9.23E+00	9.38E+00	9.21E+00	9.13E+00	9.33E+00	9.13E+00	9.29E+00	9.29E+00	9.31E+00	9.48E+00	9.44E+00	9.44E+00	9.50E+00	9.58E+00	9.52E+00	9.46E+00	9.48E+00	9.44E+00	9.52E+00	9.31E+00];
% 
%         plot (app.Time_hist,t1, suc1 ,'o','Markersize',5,'Color','k')   %%%%z=-0.6
%         hold on
%         plot (app.Time_hist,t2, suc2 ,'p','Markersize',5,'Color','k')    %%%%z=-1.0
%         hold on
%         plot (app.Time_hist,t3, suc3 ,'s','Markersize',5,'Color','k')    %%%%z=-1.4
%         hold on        
        case 4
        hold(app.Time_hist, 'on')      
        plot(app.Time_hist,time_plot/3600,app.RESULTS.hw_obs_D,'LineWidth',1) 
        app.Time_hist.YLabel.String = 'Water head, hw(m)';
        app.Time_hist.XLabel.String = 'Time (hrs)';           
        hold(app.Time_hist, 'on')
        case 5
        hold(app.Time_hist, 'on')      
        plot(app.Time_hist,time_plot/3600,app.RESULTS.hw_obs_E,'LineWidth',1) 
        app.Time_hist.YLabel.String = 'Water head, hw(m)'; 
        app.Time_hist.XLabel.String = 'Time (hrs)';         
        hold(app.Time_hist, 'on')
        case 6   
        hold(app.Time_hist, 'on')    
        hold(app.Time_hist,"on")
        app.Time_hist.FontSize = 15;
        plot(app.Time_hist,time_plot/3600,app.RESULTS.IR*3600*100,'LineWidth',1.0,'color','k',"LineStyle","-")        
        app.Time_hist.YLabel.String = 'Infiltration rate (cm/hr)';
        app.Time_hist.XLabel.String = 'Time [hrs]';  
%         app.Time_hist.Position = [110,60,500,350];
%         app.Time_hist.YLim = [0,10];        
        
        case 7
        hold(app.Time_hist, 'on')                
        plot(app.Time_hist,time_plot/3600,app.RESULTS.Run*3600*100,'LineWidth',1.2,'color','k',"LineStyle","-")    
        app.Time_hist.YLabel.String = 'Runoff rate (cm/hr)'; 
        
        case 8 
        hold(app.Time_hist, 'on')             
        plot(app.Time_hist,time_plot/3600,app.RESULTS.Q*100,'LineWidth',1)    
        app.Time_hist.YLabel.String = 'Acumulative infiltration (cm)'; 
        case 9
        hold(app.Time_hist, 'on')             
        plot(app.Time_hist,time_plot/3600,app.RESULTS.R*100,'LineWidth',1)    
        app.Time_hist.YLabel.String = 'Acumulative Runoff (cm)';             
    end


        hold(app.Time_hist)
        end

        % Callback function
        function HydrologicalloadingCheckBoxValueChanged(app, event)
   
        if  app.HydrologicalloadingCheckBox.Value == 1
                 app.i_q = 1; 
            else
                app.i_q = 0;
        end
            
        end

        % Callback function
        function MechanicalloadingyCheckBoxValueChanged(app, event)
         
        if  app.MechanicalloadingyCheckBox.Value == 1
                 app.i_py = 1; 
            else
                app.i_py = 0;
        end

        end

        % Callback function
        function MechanicalloadingxCheckBoxValueChanged(app, event)

        if  app.MechanicalloadingxCheckBox.Value == 1
                app.i_px = 1;
            else
                app.i_px = 0;
        end
        end

        % Selection changed function: 
        % InitialconditionwaterheadprofileButtonGroup
        function InitialconditionwaterheadprofileButtonGroupSelectionChanged(app, event)
        switch  app.InitialconditionwaterheadprofileButtonGroup.SelectedObject
            case app.ConstantButton
                 app.IC_index =1;
                 app.constantEditField.Enable = 'on';
                 app.functionEditField.Enable = 'off';                 
            case app.FunctionButton 
                 app.IC_index =2;
                 app.functionEditField.Enable = 'on';
                 app.constantEditField.Enable = 'off';                 
                 
        end  
        end

        % Callback function
        function HydraulicmodelDropDownValueChanged(app, event)
            value = app.HydraulicmodelDropDown.Value;
            switch value 
                case "Please choose a model"
                    app.flag_hydro = 0;
                    app.VGmodelPanel.Visible = 'off';
                    app.GardnermodelPanel.Visible = 'off';
                    app.BCmodelPanel.Visible = 'off';
                case "Van Genuchten model"
                    app.flag_hydro = 1;
                    app.VGmodelPanel.Visible = 'on';
                    app.GardnermodelPanel.Visible = 'off';
                    app.BCmodelPanel.Visible = 'off';
                case "Gardner model"
                    app.flag_hydro = 2;
                    app.VGmodelPanel.Visible = 'off';
                    app.GardnermodelPanel.Visible = 'on';
                    app.BCmodelPanel.Visible = 'off';
                case "Brooks and Corey model"
                    app.flag_hydro = 3;
                    app.BCmodelPanel.Visible = 'on';
                    app.VGmodelPanel.Visible = 'off';
                    app.GardnermodelPanel.Visible = 'off';
            end
        end

        % Callback function
        function TypeoflayersDropDownValueChanged(app, event)
            value = app.TypeoflayersDropDown.Value;
            switch value
                case "1 Layer"
                    app.n_layer = 1;
           app.stEditField.Visible = 'off';
           app.ndEditField.Visible = 'off';
           app.rdEditField.Visible = 'off';
           app.thEditField.Visible = 'off';          
           app.thEditField_2.Visible = 'off';                            
                case "2 Layers"
                    app.n_layer = 2;
           app.stEditField.Visible = 'on';
           app.ndEditField.Visible = 'on';
           app.rdEditField.Visible = 'off';
           app.thEditField.Visible = 'off';          
           app.thEditField_2.Visible = 'off';         
                case "3 Layers"
                    app.n_layer = 3;
           app.stEditField.Visible = 'on';
           app.ndEditField.Visible = 'on';
           app.rdEditField.Visible = 'on';
           app.thEditField.Visible = 'off';          
           app.thEditField_2.Visible = 'off';         
                case "4 Layers"
                    app.n_layer = 4;
           app.stEditField.Visible = 'on';
           app.ndEditField.Visible = 'on';
           app.rdEditField.Visible = 'on';
           app.thEditField.Visible = 'on';          
           app.thEditField_2.Visible = 'off';         
                case "5 Layers"
                    app.n_layer = 5;
           app.stEditField.Visible = 'on';
           app.ndEditField.Visible = 'on';
           app.rdEditField.Visible = 'on';
           app.thEditField.Visible = 'on';          
           app.thEditField_2.Visible = 'on';         
            end
        end

        % Value changed function: HydraulicmodelDropDown
        function HydraulicmodelDropDownValueChanged2(app, event)
            value = app.HydraulicmodelDropDown.Value;
            switch value 
                case "Please choose a model"
                    app.flag_hydro_1 = 0;
                    app.VGmodelPanel.Visible = 'off';
                    app.GardnermodelPanel.Visible = 'off';
                    app.BCmodelPanel.Visible = 'off';
                case "Van Genuchten model"
                    app.flag_hydro_1 = 1;
                    app.VGmodelPanel.Visible = 'on';
                    app.GardnermodelPanel.Visible = 'off';
                     app.BCmodelPanel.Visible = 'off';
                case "Gardner model"
                    app.flag_hydro_1 = 2;
                    app.VGmodelPanel.Visible = 'off';
                    app.GardnermodelPanel.Visible = 'on';
                     app.BCmodelPanel.Visible = 'off';
                case "Brooks and Corey model"
                    app.flag_hydro_1 = 3;
                    app.BCmodelPanel.Visible = 'on';
                    app.VGmodelPanel.Visible = 'off';
                    app.GardnermodelPanel.Visible = 'off';
            end
        end

        % Value changed function: HydraulicmodelDropDown_2
        function HydraulicmodelDropDown_2ValueChanged(app, event)
            value = app.HydraulicmodelDropDown_2.Value;
            switch value 
                case "Please choose a model"
                    app.flag_hydro_2 = 0;
                    app.VGmodelPanel_2.Visible = 'off';
                    app.GardnermodelPanel_2.Visible = 'off';
                    app.BCmodelPanel_2.Visible = 'off';
                case "Van Genuchten model"
                    app.flag_hydro_2 = 1;
                    app.VGmodelPanel_2.Visible = 'on';
                    app.GardnermodelPanel_2.Visible = 'off';
                     app.BCmodelPanel_2.Visible = 'off';
                case "Gardner model"
                    app.flag_hydro_2 = 2;
                    app.VGmodelPanel_2.Visible = 'off';
                    app.GardnermodelPanel_2.Visible = 'on';
                     app.BCmodelPanel_2.Visible = 'off';
                case "Brooks and Corey model"
                    app.flag_hydro_2 = 3;
                    app.BCmodelPanel_2.Visible = 'on';
                    app.VGmodelPanel_2.Visible = 'off';
                    app.GardnermodelPanel_2.Visible = 'off';
            end
        end

        % Value changed function: HydraulicmodelDropDown_3
        function HydraulicmodelDropDown_3ValueChanged(app, event)
            value = app.HydraulicmodelDropDown_3.Value;
            switch value 
                case "Please choose a model"
                    app.flag_hydro_3 = 0;
                    app.VGmodelPanel_3.Visible = 'off';
                    app.GardnermodelPanel_3.Visible = 'off';
                    app.BCmodelPanel_3.Visible = 'off';
                case "Van Genuchten model"
                    app.flag_hydro_3 = 1;
                    app.VGmodelPanel_3.Visible = 'on';
                    app.GardnermodelPanel_3.Visible = 'off';
                     app.BCmodelPanel_3.Visible = 'off';
                case "Gardner model"
                    app.flag_hydro_3 = 2;
                    app.VGmodelPanel_3.Visible = 'off';
                    app.GardnermodelPanel_3.Visible = 'on';
                     app.BCmodelPanel_3.Visible = 'off';
                case "Brooks and Corey model"
                    app.flag_hydro_3 = 3;
                    app.BCmodelPanel_3.Visible = 'on';
                    app.VGmodelPanel_3.Visible = 'off';
                    app.GardnermodelPanel_3.Visible = 'off';
            end            
        end

        % Value changed function: HydraulicmodelDropDown_4
        function HydraulicmodelDropDown_4ValueChanged(app, event)
            value = app.HydraulicmodelDropDown_4.Value;
            switch value 
                case "Please choose a model"
                    app.flag_hydro_4 = 0;
                    app.VGmodelPanel_4.Visible = 'off';
                    app.GardnermodelPanel_4.Visible = 'off';
                    app.BCmodelPanel_4.Visible = 'off';
                case "Van Genuchten model"
                    app.flag_hydro_4 = 1;
                    app.VGmodelPanel_4.Visible = 'on';
                    app.GardnermodelPanel_4.Visible = 'off';
                     app.BCmodelPanel_4.Visible = 'off';
                case "Gardner model"
                    app.flag_hydro_4 = 2;
                    app.VGmodelPanel_4.Visible = 'off';
                    app.GardnermodelPanel_4.Visible = 'on';
                     app.BCmodelPanel_4.Visible = 'off';
                case "Brooks and Corey model"
                    app.flag_hydro_4 = 3;
                    app.BCmodelPanel_4.Visible = 'on';
                    app.VGmodelPanel_4.Visible = 'off';
                    app.GardnermodelPanel_4.Visible = 'off';
            end
        end

        % Value changed function: HydraulicmodelDropDown_5
        function HydraulicmodelDropDown_5ValueChanged(app, event)
            value = app.HydraulicmodelDropDown_5.Value;
            switch value 
                case "Please choose a model"
                    app.flag_hydro_5 = 0;
                    app.VGmodelPanel_5.Visible = 'off';
                    app.GardnermodelPanel_5.Visible = 'off';
                    app.BCmodelPanel_5.Visible = 'off';
                case "Van Genuchten model"
                    app.flag_hydro_5 = 1;
                    app.VGmodelPanel_5.Visible = 'on';
                    app.GardnermodelPanel_5.Visible = 'off';
                     app.BCmodelPanel_5.Visible = 'off';
                case "Gardner model"
                    app.flag_hydro_5 = 2;
                    app.VGmodelPanel_5.Visible = 'off';
                    app.GardnermodelPanel_5.Visible = 'on';
                     app.BCmodelPanel_5.Visible = 'off';
                case "Brooks and Corey model"
                    app.flag_hydro_5 = 3;
                    app.BCmodelPanel_5.Visible = 'on';
                    app.VGmodelPanel_5.Visible = 'off';
                    app.GardnermodelPanel_5.Visible = 'off';
            end
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            [file, path] = uigetfile('*.xlsx');
            app.FEMUIFigure.Visible = 'off';
            app.FEMUIFigure.Visible = 'on';
            if isequal(file,0)               
               msgbox('Please input an Excel file')
            else
                t = readtable(fullfile(path, file));
                app.UITable.Data = t;
                % Set column names as a pull down menu
                app.XDropDown.Items = t.Properties.VariableNames;
                app.YDropDown.Items = t.Properties.VariableNames;
                
%                 plot(app.Time_hist, app.UITable.data.(app.XDropDown.Value), app.UITable.Data.(app.YDropDown.Value));
            end
            
        end

        % Value changed function: XDropDown
        function XDropDownValueChanged(app, event)
            value = app.XDropDown.Value;
            
%             scatter(app.Profile_variation, app.UITable.Data.(app.XDropDown.Value),app.UITable.Data.(app.YDropDown.Value),'.');
        end

        % Value changed function: YDropDown
        function YDropDownValueChanged(app, event)
            value = app.YDropDown.Value;
            
%             scatter(app.Profile_variation, app.UITable.Data.(app.XDropDown.Value),app.UITable.Data.(app.YDropDown.Value),'.');
        end

        % Value changed function: FiguresDropDown
        function FiguresDropDownValueChanged(app, event)
            value = app.FiguresDropDown.Value;
            switch value
                case "Which figure to plot"     
%                     cla(app.Profile_variation)
%                     cla(app.Time_hist)

                case "Profile variation"
                    plot(app.Profile_variation, app.UITable.Data.(app.XDropDown.Value), app.UITable.Data.(app.YDropDown.Value),'o','Color','k','Markersize',8);
                     hold(app.Profile_variation)
                case "Time history"
                    hold(app.Time_hist,"on")
                    A = app.XDropDown.Value;
                    plot(app.Time_hist, app.UITable.Data.(app.XDropDown.Value), app.UITable.Data.(app.YDropDown.Value),'o','Color','k','Markersize',8); 
                    A = app.XDropDown.Value;
                    hold(app.Time_hist,"off")
            end
            
        end

        % Button pushed function: PlotButton_2
        function PlotButton_3Pushed(app, event)
        cla(app.Rainfall)
        time_plot = linspace(0,app.time_final,(app.num_steps+1));
        plot(app.Rainfall,time_plot/3600,app.RESULTS.Rain,'LineWidth',1)
        end

        % Callback function
        function TreeSelectionChanged(app, event)
            selectedNode = app.Tree.SelectedNodes;
             % Enable form only when a child is selected    
           Items = {'Hydraulogic loading','Normal stress','Tangential stress','Constant_W','Time history_W','Constant_V','Time history_V','Constant_H','Time history_H'};
%            subItems = {'Constant_W','Time history_W','Constant_V','Time history_V','Constant_H','Time history_H'};
           Loading_Index_aux = find(strcmp(selectedNode.Text,Items),1);
           app.Loading_Index = Loading_Index_aux;

           switch Loading_Index_aux
               case 1 
                   disableForm1(app);
                   disableForm2(app);   
               case 2 
                   disableForm1(app);
                   disableForm2(app); 
               case 3 
                   disableForm1(app);
                   disableForm2(app);                    
               case 4 
                    app.RainfallIntensitymsEditField_2.Enable = 'on';
                    app.NormalStresskPaEditField.Enable = 'off'; 
                    app.ShearStresskPaEditField.Enable = 'off';   
                    disableForm2(app); 
               
                case 5 
                    enableForm2(app)
                    disableForm1(app); 
                    
                case 6                 
                    disableForm2(app); 
                    app.RainfallIntensitymsEditField_2.Enable = 'off';
                    app.NormalStresskPaEditField.Enable = 'on'; 
                    app.ShearStresskPaEditField.Enable = 'off';   
               case 7
                    enableForm2(app)
                    disableForm1(app); 
               case 8                 
                    disableForm2(app); 
                    app.RainfallIntensitymsEditField_2.Enable = 'off';
                    app.NormalStresskPaEditField.Enable = 'off'; 
                    app.ShearStresskPaEditField.Enable = 'on';   
               case 9
                    disableForm1(app); 
                    enableForm2(app)
           end


        end

        % Value changed function: VariablesDropDown_2
        function VariablesDropDown_2ValueChanged(app, event)
            value = app.VariablesDropDown_2.Value;
            switch value
                case "Water head "
                    app.prof_flag = 1;
                case "Degree of saturation"
                    app.prof_flag = 2;                    
            end
        end

        % Value changed function: VariablesDropDown
        function VariablesDropDownValueChanged(app, event)
            value = app.VariablesDropDown.Value;
            switch value            
                case "water head_A"
                    app.hist_flag = 1;
                case "Infiltration rate" 
                    app.hist_flag = 6;   
                case "Runoff" 
                    app.hist_flag = 7;
                case "Cumulative infiltration" 
                    app.hist_flag = 8;
                case "Cumulative Runoff" 
                    app.hist_flag = 9;                       
            end

        end

        % Button pushed function: PlotInitialButton
        function PlotInitialButtonPushed(app, event)
            H = app.HeightmEditField.Value;
            Z = [0:0.01:H];  
            switch app.IC_index
                case 1
                    for i = 1: length(Z)
                        f(i) = eval(app.constantEditField.Value);
                    end
                    F = f;
         
                case 2
                f = (app.functionEditField.Value);
                F = eval(f);
            end
                
            plot(app.UIInitial,F,Z,'LineWidth',1)
 

        end

        % Selection changed function: Tree2
        function Tree2SelectionChanged(app, event)
            selectedNodes = app.Tree2.SelectedNodes;

             % Enable form only when a child is selected    
           Items = {'Load','Profile variation','Time history','Factor of safety'};
%            subItems = {'Constant_W','Time history_W','Constant_V','Time history_V','Constant_H','Time history_H'};
           Loading_Index_aux = find(strcmp(selectedNodes.Text,Items),1);
           app.Loading_Index = Loading_Index_aux;


           switch Loading_Index_aux
               case 1 
                    app.Panel_15.Visible = 'on';
                    
                    app.Panel_13.Visible = 'off';
                    app.Panel_14.Visible = 'off';
                    app.Panel_16.Visible = 'off';                   
               
                   
               case 2 
                    app.Panel_16.Visible = 'on';
                    
                    app.Panel_13.Visible = 'off';
                    app.Panel_14.Visible = 'off';
                    app.Panel_15.Visible = 'off';                      
                   
               case 3 
                    app.Panel_13.Visible = 'on';
                    
                    app.Panel_14.Visible = 'off';
                    app.Panel_15.Visible = 'off';
                    app.Panel_16.Visible = 'off';                      
                    
                 
               case 4
                    app.Panel_14.Visible = 'on';
                    
                    app.Panel_13.Visible = 'off';
                    app.Panel_15.Visible = 'off';
                    app.Panel_16.Visible = 'off';   
                

           end
            
            
        end

        % Button pushed function: PlotButtonFS_SLIP
        function PlotButtonFS_SLIPPushed(app, event)
  cla(app.FS)
        for j = 1:(app.output_times)  
            plot(app.FS ,app.RESULTS.profiles_FS_slips(:,j),(app.FE_data.Z),'LineWidth',1)
                app.FS.XLabel.String = 'Factor of safety, FS';            
                hold(app.FS, 'on') 
        end
        end

        % Button pushed function: ClicktostartButton
        function ClicktostartButtonPushed2(app, event)
            
            clc
            tic

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%used for passing loading variables

        app.q_r =  app.RainfallIntensitymsEditField_2.Value;
        app.q_py = app.NormalStresskPaEditField.Value;
        app.q_px = app.ShearStresskPaEditField.Value;


%%%%%%%%%%%%%%%%%%%%%%%%%
% app.n_layer = 1;      
%%%%%%%%%%prepared for Hydrological parameters%%%%%%%%%%%%%%%%%

%%%%%%%%%%%%%%%%%%initializing the first layer%%%%%%%%%%%%%
                app.VG.theta_res_1 = 0;
                app.VG.theta_sat_1 = 1;
                app.VG.K_sat_1     = 0;
                app.VG.alpha_SWCC_1 = 0;
                app.VG.alpha_HCF_1 = 0;
                app.VG.n_SWCC_1     =0;
                app.VG.n_HCF_1 = 0;
                app.VG.m_SWCC_1 = 0;    
                app.VG.m_HCF_1 = 0;    
        
                app.GN.theta_res_1 = 0;
                app.GN.theta_sat_1 = 1;
                app.GN.K_sat_1     = 0;
                app.GN.alpha_SWCC_1 = 0;
                app.GN.alpha_HCF_1 = 0;    
        
                app.BC.K_sat_1     = 0;
                app.BC.lambda_1 = 0;
                app.BC.hs_1     = 0;
                app.BC.theta_res_1   = 0;
                app.BC.theta_sat_1   = 1;

         if   app.n_layer == 1
           switch app.HydraulicmodelDropDown.Value  
               
               case "Van Genuchten model"
               app.VG.theta_res_1 = app.theta_resEditField.Value;
               app.VG.theta_sat_1 = app.theta_satEditField.Value;
               app.VG.K_sat_1     = app.K_satEditField.Value;
               app.VG.alpha_SWCC_1 = app.alpha_SWCCEditField.Value;
               app.VG.alpha_HCF_1 = app.alpha_HCFEditField.Value;
               app.VG.n_SWCC_1     = app.n_SWCCEditField.Value;
               app.VG.n_HCF_1 = app.n_HCFEditField.Value;
               app.VG.m_SWCC_1 = app.m_SWCCEditField.Value;    
               app.VG.m_HCF_1 = app.m_HCFEditField.Value;    
               
               case "Gardner model"    
               app.GN.theta_res_1 = app.theta_resEditField_3.Value;
               app.GN.theta_sat_1 = app.theta_satEditField_3.Value;
               app.GN.K_sat_1     = app.K_satEditField_3.Value;
               app.GN.alpha_SWCC_1 = app.alpha_SWCCEditField_2.Value;
               app.GN.alpha_HCF_1 = app.alpha_HCFEditField_2.Value;    
               
               case  "Brooks and Corey model"    
                app.BC.K_sat_1     = app.K_satEditField_2.Value;
                app.BC.lambda_1 = app.lambdaEditField.Value;
                app.BC.hs_1     = app.hsEditField.Value;
                app.BC.theta_res_1   = app.theta_resEditField_2.Value;
                app.BC.theta_sat_1   = app.theta_satEditField_2.Value; 
           end
         end

        
%%%%%%%%%%%%%%%%%%%initialize the second layer%%%%%%%%%%%%%%%%%%% 
                app.VG.theta_res_2 = 0;
                app.VG.theta_sat_2 = 1;
                app.VG.K_sat_2     = 0;
                app.VG.alpha_SWCC_2 = 0;
                app.VG.alpha_HCF_2 = 0;
                app.VG.n_SWCC_2     =0;
                app.VG.n_HCF_2 = 0;
                app.VG.m_SWCC_2 = 0;    
                app.VG.m_HCF_2 = 0;    
        
                app.GN.theta_res_2 = 0;
                app.GN.theta_sat_2 = 1;
                app.GN.K_sat_2     = 0;
                app.GN.alpha_SWCC_2 = 0;
                    app.GN.alpha_HCF_2 = 0;    
        
                app.BC.K_sat_2     = 0;
                app.BC.lambda_2 = 0;
                app.BC.hs_2     = 0;
                app.BC.theta_res_2  = 0;
                app.BC.theta_sat_2   = 1;

        if   app.n_layer == 2
          switch app.HydraulicmodelDropDown.Value  
               
               case "Van Genuchten model"
               app.VG.theta_res_1 = app.theta_resEditField.Value;
               app.VG.theta_sat_1 = app.theta_satEditField.Value;
               app.VG.K_sat_1     = app.K_satEditField.Value;
               app.VG.alpha_SWCC_1 = app.alpha_SWCCEditField.Value;
               app.VG.alpha_HCF_1 = app.alpha_HCFEditField.Value;
               app.VG.n_SWCC_1     = app.n_SWCCEditField.Value;
               app.VG.n_HCF_1 = app.n_HCFEditField.Value;
               app.VG.m_SWCC_1 = app.m_SWCCEditField.Value;    
               app.VG.m_HCF_1 = app.m_HCFEditField.Value;    
               
               case "Gardner model"    
               app.GN.theta_res_1 = app.theta_resEditField_3.Value;
               app.GN.theta_sat_1 = app.theta_satEditField_3.Value;
               app.GN.K_sat_1     = app.K_satEditField_3.Value;
               app.GN.alpha_SWCC_1 = app.alpha_SWCCEditField_2.Value;
               app.GN.alpha_HCF_1 = app.alpha_HCFEditField_2.Value;    
               
               case  "Brooks and Corey model"    
                app.BC.K_sat_1     = app.K_satEditField_2.Value;
                app.BC.lambda_1 = app.lambdaEditField.Value;
                app.BC.hs_1     = app.hsEditField.Value;
                app.BC.theta_res_1   = app.theta_resEditField_2.Value;
                app.BC.theta_sat_1   = app.theta_satEditField_2.Value; 
           end
        
                switch app.HydraulicmodelDropDown_2.Value 
                    case "Van Genuchten model"
                    app.VG.theta_res_2 = app.theta_resEditField_4.Value;
                    app.VG.theta_sat_2 = app.theta_satEditField_4.Value;
                    app.VG.K_sat_2     = app.K_satEditField_4.Value;
                    app.VG.alpha_SWCC_2 = app.alpha_SWCCEditField_3.Value;
                    app.VG.alpha_HCF_2 = app.alpha_HCFEditField_3.Value;
                    app.VG.n_SWCC_2     = app.n_SWCCEditField_2.Value;
                    app.VG.n_HCF_2 = app.n_HCFEditField_2.Value;
                    app.VG.m_SWCC_2 = app.m_SWCCEditField_2.Value;    
                    app.VG.m_HCF_2 = app.m_HCFEditField_2.Value;    
                    
                    case "Gardner model"             
                    app.GN.theta_res_2 = app.theta_resEditField_6.Value;
                    app.GN.theta_sat_2 = app.theta_satEditField_6.Value;
                    app.GN.K_sat_2     = app.K_satEditField_6.Value;
                    app.GN.alpha_SWCC_2 = app.alpha_SWCCEditField_4.Value;
                    app.GN.alpha_HCF_2 = app.alpha_HCFEditField_4.Value;    
                    
                    case  "Brooks and Corey model"          
                    app.BC.K_sat_2     = app.K_satEditField_5.Value;
                    app.BC.lambda_2 = app.lambdaEditField_2.Value;
                    app.BC.hs_2     = app.hsEditField_2.Value;
                    app.BC.theta_res_2   = app.theta_resEditField_5.Value;
                    app.BC.theta_sat_2   = app.theta_satEditField_5.Value;
                end 
        end
          

 %%%%%%%%%%%%%%%%%%%%%%%%%initialize the third layer      
                      app.VG.theta_res_3 = 0;
                app.VG.theta_sat_3 = 1;
                app.VG.K_sat_3     = 0;
                app.VG.alpha_SWCC_3 = 0;
                app.VG.alpha_HCF_3 = 0;
                app.VG.n_SWCC_3     =0;
                app.VG.n_HCF_3 = 0;
                app.VG.m_SWCC_3 = 0;    
                app.VG.m_HCF_3 = 0;    
        
                app.GN.theta_res_3 = 0;
                app.GN.theta_sat_3 = 1;
                app.GN.K_sat_3     = 0;
                app.GN.alpha_SWCC_3 = 0;
                app.GN.alpha_HCF_3 = 0;    
        
                app.BC.K_sat_3     = 0;
                app.BC.lambda_3 = 0;
                app.BC.hs_3     = 0;
                app.BC.theta_res_3  = 0;
                app.BC.theta_sat_3   = 1;   
        if   app.n_layer == 3
          switch app.HydraulicmodelDropDown.Value  
               
               case "Van Genuchten model"
               app.VG.theta_res_1 = app.theta_resEditField.Value;
               app.VG.theta_sat_1 = app.theta_satEditField.Value;
               app.VG.K_sat_1     = app.K_satEditField.Value;
               app.VG.alpha_SWCC_1 = app.alpha_SWCCEditField.Value;
               app.VG.alpha_HCF_1 = app.alpha_HCFEditField.Value;
               app.VG.n_SWCC_1     = app.n_SWCCEditField.Value;
               app.VG.n_HCF_1 = app.n_HCFEditField.Value;
               app.VG.m_SWCC_1 = app.m_SWCCEditField.Value;    
               app.VG.m_HCF_1 = app.m_HCFEditField.Value;    
               
               case "Gardner model"    
               app.GN.theta_res_1 = app.theta_resEditField_3.Value;
               app.GN.theta_sat_1 = app.theta_satEditField_3.Value;
               app.GN.K_sat_1     = app.K_satEditField_3.Value;
               app.GN.alpha_SWCC_1 = app.alpha_SWCCEditField_2.Value;
               app.GN.alpha_HCF_1 = app.alpha_HCFEditField_2.Value;    
               
               case  "Brooks and Corey model"    
                app.BC.K_sat_1     = app.K_satEditField_2.Value;
                app.BC.lambda_1 = app.lambdaEditField.Value;
                app.BC.hs_1     = app.hsEditField.Value;
                app.BC.theta_res_1   = app.theta_resEditField_2.Value;
                app.BC.theta_sat_1   = app.theta_satEditField_2.Value; 
           end
        
                switch app.HydraulicmodelDropDown_2.Value 
                    case "Van Genuchten model"
                    app.VG.theta_res_2 = app.theta_resEditField_4.Value;
                    app.VG.theta_sat_2 = app.theta_satEditField_4.Value;
                    app.VG.K_sat_2     = app.K_satEditField_4.Value;
                    app.VG.alpha_SWCC_2 = app.alpha_SWCCEditField_3.Value;
                    app.VG.alpha_HCF_2 = app.alpha_HCFEditField_3.Value;
                    app.VG.n_SWCC_2     = app.n_SWCCEditField_2.Value;
                    app.VG.n_HCF_2 = app.n_HCFEditField_2.Value;
                    app.VG.m_SWCC_2 = app.m_SWCCEditField_2.Value;    
                    app.VG.m_HCF_2 = app.m_HCFEditField_2.Value;    
                    
                    case "Gardner model"             
                    app.GN.theta_res_2 = app.theta_resEditField_6.Value;
                    app.GN.theta_sat_2 = app.theta_satEditField_6.Value;
                    app.GN.K_sat_2     = app.K_satEditField_6.Value;
                    app.GN.alpha_SWCC_2 = app.alpha_SWCCEditField_4.Value;
                    app.GN.alpha_HCF_2 = app.alpha_HCFEditField_4.Value;    
                    
                    case  "Brooks and Corey model"          
                    app.BC.K_sat_2     = app.K_satEditField_5.Value;
                    app.BC.lambda_2 = app.lambdaEditField_2.Value;
                    app.BC.hs_2     = app.hsEditField_2.Value;
                    app.BC.theta_res_2   = app.theta_resEditField_5.Value;
                    app.BC.theta_sat_2   = app.theta_satEditField_5.Value;
                end 
           switch app.HydraulicmodelDropDown_3.Value 
               
               case "Van Genuchten model"
               app.VG.theta_res_3 = app.theta_resEditField_7.Value;
                app.VG.theta_sat_3 = app.theta_satEditField_7.Value;
                app.VG.K_sat_3     = app.K_satEditField_7.Value;
                app.VG.alpha_SWCC_3 = app.alpha_SWCCEditField_5.Value;
                app.VG.alpha_HCF_3 = app.alpha_HCFEditField_5.Value;
                app.VG.n_SWCC_3     = app.n_SWCCEditField_3.Value;
                app.VG.n_HCF_3 = app.n_HCFEditField_3.Value;
                app.VG.m_SWCC_3 = app.m_SWCCEditField_3.Value;    
                app.VG.m_HCF_3 = app.m_HCFEditField_3.Value;    
                
               case "Gardner model"    
                app.GN.theta_res_3 = app.theta_resEditField_9.Value;
                app.GN.theta_sat_3 = app.theta_satEditField_9.Value;
                app.GN.K_sat_3     = app.K_satEditField_9.Value;
                app.GN.alpha_SWCC_3 = app.alpha_SWCCEditField_6.Value;
                app.GN.alpha_HCF_3 = app.alpha_HCFEditField_6.Value;    
                
               case "Brooks and Corey model"
                app.BC.K_sat_3    = app.K_satEditField_8.Value;
                app.BC.lambda_3 = app.lambdaEditField_3.Value;
                app.BC.hs_3     = app.hsEditField_3.Value;
                app.BC.theta_res_3   = app.theta_resEditField_8.Value;
                app.BC.theta_sat_3   = app.theta_satEditField_8.Value;
           end
       end
        
 %%%%%%%%%%%%%%%%%%%%initialize the fouth layer%%%%%%%%%%%%%
                 app.VG.theta_res_4 = 0;
                app.VG.theta_sat_4 = 1;
                app.VG.K_sat_4     = 0;
                app.VG.alpha_SWCC_4 = 0;
                app.VG.alpha_HCF_4 = 0;
                app.VG.n_SWCC_4     =0;
                app.VG.n_HCF_4 = 0;
                app.VG.m_SWCC_4 = 0;    
                app.VG.m_HCF_4 = 0;    
        
                app.GN.theta_res_4 = 0;
                app.GN.theta_sat_4 = 1;
                app.GN.K_sat_4     = 0;
                app.GN.alpha_SWCC_4 = 0;
                app.GN.alpha_HCF_4 = 0;    
        
                app.BC.K_sat_4     = 0;
                app.BC.lambda_4 = 0;
                app.BC.hs_4     = 0;
                app.BC.theta_res_4  = 0;
                app.BC.theta_sat_4   = 1;  
      
        if   app.n_layer == 4
          switch app.HydraulicmodelDropDown.Value  
               
               case "Van Genuchten model"
               app.VG.theta_res_1 = app.theta_resEditField.Value;
               app.VG.theta_sat_1 = app.theta_satEditField.Value;
               app.VG.K_sat_1     = app.K_satEditField.Value;
               app.VG.alpha_SWCC_1 = app.alpha_SWCCEditField.Value;
               app.VG.alpha_HCF_1 = app.alpha_HCFEditField.Value;
               app.VG.n_SWCC_1     = app.n_SWCCEditField.Value;
               app.VG.n_HCF_1 = app.n_HCFEditField.Value;
               app.VG.m_SWCC_1 = app.m_SWCCEditField.Value;    
               app.VG.m_HCF_1 = app.m_HCFEditField.Value;    
               
               case "Gardner model"    
               app.GN.theta_res_1 = app.theta_resEditField_3.Value;
               app.GN.theta_sat_1 = app.theta_satEditField_3.Value;
               app.GN.K_sat_1     = app.K_satEditField_3.Value;
               app.GN.alpha_SWCC_1 = app.alpha_SWCCEditField_2.Value;
               app.GN.alpha_HCF_1 = app.alpha_HCFEditField_2.Value;    
               
               case  "Brooks and Corey model"    
                app.BC.K_sat_1     = app.K_satEditField_2.Value;
                app.BC.lambda_1 = app.lambdaEditField.Value;
                app.BC.hs_1     = app.hsEditField.Value;
                app.BC.theta_res_1   = app.theta_resEditField_2.Value;
                app.BC.theta_sat_1   = app.theta_satEditField_2.Value; 
           end
        
                switch app.HydraulicmodelDropDown_2.Value 
                    case "Van Genuchten model"
                    app.VG.theta_res_2 = app.theta_resEditField_4.Value;
                    app.VG.theta_sat_2 = app.theta_satEditField_4.Value;
                    app.VG.K_sat_2     = app.K_satEditField_4.Value;
                    app.VG.alpha_SWCC_2 = app.alpha_SWCCEditField_3.Value;
                    app.VG.alpha_HCF_2 = app.alpha_HCFEditField_3.Value;
                    app.VG.n_SWCC_2     = app.n_SWCCEditField_2.Value;
                    app.VG.n_HCF_2 = app.n_HCFEditField_2.Value;
                    app.VG.m_SWCC_2 = app.m_SWCCEditField_2.Value;    
                    app.VG.m_HCF_2 = app.m_HCFEditField_2.Value;    
                    
                    case "Gardner model"             
                    app.GN.theta_res_2 = app.theta_resEditField_6.Value;
                    app.GN.theta_sat_2 = app.theta_satEditField_6.Value;
                    app.GN.K_sat_2     = app.K_satEditField_6.Value;
                    app.GN.alpha_SWCC_2 = app.alpha_SWCCEditField_4.Value;
                    app.GN.alpha_HCF_2 = app.alpha_HCFEditField_4.Value;    
                    
                    case  "Brooks and Corey model"          
                    app.BC.K_sat_2     = app.K_satEditField_5.Value;
                    app.BC.lambda_2 = app.lambdaEditField_2.Value;
                    app.BC.hs_2     = app.hsEditField_2.Value;
                    app.BC.theta_res_2   = app.theta_resEditField_5.Value;
                    app.BC.theta_sat_2   = app.theta_satEditField_5.Value;
                end 
           switch app.HydraulicmodelDropDown_3.Value 
               
               case "Van Genuchten model"
               app.VG.theta_res_3 = app.theta_resEditField_7.Value;
                app.VG.theta_sat_3 = app.theta_satEditField_7.Value;
                app.VG.K_sat_3     = app.K_satEditField_7.Value;
                app.VG.alpha_SWCC_3 = app.alpha_SWCCEditField_5.Value;
                app.VG.alpha_HCF_3 = app.alpha_HCFEditField_5.Value;
                app.VG.n_SWCC_3     = app.n_SWCCEditField_3.Value;
                app.VG.n_HCF_3 = app.n_HCFEditField_3.Value;
                app.VG.m_SWCC_3 = app.m_SWCCEditField_3.Value;    
                app.VG.m_HCF_3 = app.m_HCFEditField_3.Value;    
                
               case "Gardner model"    
                app.GN.theta_res_3 = app.theta_resEditField_9.Value;
                app.GN.theta_sat_3 = app.theta_satEditField_9.Value;
                app.GN.K_sat_3     = app.K_satEditField_9.Value;
                app.GN.alpha_SWCC_3 = app.alpha_SWCCEditField_6.Value;
                app.GN.alpha_HCF_3 = app.alpha_HCFEditField_6.Value;    
                
               case "Brooks and Corey model"
                app.BC.K_sat_3    = app.K_satEditField_8.Value;
                app.BC.lambda_3 = app.lambdaEditField_3.Value;
                app.BC.hs_3     = app.hsEditField_3.Value;
                app.BC.theta_res_3   = app.theta_resEditField_8.Value;
                app.BC.theta_sat_3   = app.theta_satEditField_8.Value;
           end            
           switch app.HydraulicmodelDropDown_4.Value
               
               case "Van Genuchten model"
                app.VG.theta_res_4 = app.theta_resEditField_10.Value;
                app.VG.theta_sat_4 = app.theta_satEditField_10.Value;
                app.VG.K_sat_4     = app.K_satEditField_10.Value;
                app.VG.alpha_SWCC_4 = app.alpha_SWCCEditField_7.Value;
                app.VG.alpha_HCF_4 = app.alpha_HCFEditField_7.Value;
                app.VG.n_SWCC_4     = app.n_SWCCEditField_4.Value;
                app.VG.n_HCF_4 = app.n_HCFEditField_4.Value;
                app.VG.m_SWCC_4 = app.m_SWCCEditField_4.Value;    
                app.VG.m_HCF_4 = app.m_HCFEditField_4.Value; 
                
               case "Gardner model"        
                app.GN.theta_res_4 = app.theta_resEditField_12.Value;
                app.GN.theta_sat_4 = app.theta_satEditField_12.Value;
                app.GN.K_sat_4     = app.K_satEditField_12.Value;
                app.GN.alpha_SWCC_4 = app.alpha_SWCCEditField_8.Value;
                app.GN.alpha_HCF_4 = app.alpha_HCFEditField_8.Value;    
                
               case "Brooks and Corey model"        
                app.BC.K_sat_4     = app.K_satEditField_11.Value;
                app.BC.lambda_4 = app.lambdaEditField_4.Value;
                app.BC.hs_4     = app.hsEditField_4.Value;
                app.BC.theta_res_4   = app.theta_resEditField_11.Value;
                app.BC.theta_sat_4   = app.theta_satEditField_11.Value;
                
           end
       end

%%%%%%%%%%%%%%%%%%%%%%%%%initialize the fifth layer%%%%%%%%%%%
                app.VG.theta_res_5 = 0;
                app.VG.theta_sat_5 = 1;
                app.VG.K_sat_5    = 0;
                app.VG.alpha_SWCC_5 = 0;
                app.VG.alpha_HCF_5 = 0;
                app.VG.n_SWCC_5     =0;
                app.VG.n_HCF_5 = 0;
                app.VG.m_SWCC_5 = 0;    
                app.VG.m_HCF_5 = 0;    
        
                app.GN.theta_res_5 = 0;
                app.GN.theta_sat_5 = 1;
                app.GN.K_sat_5     = 0;
                app.GN.alpha_SWCC_5 = 0;
                app.GN.alpha_HCF_5 = 0;    
        
                app.BC.K_sat_5     = 0;
                app.BC.lambda_5 = 0;
                app.BC.hs_5     = 0;
                app.BC.theta_res_5  = 0;
                app.BC.theta_sat_5  = 1;   
                
        if   app.n_layer == 5 
          switch app.HydraulicmodelDropDown.Value  
               
               case "Van Genuchten model"
               app.VG.theta_res_1 = app.theta_resEditField.Value;
               app.VG.theta_sat_1 = app.theta_satEditField.Value;
               app.VG.K_sat_1     = app.K_satEditField.Value;
               app.VG.alpha_SWCC_1 = app.alpha_SWCCEditField.Value;
               app.VG.alpha_HCF_1 = app.alpha_HCFEditField.Value;
               app.VG.n_SWCC_1     = app.n_SWCCEditField.Value;
               app.VG.n_HCF_1 = app.n_HCFEditField.Value;
               app.VG.m_SWCC_1 = app.m_SWCCEditField.Value;    
               app.VG.m_HCF_1 = app.m_HCFEditField.Value;    
               
               case "Gardner model"    
               app.GN.theta_res_1 = app.theta_resEditField_3.Value;
               app.GN.theta_sat_1 = app.theta_satEditField_3.Value;
               app.GN.K_sat_1     = app.K_satEditField_3.Value;
               app.GN.alpha_SWCC_1 = app.alpha_SWCCEditField_2.Value;
               app.GN.alpha_HCF_1 = app.alpha_HCFEditField_2.Value;    
               
               case  "Brooks and Corey model"    
                app.BC.K_sat_1     = app.K_satEditField_2.Value;
                app.BC.lambda_1 = app.lambdaEditField.Value;
                app.BC.hs_1     = app.hsEditField.Value;
                app.BC.theta_res_1   = app.theta_resEditField_2.Value;
                app.BC.theta_sat_1   = app.theta_satEditField_2.Value; 
           end
        
                switch app.HydraulicmodelDropDown_2.Value 
                    case "Van Genuchten model"
                    app.VG.theta_res_2 = app.theta_resEditField_4.Value;
                    app.VG.theta_sat_2 = app.theta_satEditField_4.Value;
                    app.VG.K_sat_2     = app.K_satEditField_4.Value;
                    app.VG.alpha_SWCC_2 = app.alpha_SWCCEditField_3.Value;
                    app.VG.alpha_HCF_2 = app.alpha_HCFEditField_3.Value;
                    app.VG.n_SWCC_2     = app.n_SWCCEditField_2.Value;
                    app.VG.n_HCF_2 = app.n_HCFEditField_2.Value;
                    app.VG.m_SWCC_2 = app.m_SWCCEditField_2.Value;    
                    app.VG.m_HCF_2 = app.m_HCFEditField_2.Value;    
                    
                    case "Gardner model"             
                    app.GN.theta_res_2 = app.theta_resEditField_6.Value;
                    app.GN.theta_sat_2 = app.theta_satEditField_6.Value;
                    app.GN.K_sat_2     = app.K_satEditField_6.Value;
                    app.GN.alpha_SWCC_2 = app.alpha_SWCCEditField_4.Value;
                    app.GN.alpha_HCF_2 = app.alpha_HCFEditField_4.Value;    
                    
                    case  "Brooks and Corey model"          
                    app.BC.K_sat_2     = app.K_satEditField_5.Value;
                    app.BC.lambda_2 = app.lambdaEditField_2.Value;
                    app.BC.hs_2     = app.hsEditField_2.Value;
                    app.BC.theta_res_2   = app.theta_resEditField_5.Value;
                    app.BC.theta_sat_2   = app.theta_satEditField_5.Value;
                end 
           switch app.HydraulicmodelDropDown_3.Value 
               
               case "Van Genuchten model"
               app.VG.theta_res_3 = app.theta_resEditField_7.Value;
                app.VG.theta_sat_3 = app.theta_satEditField_7.Value;
                app.VG.K_sat_3     = app.K_satEditField_7.Value;
                app.VG.alpha_SWCC_3 = app.alpha_SWCCEditField_5.Value;
                app.VG.alpha_HCF_3 = app.alpha_HCFEditField_5.Value;
                app.VG.n_SWCC_3     = app.n_SWCCEditField_3.Value;
                app.VG.n_HCF_3 = app.n_HCFEditField_3.Value;
                app.VG.m_SWCC_3 = app.m_SWCCEditField_3.Value;    
                app.VG.m_HCF_3 = app.m_HCFEditField_3.Value;    
                
               case "Gardner model"    
                app.GN.theta_res_3 = app.theta_resEditField_9.Value;
                app.GN.theta_sat_3 = app.theta_satEditField_9.Value;
                app.GN.K_sat_3     = app.K_satEditField_9.Value;
                app.GN.alpha_SWCC_3 = app.alpha_SWCCEditField_6.Value;
                app.GN.alpha_HCF_3 = app.alpha_HCFEditField_6.Value;    
                
               case "Brooks and Corey model"
                app.BC.K_sat_3    = app.K_satEditField_8.Value;
                app.BC.lambda_3 = app.lambdaEditField_3.Value;
                app.BC.hs_3     = app.hsEditField_3.Value;
                app.BC.theta_res_3   = app.theta_resEditField_8.Value;
                app.BC.theta_sat_3   = app.theta_satEditField_8.Value;
           end            
           switch app.HydraulicmodelDropDown_4.Value
               
               case "Van Genuchten model"
                app.VG.theta_res_4 = app.theta_resEditField_10.Value;
                app.VG.theta_sat_4 = app.theta_satEditField_10.Value;
                app.VG.K_sat_4     = app.K_satEditField_10.Value;
                app.VG.alpha_SWCC_4 = app.alpha_SWCCEditField_7.Value;
                app.VG.alpha_HCF_4 = app.alpha_HCFEditField_7.Value;
                app.VG.n_SWCC_4     = app.n_SWCCEditField_4.Value;
                app.VG.n_HCF_4 = app.n_HCFEditField_4.Value;
                app.VG.m_SWCC_4 = app.m_SWCCEditField_4.Value;    
                app.VG.m_HCF_4 = app.m_HCFEditField_4.Value; 
                
               case "Gardner model"        
                app.GN.theta_res_4 = app.theta_resEditField_12.Value;
                app.GN.theta_sat_4 = app.theta_satEditField_12.Value;
                app.GN.K_sat_4     = app.K_satEditField_12.Value;
                app.GN.alpha_SWCC_4 = app.alpha_SWCCEditField_8.Value;
                app.GN.alpha_HCF_4 = app.alpha_HCFEditField_8.Value;    
                
               case "Brooks and Corey model"        
                app.BC.K_sat_4     = app.K_satEditField_11.Value;
                app.BC.lambda_4 = app.lambdaEditField_4.Value;
                app.BC.hs_4     = app.hsEditField_4.Value;
                app.BC.theta_res_4   = app.theta_resEditField_11.Value;
                app.BC.theta_sat_4   = app.theta_satEditField_11.Value;
                
           end            
           switch app.HydraulicmodelDropDown_5.Value
                case "Van Genuchten model"           
                app.VG.theta_res_5 = app.theta_resEditField_13.Value;
                app.VG.theta_sat_5 = app.theta_satEditField_13.Value;
                app.VG.K_sat_5    = app.K_satEditField_13.Value;
                app.VG.alpha_SWCC_5 = app.alpha_SWCCEditField_9.Value;
                app.VG.alpha_HCF_5 = app.alpha_HCFEditField_9.Value;
                app.VG.n_SWCC_5     = app.n_SWCCEditField_5.Value;
                app.VG.n_HCF_5 = app.n_HCFEditField_5.Value;
                app.VG.m_SWCC_5 = app.m_SWCCEditField_5.Value;    
                app.VG.m_HCF_5 = app.m_HCFEditField_5.Value;    
               case "Gardner model"     
                app.GN.theta_res_5 = app.theta_resEditField_15.Value;
                app.GN.theta_sat_5 = app.theta_satEditField_15.Value;
                app.GN.K_sat_5    = app.K_satEditField_15.Value;
                app.GN.alpha_SWCC_5 = app.alpha_SWCCEditField_10.Value;
                app.GN.alpha_HCF_5 = app.alpha_HCFEditField_10.Value;    
               case "Brooks and Corey model"             
                app.BC.K_sat_5     = app.K_satEditField_14.Value;
                app.BC.lambda_5 = app.lambdaEditField_5.Value;
                app.BC.hs_5     = app.hsEditField_5.Value;
                app.BC.theta_res_5   = app.theta_resEditField_14.Value;
                app.BC.theta_sat_5   = app.theta_satEditField_14.Value;
           end
       end 
%%%%%%%%%%prepared for mechanical parameters%%%%%%%%%%%%%%%%%     
%%%%%%%%%%%%%%%%%%initializing the first layer%%%%%%%%%%%%%
        app.chi.layer_1 = 0;
        app.Se.layer_1 = 0;       
        app.EM.E_1 = 0;
        app.EM.G_1 = 0;
        app.EM.F_1 = 0;        
        app.SP.c_1 = 0;
        app.SP.phi_1 = 0;
        app.SP.phi_b_1 = 0;        
        app.gamma_s.layer1 = 0; 

        app.chi.layer_2 = 0;
        app.Se.layer_2 = 0;        
        app.EM.E_2 = 0;
        app.EM.G_2 = 0;
        app.EM.F_2 = 0;        
        app.SP.c_2 = 0;
        app.SP.phi_2 = 0;
        app.SP.phi_b_2 = 0;        
        app.gamma_s.layer2 = 0; 

        app.chi.layer_3 = 0;
        app.Se.layer_3 = 0;        
        app.EM.E_3 = 0;
        app.EM.G_3 = 0;
        app.EM.F_3 = 0;        
        app.SP.c_3 = 0;
        app.SP.phi_3 = 0;
        app.SP.phi_b_3 = 0;        
        app.gamma_s.layer3 = 0; 
        
        app.chi.layer_4 = 0;
        app.Se.layer_4 = 0;        
        app.EM.E_4 = 0;
        app.EM.G_4 = 0;
        app.EM.F_4 = 0;        
        app.SP.c_4 = 0;
        app.SP.phi_4 = 0;
        app.SP.phi_b_4 = 0;        
        app.gamma_s.layer4 = 0; 
        
        app.chi.layer_5 = 0;
        app.Se.layer_5 = 0;        
        app.EM.E_5 = 0;
        app.EM.G_5 = 0;
        app.EM.F_5 = 0;        
        app.SP.c_5 = 0;
        app.SP.phi_5 = 0;
        app.SP.phi_b_5 = 0;        
        app.gamma_s.layer5 = 0; 

      if   app.n_layer == 1
          
                app.chi.layer_1 = app.EditField_12.Value;
                app.Se.layer_1 = app.AirentrysuctionSeEditField.Value;
                app.EM.E_1 = app.EEditField.Value;
                app.EM.G_1 = app.GEditField.Value;
                app.EM.F_1 = app.FEditField.Value;                
                app.SP.c_1 = app.cEditField.Value;
                app.SP.phi_1 = app.EditField_7.Value;
                app.SP.phi_b_1 = app.bEditField.Value;                   
                app.gamma_s.layer1 = app.sNm3EditField.Value;  
                
      end
        
%%%%%%%%%%%%%%%%%%%initialize the second layer%%%%%%%%%%%%%%%%%%% 


      if   app.n_layer == 2
    
                app.chi.layer_1 = app.EditField_12.Value;  
                app.Se.layer_1 = app.AirentrysuctionSeEditField.Value;                 
                app.EM.E_1 = app.EEditField.Value;
                app.EM.G_1 = app.GEditField.Value;
                app.EM.F_1 = app.FEditField.Value;                
                app.SP.c_1 = app.cEditField.Value;
                app.SP.phi_1 = app.EditField_7.Value;
                app.SP.phi_b_1 = app.bEditField.Value;                  
                app.gamma_s.layer1 = app.sNm3EditField.Value;  

                app.chi.layer_2 = app.EditField_13.Value; 
                app.Se.layer_2 = app.AirentrysuctionSeEditField_2.Value;                
                app.EM.E_2 = app.EEditField_2.Value;
                app.EM.G_2 = app.GEditField_2.Value;
                app.EM.F_2 = app.FEditField_2.Value;                
                app.SP.c_2 = app.cEditField_2.Value;
                app.SP.phi_2 = app.EditField_8.Value;
                app.SP.phi_b_2 = app.bEditField_2.Value; 
                app.gamma_s.layer2 = app.sNm3EditField_2.Value;  
                
      end
          

 %%%%%%%%%%%%%%%%%%%%%%%%%initialize the third layer

        
      if   app.n_layer == 3    
      
                app.chi.layer_1 = app.EditField_12.Value;
                app.Se.layer_1 = app.AirentrysuctionSeEditField.Value;                  
                app.EM.E_1 = app.EEditField.Value;
                app.EM.G_1 = app.GEditField.Value;
                app.EM.F_1 = app.FEditField.Value;              
                app.SP.c_1 = app.cEditField.Value;
                app.SP.phi_1 = app.EditField_7.Value;
                app.SP.phi_b_1 = app.bEditField.Value;                
                app.gamma_s.layer1 = app.sNm3EditField.Value;  
               

                app.chi.layer_2 = app.EditField_13.Value;   
                app.Se.layer_2 = app.AirentrysuctionSeEditField_2.Value;                  
                app.EM.E_2 = app.EEditField_2.Value;
                app.EM.G_2 = app.GEditField_2.Value;
                app.EM.F_2 = app.FEditField_2.Value;                
                app.SP.c_2 = app.cEditField_2.Value;
                app.SP.phi_2 = app.EditField_8.Value;
                app.SP.phi_b_2 = app.bEditField_2.Value;                  
                app.gamma_s.layer2 = app.sNm3EditField_2.Value;  
                

                app.chi.layer_3 = app.EditField_14.Value;  
                app.Se.layer_3 = app.AirentrysuctionSeEditField_3.Value;                  
                app.EM.E_3 = app.EEditField_3.Value;
                app.EM.G_3 = app.GEditField_3.Value;
                app.EM.F_3 = app.FEditField_3.Value;                
                app.SP.c_3 = app.cEditField_3.Value;
                app.SP.phi_3 = app.EditField_9.Value;
                app.SP.phi_b_3 = app.bEditField_3.Value;  
                app.gamma_s.layer3 = app.sNm3EditField_3.Value;  
                          
       end
        
 %%%%%%%%%%%%%%%%%%%%initialize the fouth layer%%%%%%%%%%%%%

        
      if   app.n_layer == 4    
                app.chi.layer_1 = app.EditField_12.Value;
                app.Se.layer_1 = app.AirentrysuctionSeEditField.Value;                  
                app.EM.E_1 = app.EEditField.Value;
                app.EM.G_1 = app.GEditField.Value;
                app.EM.F_1 = app.FEditField.Value;              
                app.SP.c_1 = app.cEditField.Value;
                app.SP.phi_1 = app.EditField_7.Value;
                app.SP.phi_b_1 = app.bEditField.Value;                
                app.gamma_s.layer1 = app.sNm3EditField.Value;  
               
                app.chi.layer_2 = app.EditField_13.Value;   
                app.Se.layer_2 = app.AirentrysuctionSeEditField_2.Value;                  
                app.EM.E_2 = app.EEditField_2.Value;
                app.EM.G_2 = app.GEditField_2.Value;
                app.EM.F_2 = app.FEditField_2.Value;                
                app.SP.c_2 = app.cEditField_2.Value;
                app.SP.phi_2 = app.EditField_8.Value;
                app.SP.phi_b_2 = app.bEditField_2.Value;                  
                app.gamma_s.layer2 = app.sNm3EditField_2.Value;  
                
                app.chi.layer_3 = app.EditField_14.Value;  
                app.Se.layer_3 = app.AirentrysuctionSeEditField_3.Value;                  
                app.EM.E_3 = app.EEditField_3.Value;
                app.EM.G_3 = app.GEditField_3.Value;
                app.EM.F_3 = app.FEditField_3.Value;                
                app.SP.c_3 = app.cEditField_3.Value;
                app.SP.phi_3 = app.EditField_9.Value;
                app.SP.phi_b_3 = app.bEditField_3.Value;  
                app.gamma_s.layer3 = app.sNm3EditField_3.Value;                   
               
                app.chi.layer_4 = app.EditField_15.Value;
                app.Se.layer_4 = app.AirentrysuctionSeEditField_4.Value;                  
                app.EM.E_4 = app.EEditField_4.Value;
                app.EM.G_4 = app.GEditField_4.Value;
                app.EM.F_4 = app.FEditField_4.Value;                
                app.SP.c_4 = app.cEditField_4.Value;
                app.SP.phi_4 = app.EditField_10.Value;
                app.SP.phi_b_4 = app.bEditField_4.Value;                  
                app.gamma_s.layer4 = app.sNm3EditField_4.Value;  
                
          
       end

%%%%%%%%%%%%%%%%%%%%%%%%%initialize the fifth layer%%%%%%%%%%%

        
      if   app.n_layer == 5    
                app.chi.layer_1 = app.EditField_12.Value;
                app.Se.layer_1 = app.AirentrysuctionSeEditField.Value;                                 
                app.EM.E_1 = app.EEditField.Value;
                app.EM.G_1 = app.GEditField.Value;
                app.EM.F_1 = app.FEditField.Value;              
                app.SP.c_1 = app.cEditField.Value;
                app.SP.phi_1 = app.EditField_7.Value;
                app.SP.phi_b_1 = app.bEditField.Value;                
                app.gamma_s.layer1 = app.sNm3EditField.Value;  
               
                app.chi.layer_2 = app.EditField_13.Value;    
                app.Se.layer_2 = app.AirentrysuctionSeEditField_2.Value;    
                app.EM.E_2 = app.EEditField_2.Value;
                app.EM.G_2 = app.GEditField_2.Value;
                app.EM.F_2 = app.FEditField_2.Value;                
                app.SP.c_2 = app.cEditField_2.Value;
                app.SP.phi_2 = app.EditField_8.Value;
                app.SP.phi_b_2 = app.bEditField_2.Value;                  
                app.gamma_s.layer2 = app.sNm3EditField_2.Value;  
                
                app.chi.layer_3 = app.EditField_14.Value; 
                app.Se.layer_3 = app.AirentrysuctionSeEditField_3.Value;                  
                app.EM.E_3 = app.EEditField_3.Value;
                app.EM.G_3 = app.GEditField_3.Value;
                app.EM.F_3 = app.FEditField_3.Value;                
                app.SP.c_3 = app.cEditField_3.Value;
                app.SP.phi_3 = app.EditField_9.Value;
                app.SP.phi_b_3 = app.bEditField_3.Value;  
                app.gamma_s.layer3 = app.sNm3EditField_3.Value;                   

                app.chi.layer_4 = app.EditField_15.Value;
                app.Se.layer_4= app.AirentrysuctionSeEditField_4.Value;                  
                app.EM.E_4 = app.EEditField_4.Value;
                app.EM.G_4 = app.GEditField_4.Value;
                app.EM.F_4 = app.FEditField_4.Value;                
                app.SP.c_4 = app.cEditField_4.Value;
                app.SP.phi_4 = app.EditField_10.Value;
                app.SP.phi_b_4 = app.bEditField_4.Value;                  
                app.gamma_s.layer4 = app.sNm3EditField_4.Value;     
           
                app.chi.layer_5 = app.EditField_16.Value; 
                app.Se.layer_5 = app.AirentrysuctionSeEditField_5.Value;                  
                app.EM.E_5 = app.EEditField_5.Value;
                app.EM.G_5 = app.GEditField_5.Value;
                app.EM.F_5 = app.FEditField_5.Value;                
                app.SP.c_5 = app.cEditField_5.Value;
                app.SP.phi_5 = app.EditField_11.Value;
                app.SP.phi_b_5 = app.bEditField_5.Value;                
                app.gamma_s.layer5 = app.sNm3EditField_5.Value;  
                

      end             
      %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Sink term%%%%%%%%%%%%%%%%%%%%%%       
            
            switch app.ST_flag
                case 1
                   app.ST_flag = 1;
                   app.RootWaterUptakeModelPanel.Visible = 'on' ;
                   app.ST.h1 = app.h1EditField.Value;
                   app.ST.h2 = app.h2EditField.Value;
                   app.ST.h3 = app.h3EditField.Value;
                   app.ST.h4 = app.h4EditField.Value;
                   app.ST.d_r = app.d_rEditField.Value;
                   app.ST.T_p = app.T_pEditField.Value;  
                case 2
                   app.ST_flag = 2;                    
                   app.RootWaterUptakeModelPanel.Visible = 'on' ;
                   app.ST.h1 = 0;
                   app.ST.h2 = 0;
                   app.ST.h3 = 0;
                   app.ST.h4 = 0;
                   app.ST.d_r = 0;
                   app.ST.T_p = 0;                       
            end      


%%%%%%%%%%%%%%%%prepared for layer assignment%%%%%%%%%%
    height          = app.HeightmEditField.Value;                      %Domain of the problem [L]
    app.flag_mech =1;

    if  app.BC_top_flag ==1 && app.BC_bottom_flag ==1
        app.BC_flag = 11;
        
    elseif  app.BC_top_flag ==1 && app.BC_bottom_flag ==2
            app.BC_flag = 12;
            
    elseif  app.BC_top_flag ==2 && app.BC_bottom_flag ==1
            app.BC_flag = 21;
            
    elseif  app.BC_top_flag ==2 && app.BC_bottom_flag ==2
            app.BC_flag = 22;            
            
    end
%     c = app.BC_flag;
%     b = app.BC_top_flag
%     a = 
    switch app.n_layer
        case 1
               z_layer.layer1       = 1*height;      %Expressed in fraction of total thickness
               z_layer.layer2       = 0*height;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer3       = 0*height;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer4       = 0*height;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer5       = 0*height;      %each number refer to the elevation of the bottom of the layer 
        case 2
               z_layer.layer1       = app.FirstlayerEditField.Value*height;      %Expressed in fraction of total thickness
               z_layer.layer2       = 1*height;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer3       = 0*height;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer4       = 0*height;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer5       = 0*height;      %each number refer to the elevation of the bottom of the layer 
        case 3
               z_layer.layer1       = app.FirstlayerEditField.Value*height;      %Expressed in fraction of total thickness
               z_layer.layer2       = app.SecondlayerEditField.Value*height;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer3       = 1*height;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer4       = 0*height;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer5       = 0*height;      %each number refer to the elevation of the bottom of the layer 
        case 4
               z_layer.layer1       = app.FirstlayerEditField.Value*height;      %Expressed in fraction of total thickness
               z_layer.layer2       = app.SecondlayerEditField.Value*height;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer3       = app.ThirdlayerEditField.Value*height;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer4       = 1*height;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer5       = 0*height;      %each number refer to the elevation of the bottom of the layer                
        case 5
               z_layer.layer1       = app.FirstlayerEditField.Value*height;      %Expressed in fraction of total thickness
               z_layer.layer2       = app.SecondlayerEditField.Value*height;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer3       = app.ThirdlayerEditField.Value*height;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer4       = app.FourthlayerEditField.Value*height;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer5       = 1*height;      %each number refer to the elevation of the bottom of the layer  
    end
   
    
    %% 1. INPUT DATA: material and discretization parameters
        %1.1. Domain and discretization data
        hw1 = app.prescribedwaterheadhw1EditField.Value;
        hw2 = app.Thresholdvaluehw2EditField.Value;
        slope           = app.SlopeangleEditField.Value;                       %slope angle in degrees
        app.geom_nodes      = app.NumberofnodesEditField.Value;                      %Number of geometrical nodes []
        app.time_final      = app.TotaltimehrEditField.Value * 3600;                %running time [T]. 1st number starts at the top
        app.num_steps       = app.NumberofstepsEditField.Value;                    %Number of computational time steps []  1000000
        solution_method = 1;                      %flag for : 1 == explicit, one-step; 2 == explicit, RK4,  
        g               = 10;                    %gravity 
        gamma_w = 10000;
      
        rho_w = 0.1*app.CC*gamma_w;                  %water density 
      
    
        %1.2 Define Material properties and stratigraphy
        [property,IN,prop_hydro,prop_mech] = get_strata(app.n_layer,z_layer,height,app.geom_nodes,app.flag_hydro_1,app.flag_hydro_2,app.flag_hydro_3,app.flag_hydro_4,app.flag_hydro_5,app.VG,app.GN,app.BC,app.flag_mech,app.EM,app.SP,app.gamma_s,app.chi,app.Se);
    
        %1.3 Compute FE data
        app.FE_data = get_FE_data(height,app.geom_nodes,app.time_final,app.num_steps,slope);

        %1.5 define loading data (i.e., Neumann BC)
        LD_data = get_LD_data(app.time_final,app.Loading_Index,app.q_r,app.q_py,app.q_px, app.RI_Table2.Data);   
    
            %1.4 Define initial conditions
      
        
        switch app.IC_index
            case 1
            f = (app.constantEditField.Value);
            case 2
            f = (app.functionEditField.Value);        
        end
        Z = app.FE_data.Z;
        F = eval(f);       
        IC_data = get_IC_data(g,slope,LD_data.qr,height,app.geom_nodes,app.FE_data.ZZ,app.FE_data.uy_nodes,app.FE_data.ux_nodes,app.FE_data.hw_nodes,F,IN,property,rho_w,gamma_w);
        
    %     %1.6 define BC data (i.e., Dirichlect BC)
    %     BC_data = get_BC_data(height,FE_data.Z,IC_data.Y_0,FE_data.uy_nodes,FE_data.ux_nodes,FE_data.hw_nodes);
        
        %1.7 allocate memory for saving RESULTS
        app.output_times = app.NumberofoutputsEditField.Value;                        %1st number is always the first time step,
        obs_node_A   = app.NodesEditField.Value;                        %Observation node for plotting time-history of variables.
        obs_node_B   = app.NodesEditField_2.Value;
        obs_node_C   = app.NodesEditField_3.Value;        
        obs_node_D   = app.NodesEditField_4.Value;        
        obs_node_E   = app.NodesEditField_5.Value;
        obs_nodes  = [obs_node_A;obs_node_B;obs_node_C;obs_node_D;obs_node_E];
    
        RESULTS_aux      = allocate_results(app.num_steps,app.output_times,app.geom_nodes);
        app.RESULTS = RESULTS_aux;
       
    
     %% 2. INITIALIZE 
        t                      = 0;                     %initialize time 
        hh                     = waitbar(0,'Please wait...');  %open waitbar  
        Y_0                    = IC_data.Y_0;           %Solution vector at t=0;   
        dt                     = app.FE_data.dt;
        
         %2.1 compute initial factor of safety  
        [hydro_state] = get_hydro_state(hw2,Y_0(app.FE_data.hw_nodes),prop_hydro,app.flag_hydro_1,app.flag_hydro_2,app.flag_hydro_3,app.flag_hydro_4,app.flag_hydro_5,IN,app.n_layer);
        Sr_ini = hydro_state.Sr;
        
        [chi_data,k_data] = get_chi_data(app.flag_stress,IN,app.n_layer,app.flag_chi,property, Y_0(app.FE_data.hw_nodes), gamma_w, Sr_ini);
        [FS_slips_ini,FS_flow_ini] = get_FS_CC(IN,app.n_layer,app.flag_stress,app.FS_flag,chi_data,k_data, property,app.FE_data.Z_FS, IC_data.Y_0(app.FE_data.hw_nodes),slope, gamma_w,Sr_ini); 
               
    
        %1.6 define BC data (i.e., Dirichlect BC)
    %     BC_data = get_BC_data(Sr_ini(1), height,app.FE_data.Z,IC_data.Y_0,app.FE_data.uy_nodes,app.FE_data.ux_nodes,app.FE_data.hw_nodes);


         %2.2  Save results at t=0
        t_output_prof = 1; %time step for saving output profiles
        t_output_obs  = 1; %time step for saving time histories
        tn_eval       = 1;
        [app.RESULTS,t_output_prof] = save_results_ini(app.flag_node,app.RESULTS,tn_eval,t_output_prof,t_output_obs,...
                                IC_data.Y_0(app.FE_data.uy_nodes),IC_data.Y_0(app.FE_data.ux_nodes),IC_data.Y_0(app.FE_data.hw_nodes),... %profiles of uy, ux, hw
                                obs_nodes,app.FE_data,FS_slips_ini,FS_flow_ini,Sr_ini,IC_data.q_in,IC_data.Run,hydro_state.k_rel(1),hydro_state.Sr(1),IC_data.IR,IC_data.Rain,IC_data.Q,IC_data.R); %time histories of uy,ux,hw
    
          A = app.RESULTS ;                 
        net_stress = zeros( app.geom_nodes-1,1);
        effect_ini = zeros( app.geom_nodes-1,1);
     
        q_2 = 0;  
        Q1 =   IC_data.Q;
        R1 =   IC_data.R;     
        A = app.flow_flag;
    %% 3. PROCESSING 
        for tn = 1:app.num_steps         
            Rain = -LD_data.q(t);
     %%%%%%%%%%%%%%%gravity loading%%%%%%%%%%%%
            
            %1 compute variables at t=0
            if app.flow_flag == 1
                threhold = hw2;
            elseif app.flow_flag == 0
                threhold = hw1;
            end
            [hydro_state] = get_hydro_state(threhold,Y_0(app.FE_data.hw_nodes),prop_hydro,app.flag_hydro_1,app.flag_hydro_2,app.flag_hydro_3,app.flag_hydro_4,app.flag_hydro_5,IN,app.n_layer);
            Sr_0 = hydro_state.Sr;

            %%%%check boundary condition%%%%%
             BC_data = get_BC_data(app.flow_flag,hw1,app.BC_flag,hw2,Rain,q_2,Sr_0(1), height,app.FE_data.Z,IC_data.Y_0,app.FE_data.uy_nodes,app.FE_data.ux_nodes,app.FE_data.hw_nodes);
               
            [mech_state] = get_mech_state(Y_0(app.FE_data.uy_nodes),prop_mech);
            [chi_data,k_data] = get_chi_data(app.flag_stress,IN,app.n_layer,app.flag_chi,property, Y_0(app.FE_data.hw_nodes), gamma_w, Sr_0);
            [q_2,A_0,F_0,FE_matrices_state] = get_FE_matrices(app.CC,app.flow_flag,chi_data,Rain,IC_data, app.FE_data,hydro_state,mech_state,Y_0,LD_data,t,slope,g,rho_w,app.ST_flag,app.ST); 
                    
             R = (A_0*Y_0 + dt*F_0);                  
            %4.5 variable for next time step
            [Y_1]=get_x_lagrange(app.FE_data,BC_data,A_0,R);
             Y_0 = Y_1;

       if app.flow_flag == 1             
        if Rain <= q_2
            [hydro_state] = get_hydro_state(threhold,Y_0(app.FE_data.hw_nodes),prop_hydro,app.flag_hydro_1,app.flag_hydro_2,app.flag_hydro_3,app.flag_hydro_4,app.flag_hydro_5,IN,app.n_layer);
            Sr_0 = hydro_state.Sr;            
        end
 
           if Sr_0(1) ==1
               Y_0(3) = hw2;    
           end
        end
           
           IR_0 = FE_matrices_state.IR;
           Q2 = Q1+FE_matrices_state.IR*dt;
           R2 = R1+FE_matrices_state.Run*dt;      
           %4.6  compute factor of safety  
           [FS_slips,FS_flow] = get_FS_CC(IN,app.n_layer,app.flag_stress,app.FS_flag,chi_data,k_data, property,app.FE_data.Z_FS, Y_0(app.FE_data.hw_nodes),slope, gamma_w,Sr_0); 
            
            
            %save results
            t_output_obs  = tn+1; %time step for saving time histories
            tn_eval       = ismember(tn,app.RESULTS.steps_results);            
            [app.RESULTS,t_output_prof] = save_results(app.flag_node,app.RESULTS,tn_eval,t_output_prof,t_output_obs,...
                                        Y_0(app.FE_data.uy_nodes),Y_0(app.FE_data.ux_nodes),Y_0(app.FE_data.hw_nodes),... %profiles of uy, ux, hw
                                        obs_nodes,FE_matrices_state,FS_slips,FS_flow,Sr_0,FE_matrices_state. g_int_top,FE_matrices_state. Run,hydro_state.k_rel(1),hydro_state.Sr(1),FE_matrices_state.IR,Rain,Q2,R2); %time histories of uy,ux,hw
           B = app.RESULTS ;               
             %update for next time step
              Q1 = Q2;
              R1 = R2;  
              t=t+dt;   
             waitbar(tn/max(app.num_steps)) %update waitbar
        end
        
        close(hh) %close waitbar
        
        msgbox('Computation completed!')


        end

        % Value changed function: NumberoflayerKnob
        function NumberoflayerKnobValueChanged(app, event)
            value = app.NumberoflayerKnob.Value;
            switch value
                case "1"  
                    app.n_layer = 1;     
                     app.Layer1Button.Enable = "on";
                     app.Layer2Button.Enable = "off";
                     app.Layer3Button.Enable = "off";                     
                     app.Layer4Button.Enable = "off";
                     app.Layer5Button.Enable = "off";  
                     app.Layer1Button_2.Enable = "on";                     
                     app.Layer2Button_2.Enable = "off";
                     app.Layer3Button_2.Enable = "off";                     
                     app.Layer4Button_2.Enable = "off";
                     app.Layer5Button_2.Enable = "off";  
                     
            app.SecondlayerEditField.Enable = "off";
            app.SecondlayerEditFieldLabel.Enable = "off";
            app.ThirdlayerEditFieldLabel.Enable = "off";
            app.ThirdlayerEditField.Enable = "off";
            app.FourthlayerEditFieldLabel.Enable = "off";
            app.FourthlayerEditField.Enable = "off";
            app.FifthlayerEditFieldLabel.Enable = "off";
            app.FifthlayerEditField.Enable = "off";
                     
                     app.Image8.Visible = "on";
                     app.Image9.Visible = "off";        
                     app.Image10.Visible = "off";
                     app.Image11.Visible = "off";
                     app.Image12.Visible = "off";
                     
                     app.Image14.Visible = "on";                     
                     app.Image14_2.Visible = "on";
                     app.Image15.Visible = "off";  
                     app.Image15_2.Visible = "off"; 
                     app.Image17.Visible = "off";                    
                     app.Image17_2.Visible = "off";                        
                     app.Image20.Visible = "off";                             
                     app.Image20_2.Visible = "off";  
                     app.Image24.Visible = "off";                      
                     app.Image24_2.Visible = "off";   
                     
                     app.FirstlayerEditField.Value = 1;
                     app.SecondlayerEditField.Value = 0;
                     app.ThirdlayerEditField.Value = 0;
                     app.FourthlayerEditField.Value = 0;
                     app.FifthlayerEditField.Value = 0;                     
                     
                case "2"
                    app.n_layer = 2;
                     app.Layer1Button.Enable = "on";
                     app.Layer2Button.Enable = "on";
                     app.Layer3Button.Enable = "off";                     
                     app.Layer4Button.Enable = "off";
                     app.Layer5Button.Enable = "off";
                     
                     app.Layer1Button_2.Enable = "on";                     
                     app.Layer2Button_2.Enable = "on";
                     app.Layer3Button_2.Enable = "off";                     
                     app.Layer4Button_2.Enable = "off";
                     app.Layer5Button_2.Enable = "off";  
                     
            app.SecondlayerEditField.Enable = "on";
            app.SecondlayerEditFieldLabel.Enable = "on";
            app.ThirdlayerEditFieldLabel.Enable = "off";
            app.ThirdlayerEditField.Enable = "off";
            app.FourthlayerEditFieldLabel.Enable = "off";
            app.FourthlayerEditField.Enable = "off";
            app.FifthlayerEditFieldLabel.Enable = "off";
            app.FifthlayerEditField.Enable = "off";
                   
                     app.Image8.Visible = "off";
                     app.Image9.Visible = "on";        
                     app.Image10.Visible = "off";
                     app.Image11.Visible = "off";
                     app.Image12.Visible = "off";
                     
                     app.Image15.Visible = "on";  
                     app.Image15_2.Visible = "on";   
                     app.Image14.Visible = "off";                     
                     app.Image14_2.Visible = "off";
                     app.Image17.Visible = "off";                    
                     app.Image17_2.Visible = "off";                        
                     app.Image20.Visible = "off";                             
                     app.Image20_2.Visible = "off";  
                     app.Image24.Visible = "off";                      
                     app.Image24_2.Visible = "off";    
                     
                     app.FirstlayerEditField.Value = 0;
                     app.SecondlayerEditField.Value = 1;
                     app.ThirdlayerEditField.Value = 0;
                     app.FourthlayerEditField.Value = 0;
                     app.FifthlayerEditField.Value = 0;                       
                     
                case "3"
                    app.n_layer = 3;
                     app.Layer1Button.Enable = "on";
                     app.Layer2Button.Enable = "on";
                     app.Layer3Button.Enable = "on";                     
                     app.Layer4Button.Enable = "off";
                     app.Layer5Button.Enable = "off";      
                     app.Layer1Button_2.Enable = "on";                     
                     app.Layer2Button_2.Enable = "on";
                     app.Layer3Button_2.Enable = "on";                     
                     app.Layer4Button_2.Enable = "off";
                     app.Layer5Button_2.Enable = "off";                       
                     
                    
            app.SecondlayerEditField.Enable = "on";
            app.SecondlayerEditFieldLabel.Enable = "on";
            app.ThirdlayerEditFieldLabel.Enable = "on";
            app.ThirdlayerEditField.Enable = "on";
            app.FourthlayerEditFieldLabel.Enable = "off";
            app.FourthlayerEditField.Enable = "off";
            app.FifthlayerEditFieldLabel.Enable = "off";
            app.FifthlayerEditField.Enable = "off";

                    
                     app.Image8.Visible = "off";
                     app.Image9.Visible = "off";        
                     app.Image10.Visible = "off";
                     app.Image11.Visible = "off";
                     app.Image12.Visible = "on";   
                     
                      app.Image17.Visible = "on";                    
                      app.Image17_2.Visible = "on"; 
                     app.Image14.Visible = "off";                     
                     app.Image14_2.Visible = "off";
                     app.Image15.Visible = "off";  
                     app.Image15_2.Visible = "off";                        
                     app.Image20.Visible = "off";                             
                     app.Image20_2.Visible = "off";  
                     app.Image24.Visible = "off";                      
                     app.Image24_2.Visible = "off";  
                     
                     app.FirstlayerEditField.Value = 0;
                     app.SecondlayerEditField.Value = 0;
                     app.ThirdlayerEditField.Value = 1;
                     app.FourthlayerEditField.Value = 0;
                     app.FifthlayerEditField.Value = 0; 
                     
                case "4"
                    app.n_layer = 4;
                     app.Layer1Button.Enable = "on";
                     app.Layer2Button.Enable = "on";
                     app.Layer3Button.Enable = "on";                     
                     app.Layer4Button.Enable = "on";
                     app.Layer5Button.Enable = "off";   
                     app.Layer1Button_2.Enable = "on";                     
                     app.Layer2Button_2.Enable = "on";
                     app.Layer3Button_2.Enable = "on";                     
                     app.Layer4Button_2.Enable = "on";
                     app.Layer5Button_2.Enable = "off";                       
                     
                     
            app.SecondlayerEditField.Enable = "on";
            app.SecondlayerEditFieldLabel.Enable = "on";
            app.ThirdlayerEditFieldLabel.Enable = "on";
            app.ThirdlayerEditField.Enable = "on";
            app.FourthlayerEditFieldLabel.Enable = "on";
            app.FourthlayerEditField.Enable = "on";
            app.FifthlayerEditFieldLabel.Enable = "off";
            app.FifthlayerEditField.Enable = "off";


                     app.Image8.Visible = "off";
                     app.Image9.Visible = "off";        
                     app.Image10.Visible = "on";
                     app.Image11.Visible = "off";
                     app.Image12.Visible = "off";   
                     
                     app.Image20.Visible = "on";                             
                     app.Image20_2.Visible = "on";
                     app.Image14.Visible = "off";                     
                     app.Image14_2.Visible = "off";
                     app.Image15.Visible = "off";  
                     app.Image15_2.Visible = "off"; 
                     app.Image17.Visible = "off";                    
                     app.Image17_2.Visible = "off";                          
                     app.Image24.Visible = "off";                      
                     app.Image24_2.Visible = "off"; 
                     
                     app.FirstlayerEditField.Value = 0;
                     app.SecondlayerEditField.Value = 0;
                     app.ThirdlayerEditField.Value = 0;
                     app.FourthlayerEditField.Value = 1;
                     app.FifthlayerEditField.Value = 0;                      
                     
                     
                case "5"
                    app.n_layer = 5;
                     app.Layer1Button.Enable = "on";
                     app.Layer2Button.Enable = "on";
                     app.Layer3Button.Enable = "on";                     
                     app.Layer4Button.Enable = "on";
                     app.Layer5Button.Enable = "on";    
                     app.Layer1Button_2.Enable = "on";                     
                     app.Layer2Button_2.Enable = "on";
                     app.Layer3Button_2.Enable = "on";                     
                     app.Layer4Button_2.Enable = "on";
                     app.Layer5Button_2.Enable = "on";
                     
            app.SecondlayerEditField.Enable = "on";
            app.SecondlayerEditFieldLabel.Enable = "on";
            app.ThirdlayerEditFieldLabel.Enable = "on";
            app.ThirdlayerEditField.Enable = "on";
            app.FourthlayerEditFieldLabel.Enable = "on";
            app.FourthlayerEditField.Enable = "on";
            app.FifthlayerEditFieldLabel.Enable = "on";
            app.FifthlayerEditField.Enable = "on";

                     
                     app.Image8.Visible = "off";
                     app.Image9.Visible = "off";        
                     app.Image10.Visible = "off";
                     app.Image11.Visible = "on";
                     app.Image12.Visible = "off";
                   
                     app.Image24.Visible = "on";                      
                     app.Image24_2.Visible = "on"; 
                     app.Image14.Visible = "off";                     
                     app.Image14_2.Visible = "off";
                     app.Image15.Visible = "off";  
                     app.Image15_2.Visible = "off"; 
                     app.Image17.Visible = "off";                    
                     app.Image17_2.Visible = "off";                        
                     app.Image20.Visible = "off";                             
                     app.Image20_2.Visible = "off"; 
                     
                      app.FirstlayerEditField.Value = 0;
                     app.SecondlayerEditField.Value = 0;
                     app.ThirdlayerEditField.Value = 0;
                     app.FourthlayerEditField.Value = 0;
                     app.FifthlayerEditField.Value = 1;                     
                         
            end
        
        end

        % Selection changed function: 
        % AssignpropertytolayersButtonGroup
        function AssignpropertytolayersButtonGroupSelectionChanged(app, event)
            selectedButton = app.AssignpropertytolayersButtonGroup.SelectedObject;
            switch selectedButton
                case app.Layer1Button
                    switch app.n_layer 
                        case 1
                             app.Image14.Visible = "on";
                             app.Image15.Visible = "off";        
                             app.Image16.Visible = "off";
                             app.Image17.Visible = "off";
                             app.Image18.Visible = "off";                     
                             app.Image19.Visible = "off";
                             app.Image20.Visible = "off";        
                             app.Image21.Visible = "off";
                             app.Image22.Visible = "off";
                             app.Image23.Visible = "off";                     
                             app.Image24.Visible = "off";
                             app.Image25.Visible = "off";        
                             app.Image26.Visible = "off";
                             app.Image27.Visible = "off";
                             app.Image28.Visible = "off";                           
                        case 2
                             app.Image14.Visible = "off";
                             app.Image15.Visible = "on";        
                             app.Image16.Visible = "off";
                             app.Image17.Visible = "off";
                             app.Image18.Visible = "off";                     
                             app.Image19.Visible = "off";
                             app.Image20.Visible = "off";        
                             app.Image21.Visible = "off";
                             app.Image22.Visible = "off";
                             app.Image23.Visible = "off";                     
                             app.Image24.Visible = "off";
                             app.Image25.Visible = "off";        
                             app.Image26.Visible = "off";
                             app.Image27.Visible = "off";
                             app.Image28.Visible = "off"; 
                        case 3
                             app.Image14.Visible = "off";
                             app.Image15.Visible = "off";        
                             app.Image16.Visible = "off";
                             app.Image17.Visible = "on";
                             app.Image18.Visible = "off";                     
                             app.Image19.Visible = "off";
                             app.Image20.Visible = "off";        
                             app.Image21.Visible = "off";
                             app.Image22.Visible = "off";
                             app.Image23.Visible = "off";                     
                             app.Image24.Visible = "off";
                             app.Image25.Visible = "off";        
                             app.Image26.Visible = "off";
                             app.Image27.Visible = "off";
                             app.Image28.Visible = "off"; 
                        case 4
                             app.Image14.Visible = "off";
                             app.Image15.Visible = "off";        
                             app.Image16.Visible = "off";
                             app.Image17.Visible = "off";
                             app.Image18.Visible = "off";                     
                             app.Image19.Visible = "off";
                             app.Image20.Visible = "on";        
                             app.Image21.Visible = "off";
                             app.Image22.Visible = "off";
                             app.Image23.Visible = "off";                     
                             app.Image24.Visible = "off";
                             app.Image25.Visible = "off";        
                             app.Image26.Visible = "off";
                             app.Image27.Visible = "off";
                             app.Image28.Visible = "off";                              
                        case 5
                             app.Image14.Visible = "off";
                             app.Image15.Visible = "off";        
                             app.Image16.Visible = "off";
                             app.Image17.Visible = "off";
                             app.Image18.Visible = "off";                     
                             app.Image19.Visible = "off";
                             app.Image20.Visible = "off";        
                             app.Image21.Visible = "off";
                             app.Image22.Visible = "off";
                             app.Image23.Visible = "off";                     
                             app.Image24.Visible = "on";
                             app.Image25.Visible = "off";        
                             app.Image26.Visible = "off";
                             app.Image27.Visible = "off";
                             app.Image28.Visible = "off"; 
                    end
                       
                    app.stPanel.Visible = 'on';                   
                    app.ndPanel.Visible = 'off';
                    app.rdPanel.Visible = 'off';
                    app.thPanel.Visible = 'off';
                    app.thPanel_2.Visible = 'off';
                    
                case app.Layer2Button
                    switch app.n_layer                       
                        case 2
                             app.Image14.Visible = "off";
                             app.Image15.Visible = "off";        
                             app.Image16.Visible = "on";
                             app.Image17.Visible = "off";
                             app.Image18.Visible = "off";                     
                             app.Image19.Visible = "off";
                             app.Image20.Visible = "off";        
                             app.Image21.Visible = "off";
                             app.Image22.Visible = "off";
                             app.Image23.Visible = "off";                     
                             app.Image24.Visible = "off";
                             app.Image25.Visible = "off";        
                             app.Image26.Visible = "off";
                             app.Image27.Visible = "off";
                             app.Image28.Visible = "off"; 
                        case 3
                             app.Image14.Visible = "off";
                             app.Image15.Visible = "off";        
                             app.Image16.Visible = "off";
                             app.Image17.Visible = "off";
                             app.Image18.Visible = "on";                     
                             app.Image19.Visible = "off";
                             app.Image20.Visible = "off";        
                             app.Image21.Visible = "off";
                             app.Image22.Visible = "off";
                             app.Image23.Visible = "off";                     
                             app.Image24.Visible = "off";
                             app.Image25.Visible = "off";        
                             app.Image26.Visible = "off";
                             app.Image27.Visible = "off";
                             app.Image28.Visible = "off"; 
                        case 4
                             app.Image14.Visible = "off";
                             app.Image15.Visible = "off";        
                             app.Image16.Visible = "off";
                             app.Image17.Visible = "off";
                             app.Image18.Visible = "off";                     
                             app.Image19.Visible = "off";
                             app.Image20.Visible = "off";        
                             app.Image21.Visible = "on";
                             app.Image22.Visible = "off";
                             app.Image23.Visible = "off";                     
                             app.Image24.Visible = "off";
                             app.Image25.Visible = "off";        
                             app.Image26.Visible = "off";
                             app.Image27.Visible = "off";
                             app.Image28.Visible = "off";                              
                        case 5
                             app.Image14.Visible = "off";
                             app.Image15.Visible = "off";        
                             app.Image16.Visible = "off";
                             app.Image17.Visible = "off";
                             app.Image18.Visible = "off";                     
                             app.Image19.Visible = "off";
                             app.Image20.Visible = "off";        
                             app.Image21.Visible = "off";
                             app.Image22.Visible = "off";
                             app.Image23.Visible = "off";                     
                             app.Image24.Visible = "off";
                             app.Image25.Visible = "on";        
                             app.Image26.Visible = "off";
                             app.Image27.Visible = "off";
                             app.Image28.Visible = "off"; 
                    end                    
                    app.stPanel.Visible = 'off';                   
                    app.ndPanel.Visible = 'on';
                    app.rdPanel.Visible = 'off';
                    app.thPanel.Visible = 'off';
                    app.thPanel_2.Visible = 'off';
                    
                case app.Layer3Button
                    switch app.n_layer                       
                        case 3
                             app.Image14.Visible = "off";
                             app.Image15.Visible = "off";        
                             app.Image16.Visible = "off";
                             app.Image17.Visible = "off";
                             app.Image18.Visible = "off";                     
                             app.Image19.Visible = "on";
                             app.Image20.Visible = "off";        
                             app.Image21.Visible = "off";
                             app.Image22.Visible = "off";
                             app.Image23.Visible = "off";                     
                             app.Image24.Visible = "off";
                             app.Image25.Visible = "off";        
                             app.Image26.Visible = "off";
                             app.Image27.Visible = "off";
                             app.Image28.Visible = "off"; 
                        case 4
                             app.Image14.Visible = "off";
                             app.Image15.Visible = "off";        
                             app.Image16.Visible = "off";
                             app.Image17.Visible = "off";
                             app.Image18.Visible = "off";                     
                             app.Image19.Visible = "off";
                             app.Image20.Visible = "off";        
                             app.Image21.Visible = "off";
                             app.Image22.Visible = "on";
                             app.Image23.Visible = "off";                     
                             app.Image24.Visible = "off";
                             app.Image25.Visible = "off";        
                             app.Image26.Visible = "off";
                             app.Image27.Visible = "off";
                             app.Image28.Visible = "off";                              
                        case 5
                             app.Image14.Visible = "off";
                             app.Image15.Visible = "off";        
                             app.Image16.Visible = "off";
                             app.Image17.Visible = "off";
                             app.Image18.Visible = "off";                     
                             app.Image19.Visible = "off";
                             app.Image20.Visible = "off";        
                             app.Image21.Visible = "off";
                             app.Image22.Visible = "off";
                             app.Image23.Visible = "off";                     
                             app.Image24.Visible = "off";
                             app.Image25.Visible = "off";        
                             app.Image26.Visible = "on";
                             app.Image27.Visible = "off";
                             app.Image28.Visible = "off"; 
                    end                       
                    app.stPanel.Visible = 'off';                   
                    app.ndPanel.Visible = 'off';
                    app.rdPanel.Visible = 'on';
                    app.thPanel.Visible = 'off';
                    app.thPanel_2.Visible = 'off';
                    
                case app.Layer4Button
                    switch app.n_layer                       
                        case 4
                             app.Image14.Visible = "off";
                             app.Image15.Visible = "off";        
                             app.Image16.Visible = "off";
                             app.Image17.Visible = "off";
                             app.Image18.Visible = "off";                     
                             app.Image19.Visible = "off";
                             app.Image20.Visible = "off";        
                             app.Image21.Visible = "off";
                             app.Image22.Visible = "off";
                             app.Image23.Visible = "on";                     
                             app.Image24.Visible = "off";
                             app.Image25.Visible = "off";        
                             app.Image26.Visible = "off";
                             app.Image27.Visible = "off";
                             app.Image28.Visible = "off";                              
                        case 5
                             app.Image14.Visible = "off";
                             app.Image15.Visible = "off";        
                             app.Image16.Visible = "off";
                             app.Image17.Visible = "off";
                             app.Image18.Visible = "off";                     
                             app.Image19.Visible = "off";
                             app.Image20.Visible = "off";        
                             app.Image21.Visible = "off";
                             app.Image22.Visible = "off";
                             app.Image23.Visible = "off";                     
                             app.Image24.Visible = "off";
                             app.Image25.Visible = "off";        
                             app.Image26.Visible = "off";
                             app.Image27.Visible = "on";
                             app.Image28.Visible = "off"; 
                    end                        
                    app.stPanel.Visible = 'off';                   
                    app.ndPanel.Visible = 'off';
                    app.rdPanel.Visible = 'off';
                    app.thPanel.Visible = 'on';
                    app.thPanel_2.Visible = 'off';
                    
                case app.Layer5Button
                    switch app.n_layer                                                 
                        case 5
                             app.Image14.Visible = "off";
                             app.Image15.Visible = "off";        
                             app.Image16.Visible = "off";
                             app.Image17.Visible = "off";
                             app.Image18.Visible = "off";                     
                             app.Image19.Visible = "off";
                             app.Image20.Visible = "off";        
                             app.Image21.Visible = "off";
                             app.Image22.Visible = "off";
                             app.Image23.Visible = "off";                     
                             app.Image24.Visible = "off";
                             app.Image25.Visible = "off";        
                             app.Image26.Visible = "off";
                             app.Image27.Visible = "off";
                             app.Image28.Visible = "on"; 
                    end                     
                    app.stPanel.Visible = 'off';                   
                    app.ndPanel.Visible = 'off';
                    app.rdPanel.Visible = 'off';
                    app.thPanel.Visible = 'off';
                    app.thPanel_2.Visible = 'on';    
                    
            end
       
        end

        % Button pushed function: LocationButton
        function LocationButtonPushed(app, event)
            Z_aux = linspace(app.HeightmEditField.Value, 0,app.NumberofnodesEditField.Value);
            Node_1 = app.NodesEditField.Value;
            Node_2 = app.NodesEditField_2.Value;
            Node_3 = app.NodesEditField_3.Value;
            Node_4 = app.NodesEditField_4.Value;
            Node_5 = app.NodesEditField_5.Value;            
        app.EditField_6.Value = app.HeightmEditField.Value - Z_aux(Node_1);
        app.EditField_17.Value = app.HeightmEditField.Value - Z_aux(Node_2);
        app.EditField_18.Value = app.HeightmEditField.Value - Z_aux(Node_3);
        app.EditField_19.Value = app.HeightmEditField.Value - Z_aux(Node_4);
        app.EditField_20.Value = app.HeightmEditField.Value - Z_aux(Node_5);        
        end

        % Selection changed function: 
        % AssignpropertytolayersButtonGroup_2
        function AssignpropertytolayersButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.AssignpropertytolayersButtonGroup_2.SelectedObject;
            switch selectedButton
                case app.Layer1Button_2
                    switch app.n_layer 
                        case 1
                             app.Image14_2.Visible = "on";
                             app.Image15_2.Visible = "off";        
                             app.Image16_2.Visible = "off";
                             app.Image17_2.Visible = "off";
                             app.Image18_2.Visible = "off";                     
                             app.Image19_2.Visible = "off";
                             app.Image20_2.Visible = "off";        
                             app.Image21_2.Visible = "off";
                             app.Image22_2.Visible = "off";
                             app.Image23_2.Visible = "off";                     
                             app.Image24_2.Visible = "off";
                             app.Image25_2.Visible = "off";        
                             app.Image26_2.Visible = "off";
                             app.Image27_2.Visible = "off";
                             app.Image28_2.Visible = "off";                           
                        case 2
                             app.Image14_2.Visible = "off";
                             app.Image15_2.Visible = "on";        
                             app.Image16_2.Visible = "off";
                             app.Image17_2.Visible = "off";
                             app.Image18_2.Visible = "off";                     
                             app.Image19_2.Visible = "off";
                             app.Image20_2.Visible = "off";        
                             app.Image21_2.Visible = "off";
                             app.Image22_2.Visible = "off";
                             app.Image23_2.Visible = "off";                     
                             app.Image24_2.Visible = "off";
                             app.Image25_2.Visible = "off";        
                             app.Image26_2.Visible = "off";
                             app.Image27_2.Visible = "off";
                             app.Image28_2.Visible = "off"; 
                        case 3
                             app.Image14_2.Visible = "off";
                             app.Image15_2.Visible = "off";        
                             app.Image16_2.Visible = "off";
                             app.Image17_2.Visible = "on";
                             app.Image18_2.Visible = "off";                     
                             app.Image19_2.Visible = "off";
                             app.Image20_2.Visible = "off";        
                             app.Image21_2.Visible = "off";
                             app.Image22_2.Visible = "off";
                             app.Image23_2.Visible = "off";                     
                             app.Image24_2.Visible = "off";
                             app.Image25_2.Visible = "off";        
                             app.Image26_2.Visible = "off";
                             app.Image27_2.Visible = "off";
                             app.Image28_2.Visible = "off"; 
                        case 4
                             app.Image14_2.Visible = "off";
                             app.Image15_2.Visible = "off";        
                             app.Image16_2.Visible = "off";
                             app.Image17_2.Visible = "off";
                             app.Image18_2.Visible = "off";                     
                             app.Image19_2.Visible = "off";
                             app.Image20_2.Visible = "on";        
                             app.Image21_2.Visible = "off";
                             app.Image22_2.Visible = "off";
                             app.Image23_2.Visible = "off";                     
                             app.Image24_2.Visible = "off";
                             app.Image25_2.Visible = "off";        
                             app.Image26_2.Visible = "off";
                             app.Image27_2.Visible = "off";
                             app.Image28_2.Visible = "off";                              
                        case 5
                             app.Image14_2.Visible = "off";
                             app.Image15_2.Visible = "off";        
                             app.Image16_2.Visible = "off";
                             app.Image17_2.Visible = "off";
                             app.Image18_2.Visible = "off";                     
                             app.Image19_2.Visible = "off";
                             app.Image20_2.Visible = "off";        
                             app.Image21_2.Visible = "off";
                             app.Image22_2.Visible = "off";
                             app.Image23_2.Visible = "off";                     
                             app.Image24_2.Visible = "on";
                             app.Image25_2.Visible = "off";        
                             app.Image26_2.Visible = "off";
                             app.Image27_2.Visible = "off";
                             app.Image28_2.Visible = "off"; 
                    end
                       
                    app.stPanel_2.Visible = 'on';                   
                    app.ndPanel_2.Visible = 'off';
                    app.rdPanel_2.Visible = 'off';
                    app.thPanel_3.Visible = 'off';
                    app.thPanel_4.Visible = 'off';
                    
                case app.Layer2Button_2
                    switch app.n_layer                       
                        case 2
                             app.Image14_2.Visible = "off";
                             app.Image15_2.Visible = "off";        
                             app.Image16_2.Visible = "on";
                             app.Image17_2.Visible = "off";
                             app.Image18_2.Visible = "off";                     
                             app.Image19_2.Visible = "off";
                             app.Image20_2.Visible = "off";        
                             app.Image21_2.Visible = "off";
                             app.Image22_2.Visible = "off";
                             app.Image23_2.Visible = "off";                     
                             app.Image24_2.Visible = "off";
                             app.Image25_2.Visible = "off";        
                             app.Image26_2.Visible = "off";
                             app.Image27_2.Visible = "off";
                             app.Image28_2.Visible = "off"; 
                        case 3
                             app.Image14_2.Visible = "off";
                             app.Image15_2.Visible = "off";        
                             app.Image16_2.Visible = "off";
                             app.Image17_2.Visible = "off";
                             app.Image18_2.Visible = "on";                     
                             app.Image19_2.Visible = "off";
                             app.Image20_2.Visible = "off";        
                             app.Image21_2.Visible = "off";
                             app.Image22_2.Visible = "off";
                             app.Image23_2.Visible = "off";                     
                             app.Image24_2.Visible = "off";
                             app.Image25_2.Visible = "off";        
                             app.Image26_2.Visible = "off";
                             app.Image27_2.Visible = "off";
                             app.Image28_2.Visible = "off"; 
                        case 4
                             app.Image14_2.Visible = "off";
                             app.Image15_2.Visible = "off";        
                             app.Image16_2.Visible = "off";
                             app.Image17_2.Visible = "off";
                             app.Image18_2.Visible = "off";                     
                             app.Image19_2.Visible = "off";
                             app.Image20_2.Visible = "off";        
                             app.Image21_2.Visible = "on";
                             app.Image22_2.Visible = "off";
                             app.Image23_2.Visible = "off";                     
                             app.Image24_2.Visible = "off";
                             app.Image25_2.Visible = "off";        
                             app.Image26_2.Visible = "off";
                             app.Image27_2.Visible = "off";
                             app.Image28_2.Visible = "off";                              
                        case 5
                             app.Image14_2.Visible = "off";
                             app.Image15_2.Visible = "off";        
                             app.Image16_2.Visible = "off";
                             app.Image17_2.Visible = "off";
                             app.Image18_2.Visible = "off";                     
                             app.Image19_2.Visible = "off";
                             app.Image20_2.Visible = "off";        
                             app.Image21_2.Visible = "off";
                             app.Image22_2.Visible = "off";
                             app.Image23_2.Visible = "off";                     
                             app.Image24_2.Visible = "off";
                             app.Image25_2.Visible = "on";        
                             app.Image26_2.Visible = "off";
                             app.Image27_2.Visible = "off";
                             app.Image28_2.Visible = "off"; 
                    end                    
                    app.stPanel_2.Visible = 'off';                   
                    app.ndPanel_2.Visible = 'on';
                    app.rdPanel_2.Visible = 'off';
                    app.thPanel_3.Visible = 'off';
                    app.thPanel_4.Visible = 'off';
                    
                case app.Layer3Button_2
                    switch app.n_layer                       
                        case 3
                             app.Image14_2.Visible = "off";
                             app.Image15_2.Visible = "off";        
                             app.Image16_2.Visible = "off";
                             app.Image17_2.Visible = "off";
                             app.Image18_2.Visible = "off";                     
                             app.Image19_2.Visible = "on";
                             app.Image20_2.Visible = "off";        
                             app.Image21_2.Visible = "off";
                             app.Image22_2.Visible = "off";
                             app.Image23_2.Visible = "off";                     
                             app.Image24_2.Visible = "off";
                             app.Image25_2.Visible = "off";        
                             app.Image26_2.Visible = "off";
                             app.Image27_2.Visible = "off";
                             app.Image28_2.Visible = "off"; 
                        case 4
                             app.Image14_2.Visible = "off";
                             app.Image15_2.Visible = "off";        
                             app.Image16_2.Visible = "off";
                             app.Image17_2.Visible = "off";
                             app.Image18_2.Visible = "off";                     
                             app.Image19_2.Visible = "off";
                             app.Image20_2.Visible = "off";        
                             app.Image21_2.Visible = "off";
                             app.Image22_2.Visible = "on";
                             app.Image23_2.Visible = "off";                     
                             app.Image24_2.Visible = "off";
                             app.Image25_2.Visible = "off";        
                             app.Image26_2.Visible = "off";
                             app.Image27_2.Visible = "off";
                             app.Image28_2.Visible = "off";                              
                        case 5
                             app.Image14_2.Visible = "off";
                             app.Image15_2.Visible = "off";        
                             app.Image16_2.Visible = "off";
                             app.Image17_2.Visible = "off";
                             app.Image18_2.Visible = "off";                     
                             app.Image19_2.Visible = "off";
                             app.Image20_2.Visible = "off";        
                             app.Image21_2.Visible = "off";
                             app.Image22_2.Visible = "off";
                             app.Image23_2.Visible = "off";                     
                             app.Image24_2.Visible = "off";
                             app.Image25_2.Visible = "off";        
                             app.Image26_2.Visible = "on";
                             app.Image27_2.Visible = "off";
                             app.Image28_2.Visible = "off"; 
                    end                       
                    app.stPanel_2.Visible = 'off';                   
                    app.ndPanel_2.Visible = 'off';
                    app.rdPanel_2.Visible = 'on';
                    app.thPanel_3.Visible = 'off';
                    app.thPanel_4.Visible = 'off';
                    
                case app.Layer4Button_2
                    switch app.n_layer                       
                        case 4
                             app.Image14_2.Visible = "off";
                             app.Image15_2.Visible = "off";        
                             app.Image16_2.Visible = "off";
                             app.Image17_2.Visible = "off";
                             app.Image18_2.Visible = "off";                     
                             app.Image19_2.Visible = "off";
                             app.Image20_2.Visible = "off";        
                             app.Image21_2.Visible = "off";
                             app.Image22_2.Visible = "off";
                             app.Image23_2.Visible = "on";                     
                             app.Image24_2.Visible = "off";
                             app.Image25_2.Visible = "off";        
                             app.Image26_2.Visible = "off";
                             app.Image27_2.Visible = "off";
                             app.Image28_2.Visible = "off";                              
                        case 5
                             app.Image14_2.Visible = "off";
                             app.Image15_2.Visible = "off";        
                             app.Image16_2.Visible = "off";
                             app.Image17_2.Visible = "off";
                             app.Image18_2.Visible = "off";                     
                             app.Image19_2.Visible = "off";
                             app.Image20_2.Visible = "off";        
                             app.Image21_2.Visible = "off";
                             app.Image22_2.Visible = "off";
                             app.Image23_2.Visible = "off";                     
                             app.Image24_2.Visible = "off";
                             app.Image25_2.Visible = "off";        
                             app.Image26_2.Visible = "off";
                             app.Image27_2.Visible = "on";
                             app.Image28_2.Visible = "off"; 
                    end                        
                    app.stPanel_2.Visible = 'off';                   
                    app.ndPanel_2.Visible = 'off';
                    app.rdPanel_2.Visible = 'off';
                    app.thPanel_3.Visible = 'on';
                    app.thPanel_4.Visible = 'off';
                    
                case app.Layer5Button_2
                    switch app.n_layer                                                 
                        case 5
                             app.Image14_2.Visible = "off";
                             app.Image15_2.Visible = "off";        
                             app.Image16_2.Visible = "off";
                             app.Image17_2.Visible = "off";
                             app.Image18_2.Visible = "off";                     
                             app.Image19_2.Visible = "off";
                             app.Image20_2.Visible = "off";        
                             app.Image21_2.Visible = "off";
                             app.Image22_2.Visible = "off";
                             app.Image23_2.Visible = "off";                     
                             app.Image24_2.Visible = "off";
                             app.Image25_2.Visible = "off";        
                             app.Image26_2.Visible = "off";
                             app.Image27_2.Visible = "off";
                             app.Image28_2.Visible = "on"; 
                    end                     
                    app.stPanel_2.Visible = 'off';                   
                    app.ndPanel_2.Visible = 'off';
                    app.rdPanel_2.Visible = 'off';
                    app.thPanel_3.Visible = 'off';
                    app.thPanel_4.Visible = 'on';  
                    
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'Effective Stress'
                    app.flag_stress.layer_1 = 1;
                app.FEditField.Enable = 'off';
                app.bEditField.Enable = 'off';
                app.EEditField.Enable = 'on';
                app.GEditField.Enable = 'on';  
                app.Option1Button.Enable = 'on';  
                app.Option2Button.Enable = 'on';   
                app.Option3Button.Enable = 'on';
                
                case 'Independent Variables'
                    app.flag_stress.layer_1 = 2; 
                app.Option1Button.Enable = 'off';  
                app.Option2Button.Enable = 'off';   
                app.Option3Button.Enable = 'off';                      
                    
                app.FEditField.Enable = 'on';
                app.bEditField.Enable = 'on';
                
                app.EEditField.Enable = 'on';
                app.GEditField.Enable = 'on';

            end
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            switch value
                case 'Effective Stress'
                    app.flag_stress.layer_2 = 1;
                app.FEditField_2.Enable = 'off';
                app.bEditField_2.Enable = 'off';
                app.EEditField_2.Enable = 'on';
                app.GEditField_2.Enable = 'on';                
                app.Option1Button_2.Enable = 'on';  
                app.Option2Button_2.Enable = 'on';   
                app.Option3Button_2.Enable = 'on';
                
                case 'Independent Variables'
                    app.flag_stress.layer_2 = 2; 
                app.Option1Button_2.Enable = 'off';  
                app.Option2Button_2.Enable = 'off';   
                app.Option3Button_2.Enable = 'off';                            
                app.FEditField_2.Enable = 'on';
                app.bEditField_2.Enable = 'on';
                
                app.EEditField_2.Enable = 'on';
                app.GEditField_2.Enable = 'on';

            end            
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            switch value
                case 'Effective Stress'
                    app.flag_stress.layer_3 = 1;
                app.FEditField_3.Enable = 'off';
                app.bEditField_3.Enable = 'off';
                app.EEditField_3.Enable = 'on';
                app.GEditField_3.Enable = 'on';  
                app.Option1Button_3.Enable = 'on';  
                app.Option2Button_3.Enable = 'on';   
                app.Option3Button_3.Enable = 'on';                 
                case 'Independent Variables'
                    app.flag_stress.layer_3 = 2; 
                app.Option1Button_3.Enable = 'off';  
                app.Option2Button_3.Enable = 'off';   
                app.Option3Button_3.Enable = 'off';                         
                app.FEditField_3.Enable = 'on';
                app.bEditField_3.Enable = 'on';
                
                app.EEditField_3.Enable = 'on';
                app.GEditField_3.Enable = 'on';

            end              
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            value = app.Switch_4.Value;
            switch value
                case 'Effective Stress'
                    app.flag_stress.layer_4 = 1;
                app.FEditField_4.Enable = 'off';
                app.bEditField_4.Enable = 'off';
                app.EEditField_4.Enable = 'on';
                app.GEditField_4.Enable = 'on';  
                app.Option1Button_4.Enable = 'on';  
                app.Option2Button_4.Enable = 'on';   
                app.Option3Button_4.Enable = 'on';     
                
                case 'Independent Variables'
                    app.flag_stress.layer_4 = 2;
                app.Option1Button_4.Enable = 'off';  
                app.Option2Button_4.Enable = 'off';   
                app.Option3Button_4.Enable = 'off';                           
                app.FEditField_4.Enable = 'on';
                app.bEditField_4.Enable = 'on';
                
                app.EEditField_4.Enable = 'on';
                app.GEditField_4.Enable = 'on';

            end             
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            value = app.Switch_5.Value;
            switch value
                case 'Effective Stress'
                app.flag_stress.layer_5 = 1;
                app.FEditField_5.Enable = 'off';
                app.bEditField_5.Enable = 'off';
                app.EEditField_5.Enable = 'on';
                app.GEditField_5.Enable = 'on';  
                app.Option1Button_5.Enable = 'on';  
                app.Option2Button_5.Enable = 'on';   
                app.Option3Button_5.Enable = 'on'; 
                                 app.CC = 0;
                
                case 'Independent Variables'
                app.flag_stress.layer_5 = 2; 
                app.Option1Button_5.Enable = 'off';  
                app.Option2Button_5.Enable = 'off';   
                app.Option3Button_5.Enable = 'off';                        
                app.FEditField_5.Enable = 'on';
                app.bEditField_5.Enable = 'on';
                
                app.EEditField_5.Enable = 'off';
                app.GEditField_5.Enable = 'off';
                
                 app.EditField_12.Enable = 'off';
                 app.EditField_13.Enable = 'off';   
                 app.EditField_14.Enable = 'off';   
                 app.EditField_15.Enable = 'off';   
                 app.EditField_16.Enable = 'off';                    

            end               
        end

        % Selection changed function: 
        % EffectivestressparameterButtonGroup
        function EffectivestressparameterButtonGroupSelectionChanged(app, event)
            selectedButton = app.EffectivestressparameterButtonGroup.SelectedObject;
            switch selectedButton
                case app.Option1Button
                    app.flag_chi.layer_1 = 1;
                    app.AirentrysuctionSeEditField.Enable = 'off';      
                    app.EditField_12.Enable = 'off';                   
                case app.Option2Button  
                    app.flag_chi.layer_1 = 2; 
                    app.AirentrysuctionSeEditField.Enable = 'on';
                    app.EditField_12.Enable = 'off';                      
                case app.Option3Button
                    app.flag_chi.layer_1 = 3; 
                    app.EditField_12.Enable = 'on';
                    app.AirentrysuctionSeEditField.Enable = 'off';                    
            end
        end

        % Selection changed function: 
        % EffectivestressparameterButtonGroup_2
        function EffectivestressparameterButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.EffectivestressparameterButtonGroup_2.SelectedObject;
            switch selectedButton
                case app.Option1Button_2
                    app.flag_chi.layer_2 = 1;
                    app.EditField_13.Enable = 'off';                      
                    app.AirentrysuctionSeEditField_2.Enable = 'off';                    
                case app.Option2Button_2  
                    app.flag_chi.layer_2 = 2; 
                    app.EditField_13.Enable = 'off';                     
                    app.AirentrysuctionSeEditField_2.Enable = 'on';
                case app.Option3Button_2
                    app.flag_chi.layer_2 = 3; 
                    app.EditField_13.Enable = 'on';                     
                    app.AirentrysuctionSeEditField_2.Enable = 'off';                    
            end            
        end

        % Selection changed function: 
        % EffectivestressparameterButtonGroup_3
        function EffectivestressparameterButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.EffectivestressparameterButtonGroup_3.SelectedObject;
            switch selectedButton
                case app.Option1Button_3
                    app.flag_chi.layer_3 = 1;
                    app.EditField_14.Enable = 'off';                       
                    app.AirentrysuctionSeEditField_3.Enable = 'off';                    
                case app.Option2Button_3  
                    app.flag_chi.layer_3 = 2;
                    app.EditField_14.Enable = 'off';                       
                    app.AirentrysuctionSeEditField_3.Enable = 'on';
                case app.Option3Button_3
                    app.flag_chi.layer_3 = 3; 
                    app.EditField_14.Enable = 'on';                     
                    app.AirentrysuctionSeEditField_3.Enable = 'off';                    
            end             
        end

        % Selection changed function: 
        % EffectivestressparameterButtonGroup_4
        function EffectivestressparameterButtonGroup_4SelectionChanged(app, event)
            selectedButton = app.EffectivestressparameterButtonGroup_4.SelectedObject;
            switch selectedButton
                case app.Option1Button_4
                    app.flag_chi.layer_4 = 1;
                    app.EditField_15.Enable = 'off';                       
                    app.AirentrysuctionSeEditField_4.Enable = 'off';                    
                case app.Option2Button_4  
                    app.flag_chi.layer_4 = 2; 
                    app.EditField_15.Enable = 'off';                      
                    app.AirentrysuctionSeEditField_4.Enable = 'on';
                case app.Option3Button_4
                    app.flag_chi.layer_4 = 3;
                    app.EditField_15.Enable = 'on';                 
                    app.AirentrysuctionSeEditField_4.Enable = 'off';                    
            end 
        end

        % Selection changed function: 
        % EffectivestressparameterButtonGroup_5
        function EffectivestressparameterButtonGroup_5SelectionChanged(app, event)
            selectedButton = app.EffectivestressparameterButtonGroup_5.SelectedObject;
            switch selectedButton
                case app.Option1Button_5
                    app.flag_chi.layer_5 = 1;
                    app.EditField_16.Enable = 'off';                    
                    app.AirentrysuctionSeEditField_5.Enable = 'off';                    
                case app.Option2Button_5  
                    app.flag_chi.layer_5 = 2;
                    app.EditField_16.Enable = 'off';                     
                    app.AirentrysuctionSeEditField_5.Enable = 'on';
                case app.Option3Button_5
                    app.flag_chi.layer_5 = 3;
                    app.EditField_16.Enable = 'on';                    
                    app.AirentrysuctionSeEditField_5.Enable = 'off';                    
            end 
        end

        % Value changed function: CouplingSwitch
        function CouplingSwitchValueChanged(app, event)
            value = app.CouplingSwitch.Value;
            switch value
                case 'Off'
                    app.CC = 0;
                case 'On'
                    app.CC = 1;
            end            
 
         
        end

        % Value changed function: Switch_6
        function Switch_6ValueChanged(app, event)
            value = app.Switch_6.Value;
            switch value
                case 'Single slope'
                    app.flag_slope = 1;
                    app.SingleslopePanel_2.Visible = 'on';
                    app.RegionalPanel_2.Visible = 'off';   
                    app.SlopeangleEditField.Enable = 'on'; 


      %%%%%%%%%%%%%%%%%%%Disable regional part%%%%%%%%%%%%%%%              
                    app.NumberofnodesEditField_2.Enable = 'off';
                    app.NumberofstepsEditField_2.Enable = 'off'; 
                    app.ThicknessButton.Enable = 'off';
                    app.SlopeButton.Enable = 'off';                    
                    app.RockButton.Enable = 'off';
                    app.ViewDEMButton.Enable = 'off'; 
                    app.TotaltimehrEditField_2.Enable = 'off';
                    app.EditField_21.Enable = 'off';
                    app.EditField_22.Enable = 'off';
                    app.EditField_23.Enable = 'off';                    
      %%%%%%%%%%%%%%%%%%%Enable single slope part%%%%%%%%%%%%%%%              
                    app.NumberofnodesEditField.Enable = 'on';
                    app.NumberofstepsEditField.Enable = 'on'; 
                    app.TotaltimehrEditField.Enable = 'on';
                    app.NumberofoutputsEditField.Enable = 'on';                    
                    app.NodesEditField.Enable = 'on';
                    app.LocationButton.Enable = 'on'; 
                    app.EditField_6.Enable = 'on';   
                    app.Image3.Enable = 'on';
                    app.NumberofobservationnodesKnob.Enable = 'on'; 
                    app.NodesLabel.Enable = 'on';
                    app.NodesEditField_2.Enable = 'on';                    
                    app.NodesEditField_3.Enable = 'on';
                    app.NodesEditField_4.Enable = 'on'; 
                    app.NodesEditField_5.Enable = 'on';                      
                    app.EditField_17.Enable = 'on';
                    app.EditField_18.Enable = 'on'; 
                    app.EditField_19.Enable = 'on';
                    app.EditField_20.Enable = 'on';                    
                    app.HeightmEditField.Enable = 'on';
                    app.DistancetothesurfaceLabel.Enable = 'on'; 
                                        
                case 'Regional'
                    app.flag_slope = 2;
                    app.SingleslopePanel_2.Visible = 'off';
                    app.RegionalPanel_2.Visible = 'on';  
                    app.SlopeangleEditField.Enable = 'off';
                 
      %%%%%%%%%%%%%%%%%%%Enable regional part%%%%%%%%%%%%%%%              
                    app.NumberofnodesEditField_2.Enable = 'on';
                    app.NumberofstepsEditField_2.Enable = 'on'; 
                    app.ThicknessButton.Enable = 'on';
                    app.SlopeButton.Enable = 'on';                    
                    app.RockButton.Enable = 'on';
                    app.ViewDEMButton.Enable = 'on'; 
                    app.TotaltimehrEditField_2.Enable = 'on';
                    app.EditField_21.Enable = 'on';
                    app.EditField_22.Enable = 'on';
                    app.EditField_23.Enable = 'on';                   
      %%%%%%%%%%%%%%%%%%%Disable single slope part%%%%%%%%%%%%%%%              
                    app.NumberofnodesEditField.Enable = 'off';
                    app.NumberofstepsEditField.Enable = 'off'; 
                    app.TotaltimehrEditField.Enable = 'off';
                    app.NumberofoutputsEditField.Enable = 'off';                    
                    app.NodesEditField.Enable = 'off';
                    app.LocationButton.Enable = 'off'; 
                    app.EditField_6.Enable = 'off';   
                    app.Image3.Enable = 'off';
                    app.NumberofobservationnodesKnob.Enable = 'off'; 
                    app.NodesLabel.Enable = 'off';
                    app.NodesEditField_2.Enable = 'off';                    
                    app.NodesEditField_3.Enable = 'off';
                    app.NodesEditField_4.Enable = 'off'; 
                    app.NodesEditField_5.Enable = 'off';                      
                    app.EditField_17.Enable = 'off';
                    app.EditField_18.Enable = 'off'; 
                    app.EditField_19.Enable = 'off';
                    app.EditField_20.Enable = 'off';                    
                    app.HeightmEditField.Enable = 'off';
                    app.DistancetothesurfaceLabel.Enable = 'off';                     
                    
                    
            end
        end

        % Value changed function: NumberofobservationnodesKnob
        function NumberofobservationnodesKnobValueChanged(app, event)
            value = app.NumberofobservationnodesKnob.Value;
            switch value
                case "1"
                app.flag_node =1;
                app.NodesEditField.Visible = "on";   	    
                app.NodesEditField_2.Visible = "off";
                app.NodesEditField_3.Visible = "off";
                app.NodesEditField_4.Visible = "off";
                app.NodesEditField_5.Visible = "off";   
                
                app.EditField_6.Visible = "on";   	    
                app.EditField_17.Visible = "off";
                app.EditField_18.Visible = "off";
                app.EditField_19.Visible = "off";
                app.EditField_20.Visible = "off";       
                
                app.VariablesDropDown.Visible = "on"; 
                app.VariablesDropDown_3.Visible = "off"; 
                app.VariablesDropDown_4.Visible = "off";                 
                app.VariablesDropDown_5.Visible = "off"; 
                app.VariablesDropDown_6.Visible = "off"; 
                
                app.mLabel.Visible = "on";
                app.mLabel_2.Visible = "off";
                app.mLabel_3.Visible = "off";                
                app.mLabel_4.Visible = "off" ;               
                app.mLabel_5.Visible = "off";                
                
                case "2"
                app.flag_node =2;
                app.NodesEditField.Visible = "on";                       
                app.NodesEditField_2.Visible = "on";
                app.NodesEditField_3.Visible = "off";
                app.NodesEditField_4.Visible = "off";
                app.NodesEditField_5.Visible = "off";    
                
                app.EditField_6.Visible = "on";   	    
                app.EditField_17.Visible = "on";
                app.EditField_18.Visible = "off";
                app.EditField_19.Visible = "off";
                app.EditField_20.Visible = "off"; 
                
                app.VariablesDropDown.Visible = "off"; 
                app.VariablesDropDown_3.Visible = "on"; 
                app.VariablesDropDown_4.Visible = "off";                 
                app.VariablesDropDown_5.Visible = "off"; 
                app.VariablesDropDown_6.Visible = "off";                 
                
                app.mLabel.Visible = "on";
                app.mLabel_2.Visible = "on";
                app.mLabel_3.Visible = "off"    ;            
                app.mLabel_4.Visible = "off" ;               
                app.mLabel_5.Visible = "off"  ;  
                
                
                case "3"
                app.flag_node =3;
                app.NodesEditField.Visible = "on";                       
                app.NodesEditField_2.Visible = "on";
                app.NodesEditField_3.Visible = "on";
                app.NodesEditField_4.Visible = "off";
                app.NodesEditField_5.Visible = "off";    
                
                app.EditField_6.Visible = "on";   	    
                app.EditField_17.Visible = "on";
                app.EditField_18.Visible = "on";
                app.EditField_19.Visible = "off";
                app.EditField_20.Visible = "off";  
                
                app.VariablesDropDown.Visible = "off"; 
                app.VariablesDropDown_3.Visible = "off"; 
                app.VariablesDropDown_4.Visible = "on";                 
                app.VariablesDropDown_5.Visible = "off"; 
                app.VariablesDropDown_6.Visible = "off";    
                
                app.mLabel.Visible = "on";
                app.mLabel_2.Visible = "on";
                app.mLabel_3.Visible = "on";                
                app.mLabel_4.Visible = "off" ;               
                app.mLabel_5.Visible = "off";                   
                
                case "4"
                app.flag_node =4;                    
                app.NodesEditField.Visible = "on";                       
                app.NodesEditField_2.Visible = "on";
                app.NodesEditField_3.Visible = "on";
                app.NodesEditField_4.Visible = "on";
                app.NodesEditField_5.Visible = "off";    
                
                app.EditField_6.Visible = "on";   	    
                app.EditField_17.Visible = "on";
                app.EditField_18.Visible = "on";
                app.EditField_19.Visible = "on";
                app.EditField_20.Visible = "off";    
                
                app.VariablesDropDown.Visible = "off"; 
                app.VariablesDropDown_3.Visible = "off"; 
                app.VariablesDropDown_4.Visible = "off";                 
                app.VariablesDropDown_5.Visible = "on"; 
                app.VariablesDropDown_6.Visible = "off";  
                
                app.mLabel.Visible = "on";
                app.mLabel_2.Visible = "on";
                app.mLabel_3.Visible = "on";                
                app.mLabel_4.Visible = "on" ;               
                app.mLabel_5.Visible = "off";                 
                
                case "5"
                app.flag_node =5;                    
                app.NodesEditField.Visible = "on";                       
                app.NodesEditField_2.Visible = "on";
                app.NodesEditField_3.Visible = "on";
                app.NodesEditField_4.Visible = "on";
                app.NodesEditField_5.Visible = "on";    
                
                app.EditField_6.Visible = "on";   	    
                app.EditField_17.Visible = "on";
                app.EditField_18.Visible = "on";
                app.EditField_19.Visible = "on";
                app.EditField_20.Visible = "on";     
                
                app.VariablesDropDown.Visible = "off"; 
                app.VariablesDropDown_3.Visible = "off"; 
                app.VariablesDropDown_4.Visible = "off";                 
                app.VariablesDropDown_5.Visible = "off"; 
                app.VariablesDropDown_6.Visible = "on";  
                
                app.mLabel.Visible = "on";
                app.mLabel_2.Visible = "on";
                app.mLabel_3.Visible = "on";                
                app.mLabel_4.Visible = "on" ;               
                app.mLabel_5.Visible = "on"; 
                
            end
        end

        % Callback function
        function ViewDEMButtonPushed(app, event)
%             InFile = 'img_small.tif';
%             
%             % [A,R] = geotiffread(filename)
%             
%             [SPEI,Geo] = geotiffread(InFile);
%             imshow(SPEI,'parent',app.DEMview)
% [filename, filepath] = uigetfile('*.tif', 'Select a tif file');
% fullname = fullfile(filepath, filename);
% TheImage = imread(fullname);
% ax_to_use = app.DEMview;    %adjust as appropriate
% imshow(TheImage, 'Parent', ax_to_use);
        end

        % Button pushed function: SaveresultsasmfileButton
        function SaveresultsasmfileButtonPushed(app, event)
            RESULTS = app.RESULTS;
            FE_data = app.FE_data;
            num_steps = app.num_steps;
            output_times = app.output_times;
            time_final = app.time_final;
            
            saveData = {'RESULTS','FE_data','num_steps','output_times','time_final'};
            uisave (saveData,'model_results')
        end

        % Button pushed function: ViewDEMButton
        function ViewDEMButtonPushed2(app, event)
%              plot(app.Profile_variation,app.RESULTS.profiles_hw(:,j),(app.FE_data.Z),'LineWidth',1)
%              app.Profile_variation.XLabel.String = 'Water head, hw(m)';
%              hold(app.Profile_variation, 'on')
%          [filename, filepath] = uigetfile('*.asc', 'Select a asc file');
%          fullname = fullfile(filepath, filename);
%          TheImage = imread(fullname);
%          ax_to_use = app.DEMview;    %adjust as appropriate
%          imshow(TheImage, 'Parent', ax_to_use);   
               cla(app.ViewDEM,'reset') 

                 ascii2xyz
                 
                A = [ans(:,1),ans(:,2),ans(:,3)];
                clear ans
                X_1 = A(:,1);
                Y_1 = A(:,2);
                Z_1 = A(:,3);
                scatter(app.ViewDEM,X_1,Y_1,200,Z_1,'s','filled') 
                c = gray;
                colormap(app.ViewDEM)
                d = colorbar(app.ViewDEM);
%                 d.Label.String = 'Elevation [m]';
%                 d.Label.FontSize = 10;
%             d.AxisLocation = 'in';
%                 app.ViewDEM.OuterPosition(3) = 490;
                set(get(d,'title'),'string','Elevation [m]', 'FontSize',10);  %%%does work


                hold on                
        end

        % Button pushed function: ThicknessButton
        function ThicknessButtonPushed(app, event)
             [filename, filepath] = uigetfile('*.asc', 'Select a asc file');
             app.thickness_re = filename;     
             msgbox('loading thickness complete')
        end

        % Button pushed function: SlopeButton
        function SlopeButtonPushed(app, event)
             [filename, filepath] = uigetfile('*.asc', 'Select a asc file');
             app.slope_re = filename;   
             msgbox('loading slope complete')             
        end

        % Button pushed function: RockButton
        function RockButtonPushed(app, event)
             [filename, filepath] = uigetfile('*.asc', 'Select a asc file');
             app.property_re = filename;
             msgbox('loading property complete')             
        end

        % Button pushed function: ClicktostartButton_2
        function ClicktostartButton_2Pushed(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%used for passing loading variables

        app.q_r =  app.RainfallIntensitymsEditField_2.Value;
        app.q_py = app.NormalStresskPaEditField.Value;
        app.q_px = app.ShearStresskPaEditField.Value;


%%%%%%%%%%%%%%%%%%%%%%%%%
% app.n_layer = 1;      
%%%%%%%%%%prepared for Hydrological parameters%%%%%%%%%%%%%%%%%

%%%%%%%%%%%%%%%%%%initializing the first layer%%%%%%%%%%%%%
                app.VG.theta_res_1 = 0;
                app.VG.theta_sat_1 = 1;
                app.VG.K_sat_1     = 0;
                app.VG.alpha_SWCC_1 = 0;
                app.VG.alpha_HCF_1 = 0;
                app.VG.n_SWCC_1     =0;
                app.VG.n_HCF_1 = 0;
                app.VG.m_SWCC_1 = 0;    
                app.VG.m_HCF_1 = 0;    
        
                app.GN.theta_res_1 = 0;
                app.GN.theta_sat_1 = 1;
                app.GN.K_sat_1     = 0;
                app.GN.alpha_SWCC_1 = 0;
                app.GN.alpha_HCF_1 = 0;    
        
                app.BC.K_sat_1     = 0;
                app.BC.lambda_1 = 0;
                app.BC.hs_1     = 0;
                app.BC.theta_res_1   = 0;
                app.BC.theta_sat_1   = 1;

         if   app.n_layer == 1
           switch app.HydraulicmodelDropDown.Value  
               
               case "Van Genuchten model"
               app.VG.theta_res_1 = app.theta_resEditField.Value;
               app.VG.theta_sat_1 = app.theta_satEditField.Value;
               app.VG.K_sat_1     = app.K_satEditField.Value;
               app.VG.alpha_SWCC_1 = app.alpha_SWCCEditField.Value;
               app.VG.alpha_HCF_1 = app.alpha_HCFEditField.Value;
               app.VG.n_SWCC_1     = app.n_SWCCEditField.Value;
               app.VG.n_HCF_1 = app.n_HCFEditField.Value;
               app.VG.m_SWCC_1 = app.m_SWCCEditField.Value;    
               app.VG.m_HCF_1 = app.m_HCFEditField.Value;    
               
               case "Gardner model"    
               app.GN.theta_res_1 = app.theta_resEditField_3.Value;
               app.GN.theta_sat_1 = app.theta_satEditField_3.Value;
               app.GN.K_sat_1     = app.K_satEditField_3.Value;
               app.GN.alpha_SWCC_1 = app.alpha_SWCCEditField_2.Value;
               app.GN.alpha_HCF_1 = app.alpha_HCFEditField_2.Value;    
               
               case  "Brooks and Corey model"    
                app.BC.K_sat_1     = app.K_satEditField_2.Value;
                app.BC.lambda_1 = app.lambdaEditField.Value;
                app.BC.hs_1     = app.hsEditField.Value;
                app.BC.theta_res_1   = app.theta_resEditField_2.Value;
                app.BC.theta_sat_1   = app.theta_satEditField_2.Value; 
           end
         end

        
%%%%%%%%%%%%%%%%%%%initialize the second layer%%%%%%%%%%%%%%%%%%% 
                app.VG.theta_res_2 = 0;
                app.VG.theta_sat_2 = 1;
                app.VG.K_sat_2     = 0;
                app.VG.alpha_SWCC_2 = 0;
                app.VG.alpha_HCF_2 = 0;
                app.VG.n_SWCC_2     =0;
                app.VG.n_HCF_2 = 0;
                app.VG.m_SWCC_2 = 0;    
                app.VG.m_HCF_2 = 0;    
        
                app.GN.theta_res_2 = 0;
                app.GN.theta_sat_2 = 1;
                app.GN.K_sat_2     = 0;
                app.GN.alpha_SWCC_2 = 0;
                    app.GN.alpha_HCF_2 = 0;    
        
                app.BC.K_sat_2     = 0;
                app.BC.lambda_2 = 0;
                app.BC.hs_2     = 0;
                app.BC.theta_res_2  = 0;
                app.BC.theta_sat_2   = 1;

        if   app.n_layer == 2
          switch app.HydraulicmodelDropDown.Value  
               
               case "Van Genuchten model"
               app.VG.theta_res_1 = app.theta_resEditField.Value;
               app.VG.theta_sat_1 = app.theta_satEditField.Value;
               app.VG.K_sat_1     = app.K_satEditField.Value;
               app.VG.alpha_SWCC_1 = app.alpha_SWCCEditField.Value;
               app.VG.alpha_HCF_1 = app.alpha_HCFEditField.Value;
               app.VG.n_SWCC_1     = app.n_SWCCEditField.Value;
               app.VG.n_HCF_1 = app.n_HCFEditField.Value;
               app.VG.m_SWCC_1 = app.m_SWCCEditField.Value;    
               app.VG.m_HCF_1 = app.m_HCFEditField.Value;    
               
               case "Gardner model"    
               app.GN.theta_res_1 = app.theta_resEditField_3.Value;
               app.GN.theta_sat_1 = app.theta_satEditField_3.Value;
               app.GN.K_sat_1     = app.K_satEditField_3.Value;
               app.GN.alpha_SWCC_1 = app.alpha_SWCCEditField_2.Value;
               app.GN.alpha_HCF_1 = app.alpha_HCFEditField_2.Value;    
               
               case  "Brooks and Corey model"    
                app.BC.K_sat_1     = app.K_satEditField_2.Value;
                app.BC.lambda_1 = app.lambdaEditField.Value;
                app.BC.hs_1     = app.hsEditField.Value;
                app.BC.theta_res_1   = app.theta_resEditField_2.Value;
                app.BC.theta_sat_1   = app.theta_satEditField_2.Value; 
           end
        
                switch app.HydraulicmodelDropDown_2.Value 
                    case "Van Genuchten model"
                    app.VG.theta_res_2 = app.theta_resEditField_4.Value;
                    app.VG.theta_sat_2 = app.theta_satEditField_4.Value;
                    app.VG.K_sat_2     = app.K_satEditField_4.Value;
                    app.VG.alpha_SWCC_2 = app.alpha_SWCCEditField_3.Value;
                    app.VG.alpha_HCF_2 = app.alpha_HCFEditField_3.Value;
                    app.VG.n_SWCC_2     = app.n_SWCCEditField_2.Value;
                    app.VG.n_HCF_2 = app.n_HCFEditField_2.Value;
                    app.VG.m_SWCC_2 = app.m_SWCCEditField_2.Value;    
                    app.VG.m_HCF_2 = app.m_HCFEditField_2.Value;    
                    
                    case "Gardner model"             
                    app.GN.theta_res_2 = app.theta_resEditField_6.Value;
                    app.GN.theta_sat_2 = app.theta_satEditField_6.Value;
                    app.GN.K_sat_2     = app.K_satEditField_6.Value;
                    app.GN.alpha_SWCC_2 = app.alpha_SWCCEditField_4.Value;
                    app.GN.alpha_HCF_2 = app.alpha_HCFEditField_4.Value;    
                    
                    case  "Brooks and Corey model"          
                    app.BC.K_sat_2     = app.K_satEditField_5.Value;
                    app.BC.lambda_2 = app.lambdaEditField_2.Value;
                    app.BC.hs_2     = app.hsEditField_2.Value;
                    app.BC.theta_res_2   = app.theta_resEditField_5.Value;
                    app.BC.theta_sat_2   = app.theta_satEditField_5.Value;
                end 
        end
          

 %%%%%%%%%%%%%%%%%%%%%%%%%initialize the third layer      
                      app.VG.theta_res_3 = 0;
                app.VG.theta_sat_3 = 1;
                app.VG.K_sat_3     = 0;
                app.VG.alpha_SWCC_3 = 0;
                app.VG.alpha_HCF_3 = 0;
                app.VG.n_SWCC_3     =0;
                app.VG.n_HCF_3 = 0;
                app.VG.m_SWCC_3 = 0;    
                app.VG.m_HCF_3 = 0;    
        
                app.GN.theta_res_3 = 0;
                app.GN.theta_sat_3 = 1;
                app.GN.K_sat_3     = 0;
                app.GN.alpha_SWCC_3 = 0;
                app.GN.alpha_HCF_3 = 0;    
        
                app.BC.K_sat_3     = 0;
                app.BC.lambda_3 = 0;
                app.BC.hs_3     = 0;
                app.BC.theta_res_3  = 0;
                app.BC.theta_sat_3   = 1;   
        if   app.n_layer == 3
          switch app.HydraulicmodelDropDown.Value  
               
               case "Van Genuchten model"
               app.VG.theta_res_1 = app.theta_resEditField.Value;
               app.VG.theta_sat_1 = app.theta_satEditField.Value;
               app.VG.K_sat_1     = app.K_satEditField.Value;
               app.VG.alpha_SWCC_1 = app.alpha_SWCCEditField.Value;
               app.VG.alpha_HCF_1 = app.alpha_HCFEditField.Value;
               app.VG.n_SWCC_1     = app.n_SWCCEditField.Value;
               app.VG.n_HCF_1 = app.n_HCFEditField.Value;
               app.VG.m_SWCC_1 = app.m_SWCCEditField.Value;    
               app.VG.m_HCF_1 = app.m_HCFEditField.Value;    
               
               case "Gardner model"    
               app.GN.theta_res_1 = app.theta_resEditField_3.Value;
               app.GN.theta_sat_1 = app.theta_satEditField_3.Value;
               app.GN.K_sat_1     = app.K_satEditField_3.Value;
               app.GN.alpha_SWCC_1 = app.alpha_SWCCEditField_2.Value;
               app.GN.alpha_HCF_1 = app.alpha_HCFEditField_2.Value;    
               
               case  "Brooks and Corey model"    
                app.BC.K_sat_1     = app.K_satEditField_2.Value;
                app.BC.lambda_1 = app.lambdaEditField.Value;
                app.BC.hs_1     = app.hsEditField.Value;
                app.BC.theta_res_1   = app.theta_resEditField_2.Value;
                app.BC.theta_sat_1   = app.theta_satEditField_2.Value; 
           end
        
                switch app.HydraulicmodelDropDown_2.Value 
                    case "Van Genuchten model"
                    app.VG.theta_res_2 = app.theta_resEditField_4.Value;
                    app.VG.theta_sat_2 = app.theta_satEditField_4.Value;
                    app.VG.K_sat_2     = app.K_satEditField_4.Value;
                    app.VG.alpha_SWCC_2 = app.alpha_SWCCEditField_3.Value;
                    app.VG.alpha_HCF_2 = app.alpha_HCFEditField_3.Value;
                    app.VG.n_SWCC_2     = app.n_SWCCEditField_2.Value;
                    app.VG.n_HCF_2 = app.n_HCFEditField_2.Value;
                    app.VG.m_SWCC_2 = app.m_SWCCEditField_2.Value;    
                    app.VG.m_HCF_2 = app.m_HCFEditField_2.Value;    
                    
                    case "Gardner model"             
                    app.GN.theta_res_2 = app.theta_resEditField_6.Value;
                    app.GN.theta_sat_2 = app.theta_satEditField_6.Value;
                    app.GN.K_sat_2     = app.K_satEditField_6.Value;
                    app.GN.alpha_SWCC_2 = app.alpha_SWCCEditField_4.Value;
                    app.GN.alpha_HCF_2 = app.alpha_HCFEditField_4.Value;    
                    
                    case  "Brooks and Corey model"          
                    app.BC.K_sat_2     = app.K_satEditField_5.Value;
                    app.BC.lambda_2 = app.lambdaEditField_2.Value;
                    app.BC.hs_2     = app.hsEditField_2.Value;
                    app.BC.theta_res_2   = app.theta_resEditField_5.Value;
                    app.BC.theta_sat_2   = app.theta_satEditField_5.Value;
                end 
           switch app.HydraulicmodelDropDown_3.Value 
               
               case "Van Genuchten model"
               app.VG.theta_res_3 = app.theta_resEditField_7.Value;
                app.VG.theta_sat_3 = app.theta_satEditField_7.Value;
                app.VG.K_sat_3     = app.K_satEditField_7.Value;
                app.VG.alpha_SWCC_3 = app.alpha_SWCCEditField_5.Value;
                app.VG.alpha_HCF_3 = app.alpha_HCFEditField_5.Value;
                app.VG.n_SWCC_3     = app.n_SWCCEditField_3.Value;
                app.VG.n_HCF_3 = app.n_HCFEditField_3.Value;
                app.VG.m_SWCC_3 = app.m_SWCCEditField_3.Value;    
                app.VG.m_HCF_3 = app.m_HCFEditField_3.Value;    
                
               case "Gardner model"    
                app.GN.theta_res_3 = app.theta_resEditField_9.Value;
                app.GN.theta_sat_3 = app.theta_satEditField_9.Value;
                app.GN.K_sat_3     = app.K_satEditField_9.Value;
                app.GN.alpha_SWCC_3 = app.alpha_SWCCEditField_6.Value;
                app.GN.alpha_HCF_3 = app.alpha_HCFEditField_6.Value;    
                
               case "Brooks and Corey model"
                app.BC.K_sat_3    = app.K_satEditField_8.Value;
                app.BC.lambda_3 = app.lambdaEditField_3.Value;
                app.BC.hs_3     = app.hsEditField_3.Value;
                app.BC.theta_res_3   = app.theta_resEditField_8.Value;
                app.BC.theta_sat_3   = app.theta_satEditField_8.Value;
           end
       end
        
 %%%%%%%%%%%%%%%%%%%%initialize the fouth layer%%%%%%%%%%%%%
                 app.VG.theta_res_4 = 0;
                app.VG.theta_sat_4 = 1;
                app.VG.K_sat_4     = 0;
                app.VG.alpha_SWCC_4 = 0;
                app.VG.alpha_HCF_4 = 0;
                app.VG.n_SWCC_4     =0;
                app.VG.n_HCF_4 = 0;
                app.VG.m_SWCC_4 = 0;    
                app.VG.m_HCF_4 = 0;    
        
                app.GN.theta_res_4 = 0;
                app.GN.theta_sat_4 = 1;
                app.GN.K_sat_4     = 0;
                app.GN.alpha_SWCC_4 = 0;
                app.GN.alpha_HCF_4 = 0;    
        
                app.BC.K_sat_4     = 0;
                app.BC.lambda_4 = 0;
                app.BC.hs_4     = 0;
                app.BC.theta_res_4  = 0;
                app.BC.theta_sat_4   = 1;  
      
        if   app.n_layer == 4
          switch app.HydraulicmodelDropDown.Value  
               
               case "Van Genuchten model"
               app.VG.theta_res_1 = app.theta_resEditField.Value;
               app.VG.theta_sat_1 = app.theta_satEditField.Value;
               app.VG.K_sat_1     = app.K_satEditField.Value;
               app.VG.alpha_SWCC_1 = app.alpha_SWCCEditField.Value;
               app.VG.alpha_HCF_1 = app.alpha_HCFEditField.Value;
               app.VG.n_SWCC_1     = app.n_SWCCEditField.Value;
               app.VG.n_HCF_1 = app.n_HCFEditField.Value;
               app.VG.m_SWCC_1 = app.m_SWCCEditField.Value;    
               app.VG.m_HCF_1 = app.m_HCFEditField.Value;    
               
               case "Gardner model"    
               app.GN.theta_res_1 = app.theta_resEditField_3.Value;
               app.GN.theta_sat_1 = app.theta_satEditField_3.Value;
               app.GN.K_sat_1     = app.K_satEditField_3.Value;
               app.GN.alpha_SWCC_1 = app.alpha_SWCCEditField_2.Value;
               app.GN.alpha_HCF_1 = app.alpha_HCFEditField_2.Value;    
               
               case  "Brooks and Corey model"    
                app.BC.K_sat_1     = app.K_satEditField_2.Value;
                app.BC.lambda_1 = app.lambdaEditField.Value;
                app.BC.hs_1     = app.hsEditField.Value;
                app.BC.theta_res_1   = app.theta_resEditField_2.Value;
                app.BC.theta_sat_1   = app.theta_satEditField_2.Value; 
           end
        
                switch app.HydraulicmodelDropDown_2.Value 
                    case "Van Genuchten model"
                    app.VG.theta_res_2 = app.theta_resEditField_4.Value;
                    app.VG.theta_sat_2 = app.theta_satEditField_4.Value;
                    app.VG.K_sat_2     = app.K_satEditField_4.Value;
                    app.VG.alpha_SWCC_2 = app.alpha_SWCCEditField_3.Value;
                    app.VG.alpha_HCF_2 = app.alpha_HCFEditField_3.Value;
                    app.VG.n_SWCC_2     = app.n_SWCCEditField_2.Value;
                    app.VG.n_HCF_2 = app.n_HCFEditField_2.Value;
                    app.VG.m_SWCC_2 = app.m_SWCCEditField_2.Value;    
                    app.VG.m_HCF_2 = app.m_HCFEditField_2.Value;    
                    
                    case "Gardner model"             
                    app.GN.theta_res_2 = app.theta_resEditField_6.Value;
                    app.GN.theta_sat_2 = app.theta_satEditField_6.Value;
                    app.GN.K_sat_2     = app.K_satEditField_6.Value;
                    app.GN.alpha_SWCC_2 = app.alpha_SWCCEditField_4.Value;
                    app.GN.alpha_HCF_2 = app.alpha_HCFEditField_4.Value;    
                    
                    case  "Brooks and Corey model"          
                    app.BC.K_sat_2     = app.K_satEditField_5.Value;
                    app.BC.lambda_2 = app.lambdaEditField_2.Value;
                    app.BC.hs_2     = app.hsEditField_2.Value;
                    app.BC.theta_res_2   = app.theta_resEditField_5.Value;
                    app.BC.theta_sat_2   = app.theta_satEditField_5.Value;
                end 
           switch app.HydraulicmodelDropDown_3.Value 
               
               case "Van Genuchten model"
               app.VG.theta_res_3 = app.theta_resEditField_7.Value;
                app.VG.theta_sat_3 = app.theta_satEditField_7.Value;
                app.VG.K_sat_3     = app.K_satEditField_7.Value;
                app.VG.alpha_SWCC_3 = app.alpha_SWCCEditField_5.Value;
                app.VG.alpha_HCF_3 = app.alpha_HCFEditField_5.Value;
                app.VG.n_SWCC_3     = app.n_SWCCEditField_3.Value;
                app.VG.n_HCF_3 = app.n_HCFEditField_3.Value;
                app.VG.m_SWCC_3 = app.m_SWCCEditField_3.Value;    
                app.VG.m_HCF_3 = app.m_HCFEditField_3.Value;    
                
               case "Gardner model"    
                app.GN.theta_res_3 = app.theta_resEditField_9.Value;
                app.GN.theta_sat_3 = app.theta_satEditField_9.Value;
                app.GN.K_sat_3     = app.K_satEditField_9.Value;
                app.GN.alpha_SWCC_3 = app.alpha_SWCCEditField_6.Value;
                app.GN.alpha_HCF_3 = app.alpha_HCFEditField_6.Value;    
                
               case "Brooks and Corey model"
                app.BC.K_sat_3    = app.K_satEditField_8.Value;
                app.BC.lambda_3 = app.lambdaEditField_3.Value;
                app.BC.hs_3     = app.hsEditField_3.Value;
                app.BC.theta_res_3   = app.theta_resEditField_8.Value;
                app.BC.theta_sat_3   = app.theta_satEditField_8.Value;
           end            
           switch app.HydraulicmodelDropDown_4.Value
               
               case "Van Genuchten model"
                app.VG.theta_res_4 = app.theta_resEditField_10.Value;
                app.VG.theta_sat_4 = app.theta_satEditField_10.Value;
                app.VG.K_sat_4     = app.K_satEditField_10.Value;
                app.VG.alpha_SWCC_4 = app.alpha_SWCCEditField_7.Value;
                app.VG.alpha_HCF_4 = app.alpha_HCFEditField_7.Value;
                app.VG.n_SWCC_4     = app.n_SWCCEditField_4.Value;
                app.VG.n_HCF_4 = app.n_HCFEditField_4.Value;
                app.VG.m_SWCC_4 = app.m_SWCCEditField_4.Value;    
                app.VG.m_HCF_4 = app.m_HCFEditField_4.Value; 
                
               case "Gardner model"        
                app.GN.theta_res_4 = app.theta_resEditField_12.Value;
                app.GN.theta_sat_4 = app.theta_satEditField_12.Value;
                app.GN.K_sat_4     = app.K_satEditField_12.Value;
                app.GN.alpha_SWCC_4 = app.alpha_SWCCEditField_8.Value;
                app.GN.alpha_HCF_4 = app.alpha_HCFEditField_8.Value;    
                
               case "Brooks and Corey model"        
                app.BC.K_sat_4     = app.K_satEditField_11.Value;
                app.BC.lambda_4 = app.lambdaEditField_4.Value;
                app.BC.hs_4     = app.hsEditField_4.Value;
                app.BC.theta_res_4   = app.theta_resEditField_11.Value;
                app.BC.theta_sat_4   = app.theta_satEditField_11.Value;
                
           end
       end

%%%%%%%%%%%%%%%%%%%%%%%%%initialize the fifth layer%%%%%%%%%%%
                app.VG.theta_res_5 = 0;
                app.VG.theta_sat_5 = 1;
                app.VG.K_sat_5    = 0;
                app.VG.alpha_SWCC_5 = 0;
                app.VG.alpha_HCF_5 = 0;
                app.VG.n_SWCC_5     =0;
                app.VG.n_HCF_5 = 0;
                app.VG.m_SWCC_5 = 0;    
                app.VG.m_HCF_5 = 0;    
        
                app.GN.theta_res_5 = 0;
                app.GN.theta_sat_5 = 1;
                app.GN.K_sat_5     = 0;
                app.GN.alpha_SWCC_5 = 0;
                app.GN.alpha_HCF_5 = 0;    
        
                app.BC.K_sat_5     = 0;
                app.BC.lambda_5 = 0;
                app.BC.hs_5     = 0;
                app.BC.theta_res_5  = 0;
                app.BC.theta_sat_5  = 1;   
                
        if   app.n_layer == 5 
          switch app.HydraulicmodelDropDown.Value  
               
               case "Van Genuchten model"
               app.VG.theta_res_1 = app.theta_resEditField.Value;
               app.VG.theta_sat_1 = app.theta_satEditField.Value;
               app.VG.K_sat_1     = app.K_satEditField.Value;
               app.VG.alpha_SWCC_1 = app.alpha_SWCCEditField.Value;
               app.VG.alpha_HCF_1 = app.alpha_HCFEditField.Value;
               app.VG.n_SWCC_1     = app.n_SWCCEditField.Value;
               app.VG.n_HCF_1 = app.n_HCFEditField.Value;
               app.VG.m_SWCC_1 = app.m_SWCCEditField.Value;    
               app.VG.m_HCF_1 = app.m_HCFEditField.Value;    
               
               case "Gardner model"    
               app.GN.theta_res_1 = app.theta_resEditField_3.Value;
               app.GN.theta_sat_1 = app.theta_satEditField_3.Value;
               app.GN.K_sat_1     = app.K_satEditField_3.Value;
               app.GN.alpha_SWCC_1 = app.alpha_SWCCEditField_2.Value;
               app.GN.alpha_HCF_1 = app.alpha_HCFEditField_2.Value;    
               
               case  "Brooks and Corey model"    
                app.BC.K_sat_1     = app.K_satEditField_2.Value;
                app.BC.lambda_1 = app.lambdaEditField.Value;
                app.BC.hs_1     = app.hsEditField.Value;
                app.BC.theta_res_1   = app.theta_resEditField_2.Value;
                app.BC.theta_sat_1   = app.theta_satEditField_2.Value; 
           end
        
                switch app.HydraulicmodelDropDown_2.Value 
                    case "Van Genuchten model"
                    app.VG.theta_res_2 = app.theta_resEditField_4.Value;
                    app.VG.theta_sat_2 = app.theta_satEditField_4.Value;
                    app.VG.K_sat_2     = app.K_satEditField_4.Value;
                    app.VG.alpha_SWCC_2 = app.alpha_SWCCEditField_3.Value;
                    app.VG.alpha_HCF_2 = app.alpha_HCFEditField_3.Value;
                    app.VG.n_SWCC_2     = app.n_SWCCEditField_2.Value;
                    app.VG.n_HCF_2 = app.n_HCFEditField_2.Value;
                    app.VG.m_SWCC_2 = app.m_SWCCEditField_2.Value;    
                    app.VG.m_HCF_2 = app.m_HCFEditField_2.Value;    
                    
                    case "Gardner model"             
                    app.GN.theta_res_2 = app.theta_resEditField_6.Value;
                    app.GN.theta_sat_2 = app.theta_satEditField_6.Value;
                    app.GN.K_sat_2     = app.K_satEditField_6.Value;
                    app.GN.alpha_SWCC_2 = app.alpha_SWCCEditField_4.Value;
                    app.GN.alpha_HCF_2 = app.alpha_HCFEditField_4.Value;    
                    
                    case  "Brooks and Corey model"          
                    app.BC.K_sat_2     = app.K_satEditField_5.Value;
                    app.BC.lambda_2 = app.lambdaEditField_2.Value;
                    app.BC.hs_2     = app.hsEditField_2.Value;
                    app.BC.theta_res_2   = app.theta_resEditField_5.Value;
                    app.BC.theta_sat_2   = app.theta_satEditField_5.Value;
                end 
           switch app.HydraulicmodelDropDown_3.Value 
               
               case "Van Genuchten model"
               app.VG.theta_res_3 = app.theta_resEditField_7.Value;
                app.VG.theta_sat_3 = app.theta_satEditField_7.Value;
                app.VG.K_sat_3     = app.K_satEditField_7.Value;
                app.VG.alpha_SWCC_3 = app.alpha_SWCCEditField_5.Value;
                app.VG.alpha_HCF_3 = app.alpha_HCFEditField_5.Value;
                app.VG.n_SWCC_3     = app.n_SWCCEditField_3.Value;
                app.VG.n_HCF_3 = app.n_HCFEditField_3.Value;
                app.VG.m_SWCC_3 = app.m_SWCCEditField_3.Value;    
                app.VG.m_HCF_3 = app.m_HCFEditField_3.Value;    
                
               case "Gardner model"    
                app.GN.theta_res_3 = app.theta_resEditField_9.Value;
                app.GN.theta_sat_3 = app.theta_satEditField_9.Value;
                app.GN.K_sat_3     = app.K_satEditField_9.Value;
                app.GN.alpha_SWCC_3 = app.alpha_SWCCEditField_6.Value;
                app.GN.alpha_HCF_3 = app.alpha_HCFEditField_6.Value;    
                
               case "Brooks and Corey model"
                app.BC.K_sat_3    = app.K_satEditField_8.Value;
                app.BC.lambda_3 = app.lambdaEditField_3.Value;
                app.BC.hs_3     = app.hsEditField_3.Value;
                app.BC.theta_res_3   = app.theta_resEditField_8.Value;
                app.BC.theta_sat_3   = app.theta_satEditField_8.Value;
           end            
           switch app.HydraulicmodelDropDown_4.Value
               
               case "Van Genuchten model"
                app.VG.theta_res_4 = app.theta_resEditField_10.Value;
                app.VG.theta_sat_4 = app.theta_satEditField_10.Value;
                app.VG.K_sat_4     = app.K_satEditField_10.Value;
                app.VG.alpha_SWCC_4 = app.alpha_SWCCEditField_7.Value;
                app.VG.alpha_HCF_4 = app.alpha_HCFEditField_7.Value;
                app.VG.n_SWCC_4     = app.n_SWCCEditField_4.Value;
                app.VG.n_HCF_4 = app.n_HCFEditField_4.Value;
                app.VG.m_SWCC_4 = app.m_SWCCEditField_4.Value;    
                app.VG.m_HCF_4 = app.m_HCFEditField_4.Value; 
                
               case "Gardner model"        
                app.GN.theta_res_4 = app.theta_resEditField_12.Value;
                app.GN.theta_sat_4 = app.theta_satEditField_12.Value;
                app.GN.K_sat_4     = app.K_satEditField_12.Value;
                app.GN.alpha_SWCC_4 = app.alpha_SWCCEditField_8.Value;
                app.GN.alpha_HCF_4 = app.alpha_HCFEditField_8.Value;    
                
               case "Brooks and Corey model"        
                app.BC.K_sat_4     = app.K_satEditField_11.Value;
                app.BC.lambda_4 = app.lambdaEditField_4.Value;
                app.BC.hs_4     = app.hsEditField_4.Value;
                app.BC.theta_res_4   = app.theta_resEditField_11.Value;
                app.BC.theta_sat_4   = app.theta_satEditField_11.Value;
                
           end            
           switch app.HydraulicmodelDropDown_5.Value
                case "Van Genuchten model"           
                app.VG.theta_res_5 = app.theta_resEditField_13.Value;
                app.VG.theta_sat_5 = app.theta_satEditField_13.Value;
                app.VG.K_sat_5    = app.K_satEditField_13.Value;
                app.VG.alpha_SWCC_5 = app.alpha_SWCCEditField_9.Value;
                app.VG.alpha_HCF_5 = app.alpha_HCFEditField_9.Value;
                app.VG.n_SWCC_5     = app.n_SWCCEditField_5.Value;
                app.VG.n_HCF_5 = app.n_HCFEditField_5.Value;
                app.VG.m_SWCC_5 = app.m_SWCCEditField_5.Value;    
                app.VG.m_HCF_5 = app.m_HCFEditField_5.Value;    
               case "Gardner model"     
                app.GN.theta_res_5 = app.theta_resEditField_15.Value;
                app.GN.theta_sat_5 = app.theta_satEditField_15.Value;
                app.GN.K_sat_5    = app.K_satEditField_15.Value;
                app.GN.alpha_SWCC_5 = app.alpha_SWCCEditField_10.Value;
                app.GN.alpha_HCF_5 = app.alpha_HCFEditField_10.Value;    
               case "Brooks and Corey model"             
                app.BC.K_sat_5     = app.K_satEditField_14.Value;
                app.BC.lambda_5 = app.lambdaEditField_5.Value;
                app.BC.hs_5     = app.hsEditField_5.Value;
                app.BC.theta_res_5   = app.theta_resEditField_14.Value;
                app.BC.theta_sat_5   = app.theta_satEditField_14.Value;
           end
       end 
%%%%%%%%%%prepared for mechanical parameters%%%%%%%%%%%%%%%%%     
%%%%%%%%%%%%%%%%%%initializing the first layer%%%%%%%%%%%%%
        app.chi.layer_1 = 0;
        app.Se.layer_1 = 0;       
        app.EM.E_1 = 0;
        app.EM.G_1 = 0;
        app.EM.F_1 = 0;        
        app.SP.c_1 = 0;
        app.SP.phi_1 = 0;
        app.SP.phi_b_1 = 0;        
        app.gamma_s.layer1 = 0; 

        app.chi.layer_2 = 0;
        app.Se.layer_2 = 0;        
        app.EM.E_2 = 0;
        app.EM.G_2 = 0;
        app.EM.F_2 = 0;        
        app.SP.c_2 = 0;
        app.SP.phi_2 = 0;
        app.SP.phi_b_2 = 0;        
        app.gamma_s.layer2 = 0; 

        app.chi.layer_3 = 0;
        app.Se.layer_3 = 0;        
        app.EM.E_3 = 0;
        app.EM.G_3 = 0;
        app.EM.F_3 = 0;        
        app.SP.c_3 = 0;
        app.SP.phi_3 = 0;
        app.SP.phi_b_3 = 0;        
        app.gamma_s.layer3 = 0; 
        
        app.chi.layer_4 = 0;
        app.Se.layer_4 = 0;        
        app.EM.E_4 = 0;
        app.EM.G_4 = 0;
        app.EM.F_4 = 0;        
        app.SP.c_4 = 0;
        app.SP.phi_4 = 0;
        app.SP.phi_b_4 = 0;        
        app.gamma_s.layer4 = 0; 
        
        app.chi.layer_5 = 0;
        app.Se.layer_5 = 0;        
        app.EM.E_5 = 0;
        app.EM.G_5 = 0;
        app.EM.F_5 = 0;        
        app.SP.c_5 = 0;
        app.SP.phi_5 = 0;
        app.SP.phi_b_5 = 0;        
        app.gamma_s.layer5 = 0; 

      if   app.n_layer == 1
          
                app.chi.layer_1 = app.EditField_12.Value;
                app.Se.layer_1 = app.AirentrysuctionSeEditField.Value;
                app.EM.E_1 = app.EEditField.Value;
                app.EM.G_1 = app.GEditField.Value;
                app.EM.F_1 = app.FEditField.Value;                
                app.SP.c_1 = app.cEditField.Value;
                app.SP.phi_1 = app.EditField_7.Value;
                app.SP.phi_b_1 = app.bEditField.Value;                   
                app.gamma_s.layer1 = app.sNm3EditField.Value;  
                
      end
        
%%%%%%%%%%%%%%%%%%%initialize the second layer%%%%%%%%%%%%%%%%%%% 


      if   app.n_layer == 2
    
                app.chi.layer_1 = app.EditField_12.Value;  
                app.Se.layer_1 = app.AirentrysuctionSeEditField.Value;                 
                app.EM.E_1 = app.EEditField.Value;
                app.EM.G_1 = app.GEditField.Value;
                app.EM.F_1 = app.FEditField.Value;                
                app.SP.c_1 = app.cEditField.Value;
                app.SP.phi_1 = app.EditField_7.Value;
                app.SP.phi_b_1 = app.bEditField.Value;                  
                app.gamma_s.layer1 = app.sNm3EditField.Value;  

                app.chi.layer_2 = app.EditField_13.Value; 
                app.Se.layer_2 = app.AirentrysuctionSeEditField_2.Value;                
                app.EM.E_2 = app.EEditField_2.Value;
                app.EM.G_2 = app.GEditField_2.Value;
                app.EM.F_2 = app.FEditField_2.Value;                
                app.SP.c_2 = app.cEditField_2.Value;
                app.SP.phi_2 = app.EditField_8.Value;
                app.SP.phi_b_2 = app.bEditField_2.Value; 
                app.gamma_s.layer2 = app.sNm3EditField_2.Value;  
                
      end
          

 %%%%%%%%%%%%%%%%%%%%%%%%%initialize the third layer

        
      if   app.n_layer == 3    
      
                app.chi.layer_1 = app.EditField_12.Value;
                app.Se.layer_1 = app.AirentrysuctionSeEditField.Value;                  
                app.EM.E_1 = app.EEditField.Value;
                app.EM.G_1 = app.GEditField.Value;
                app.EM.F_1 = app.FEditField.Value;              
                app.SP.c_1 = app.cEditField.Value;
                app.SP.phi_1 = app.EditField_7.Value;
                app.SP.phi_b_1 = app.bEditField.Value;                
                app.gamma_s.layer1 = app.sNm3EditField.Value;  
               

                app.chi.layer_2 = app.EditField_13.Value;   
                app.Se.layer_2 = app.AirentrysuctionSeEditField_2.Value;                  
                app.EM.E_2 = app.EEditField_2.Value;
                app.EM.G_2 = app.GEditField_2.Value;
                app.EM.F_2 = app.FEditField_2.Value;                
                app.SP.c_2 = app.cEditField_2.Value;
                app.SP.phi_2 = app.EditField_8.Value;
                app.SP.phi_b_2 = app.bEditField_2.Value;                  
                app.gamma_s.layer2 = app.sNm3EditField_2.Value;  
                

                app.chi.layer_3 = app.EditField_14.Value;  
                app.Se.layer_3 = app.AirentrysuctionSeEditField_3.Value;                  
                app.EM.E_3 = app.EEditField_3.Value;
                app.EM.G_3 = app.GEditField_3.Value;
                app.EM.F_3 = app.FEditField_3.Value;                
                app.SP.c_3 = app.cEditField_3.Value;
                app.SP.phi_3 = app.EditField_9.Value;
                app.SP.phi_b_3 = app.bEditField_3.Value;  
                app.gamma_s.layer3 = app.sNm3EditField_3.Value;  
                          
       end
        
 %%%%%%%%%%%%%%%%%%%%initialize the fouth layer%%%%%%%%%%%%%

        
      if   app.n_layer == 4    
                app.chi.layer_1 = app.EditField_12.Value;
                app.Se.layer_1 = app.AirentrysuctionSeEditField.Value;                  
                app.EM.E_1 = app.EEditField.Value;
                app.EM.G_1 = app.GEditField.Value;
                app.EM.F_1 = app.FEditField.Value;              
                app.SP.c_1 = app.cEditField.Value;
                app.SP.phi_1 = app.EditField_7.Value;
                app.SP.phi_b_1 = app.bEditField.Value;                
                app.gamma_s.layer1 = app.sNm3EditField.Value;  
               
                app.chi.layer_2 = app.EditField_13.Value;   
                app.Se.layer_2 = app.AirentrysuctionSeEditField_2.Value;                  
                app.EM.E_2 = app.EEditField_2.Value;
                app.EM.G_2 = app.GEditField_2.Value;
                app.EM.F_2 = app.FEditField_2.Value;                
                app.SP.c_2 = app.cEditField_2.Value;
                app.SP.phi_2 = app.EditField_8.Value;
                app.SP.phi_b_2 = app.bEditField_2.Value;                  
                app.gamma_s.layer2 = app.sNm3EditField_2.Value;  
                
                app.chi.layer_3 = app.EditField_14.Value;  
                app.Se.layer_3 = app.AirentrysuctionSeEditField_3.Value;                  
                app.EM.E_3 = app.EEditField_3.Value;
                app.EM.G_3 = app.GEditField_3.Value;
                app.EM.F_3 = app.FEditField_3.Value;                
                app.SP.c_3 = app.cEditField_3.Value;
                app.SP.phi_3 = app.EditField_9.Value;
                app.SP.phi_b_3 = app.bEditField_3.Value;  
                app.gamma_s.layer3 = app.sNm3EditField_3.Value;                   
               
                app.chi.layer_4 = app.EditField_15.Value;
                app.Se.layer_4 = app.AirentrysuctionSeEditField_4.Value;                  
                app.EM.E_4 = app.EEditField_4.Value;
                app.EM.G_4 = app.GEditField_4.Value;
                app.EM.F_4 = app.FEditField_4.Value;                
                app.SP.c_4 = app.cEditField_4.Value;
                app.SP.phi_4 = app.EditField_10.Value;
                app.SP.phi_b_4 = app.bEditField_4.Value;                  
                app.gamma_s.layer4 = app.sNm3EditField_4.Value;  
                
          
       end

%%%%%%%%%%%%%%%%%%%%%%%%%initialize the fifth layer%%%%%%%%%%%

        
      if   app.n_layer == 5    
                app.chi.layer_1 = app.EditField_12.Value;
                app.Se.layer_1 = app.AirentrysuctionSeEditField.Value;                                 
                app.EM.E_1 = app.EEditField.Value;
                app.EM.G_1 = app.GEditField.Value;
                app.EM.F_1 = app.FEditField.Value;              
                app.SP.c_1 = app.cEditField.Value;
                app.SP.phi_1 = app.EditField_7.Value;
                app.SP.phi_b_1 = app.bEditField.Value;                
                app.gamma_s.layer1 = app.sNm3EditField.Value;  
               
                app.chi.layer_2 = app.EditField_13.Value;    
                app.Se.layer_2 = app.AirentrysuctionSeEditField_2.Value;    
                app.EM.E_2 = app.EEditField_2.Value;
                app.EM.G_2 = app.GEditField_2.Value;
                app.EM.F_2 = app.FEditField_2.Value;                
                app.SP.c_2 = app.cEditField_2.Value;
                app.SP.phi_2 = app.EditField_8.Value;
                app.SP.phi_b_2 = app.bEditField_2.Value;                  
                app.gamma_s.layer2 = app.sNm3EditField_2.Value;  
                
                app.chi.layer_3 = app.EditField_14.Value; 
                app.Se.layer_3 = app.AirentrysuctionSeEditField_3.Value;                  
                app.EM.E_3 = app.EEditField_3.Value;
                app.EM.G_3 = app.GEditField_3.Value;
                app.EM.F_3 = app.FEditField_3.Value;                
                app.SP.c_3 = app.cEditField_3.Value;
                app.SP.phi_3 = app.EditField_9.Value;
                app.SP.phi_b_3 = app.bEditField_3.Value;  
                app.gamma_s.layer3 = app.sNm3EditField_3.Value;                   

                app.chi.layer_4 = app.EditField_15.Value;
                app.Se.layer_4= app.AirentrysuctionSeEditField_4.Value;                  
                app.EM.E_4 = app.EEditField_4.Value;
                app.EM.G_4 = app.GEditField_4.Value;
                app.EM.F_4 = app.FEditField_4.Value;                
                app.SP.c_4 = app.cEditField_4.Value;
                app.SP.phi_4 = app.EditField_10.Value;
                app.SP.phi_b_4 = app.bEditField_4.Value;                  
                app.gamma_s.layer4 = app.sNm3EditField_4.Value;     
           
                app.chi.layer_5 = app.EditField_16.Value; 
                app.Se.layer_5 = app.AirentrysuctionSeEditField_5.Value;                  
                app.EM.E_5 = app.EEditField_5.Value;
                app.EM.G_5 = app.GEditField_5.Value;
                app.EM.F_5 = app.FEditField_5.Value;                
                app.SP.c_5 = app.cEditField_5.Value;
                app.SP.phi_5 = app.EditField_11.Value;
                app.SP.phi_b_5 = app.bEditField_5.Value;                
                app.gamma_s.layer5 = app.sNm3EditField_5.Value;  
                

      end
      
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%sink term%%%%%%%%%%%%%%%%%%%%%      
            switch app.ST_flag
                case 1
                   app.ST_flag = 1;
                   app.RootWaterUptakeModelPanel.Visible = 'on' ;
                   app.ST.h1 = app.h1EditField.Value;
                   app.ST.h2 = app.h2EditField.Value;
                   app.ST.h3 = app.h3EditField.Value;
                   app.ST.h4 = app.h4EditField.Value;
                   app.ST.d_r = app.d_rEditField.Value;
                   app.ST.T_p = app.T_pEditField.Value;  
                case 2
                   app.ST_flag = 2;                    
                   app.RootWaterUptakeModelPanel.Visible = 'on' ;
                   app.ST.h1 = 0;
                   app.ST.h2 = 0;
                   app.ST.h3 = 0;
                   app.ST.h4 = 0;
                   app.ST.d_r = 0;
                   app.ST.T_p = 0;                       
            end 
      
      
      %MAIN PROGRAM 
       
        %% 1. INPUT MAPS
        [zz,R] = arcgridread (eval('app.thickness_re'));
        B = app.thickness_re;
        zz_length = numel(zz);
        zz_column = reshape(zz,[zz_length,1]);
        %1.2 slope angle
        [slope,R] = arcgridread(eval('app.slope_re'));
        slope_length = numel(slope);
        slope_column = reshape(slope,[slope_length,1]);
        % 1.3 read property grid
        [rock_map,~] = arcgridread (eval('app.property_re'));
        rock = reshape(rock_map,[zz_length,1]);
        
          
        %% 2. INITIALIZE RESULTS MATRIX AND COMPUTATIONAL CELLS
        %2.1 initialize matrix of results
        hw1 = app.prescribedwaterheadhw1EditField.Value;
        hw2 = app.Thresholdvaluehw2EditField.Value;
        
        app.RESULTS     = zeros(length(slope_column),6);
        t_failure   = NaN(length(slope_column),1);
        z_failure   = NaN(length(slope_column),1);
        IR   = NaN(length(slope_column),1);
        Run   = NaN(length(slope_column),1);
        accu_IR   = NaN(length(slope_column),1);
        accu_Run   = NaN(length(slope_column),1);        
%         flag        = NaN(length(slope_column),1);
        %2.2 define the cells that want to be tested
        aux_cells_nan = isnan(slope_column);
        app.geom_nodes_re      = app.NumberofnodesEditField_2.Value;                      %Number of geometrical nodes []
        app.time_final_re       = app.TotaltimehrEditField_2.Value;                %running time [T]. 1st number starts at the top
        app.num_steps_re        = app.NumberofstepsEditField_2.Value;                    %Number of computational time steps []  1000000        

        rho_w = 0.1*10000;

        slope_uplim = app.EditField_22.Value;
        slope_lowlim = app.EditField_21.Value;
        depth_detect = app.EditField_23.Value;

        switch app.IC_index
            case 1
            f = (app.constantEditField.Value);
            case 2
            f = (app.functionEditField.Value);        
        end
     

        comp_cells_1 = find(aux_cells_nan==0 & slope_column>slope_lowlim & slope_column<slope_uplim & rock==1 & zz_column==depth_detect); %ignore the NaN cells and those outside the slope angle range
     
        %%%%%%%%%%%%%%%%prepared for layer assignment%%%%%%%%%%
    AAA          = zz_column(comp_cells_1);                      %Domain of the problem [L]
    height_aux   = AAA(1);
    app.flag_mech =1;

    switch app.n_layer
        case 1
               z_layer.layer1       = 1*height_aux;      %Expressed in fraction of total thickness
               z_layer.layer2       = 0*height_aux;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer3       = 0*height_aux;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer4       = 0*height_aux;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer5       = 0*height_aux;      %each number refer to the elevation of the bottom of the layer 
        case 2
               z_layer.layer1       = app.FirstlayerEditField.Value*height_aux;      %Expressed in fraction of total thickness
               z_layer.layer2       = 1*height_aux;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer3       = 0*height_aux;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer4       = 0*height_aux;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer5       = 0*height_aux;      %each number refer to the elevation of the bottom of the layer 
        case 3
               z_layer.layer1       = app.FirstlayerEditField.Value*height_aux;      %Expressed in fraction of total thickness
               z_layer.layer2       = app.SecondlayerEditField.Value*height_aux;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer3       = 1*height_aux;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer4       = 0*height_aux;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer5       = 0*height_aux;      %each number refer to the elevation of the bottom of the layer 
        case 4
               z_layer.layer1       = app.FirstlayerEditField.Value*height_aux;      %Expressed in fraction of total thickness
               z_layer.layer2       = app.SecondlayerEditField.Value*height_aux;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer3       = app.ThirdlayerEditField.Value*height_aux;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer4       = 1*height_aux;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer5       = 0*height_aux;      %each number refer to the elevation of the bottom of the layer                
        case 5
               z_layer.layer1       = app.FirstlayerEditField.Value*height_aux;      %Expressed in fraction of total thickness
               z_layer.layer2       = app.SecondlayerEditField.Value*height_aux;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer3       = app.ThirdlayerEditField.Value*height_aux;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer4       = app.FourthlayerEditField.Value*height_aux;      %each number refer to the elevation of the bottom of the layer
               z_layer.layer5       = 1*height_aux;      %each number refer to the elevation of the bottom of the layer  
    end
    
        [t_failure(comp_cells_1),z_failure(comp_cells_1),IR(comp_cells_1),Run(comp_cells_1),accu_Run(comp_cells_1),accu_IR(comp_cells_1)] = get_suction_FS_explicit_regional(app.ST_flag,app.ST,hw2, height_aux,zz_column(comp_cells_1),slope_column(comp_cells_1),app.geom_nodes_re,app.num_steps_re,app.time_final_re,rho_w,f,...
            app.n_layer,z_layer,app.flag_hydro_1,app.flag_hydro_2,app.flag_hydro_3,app.flag_hydro_4,app.flag_hydro_5,app.VG,app.GN,app.BC,app.flag_mech,app.EM,app.SP,app.gamma_s, app.chi,app.Se,...
            app.Loading_Index,app.q_r,app.q_py,app.q_px, app.RI_Table2.Data,app.flag_chi,app.flag_stress); 
        

        %% 3. POST-PROCESSING
        %3.1 Store the computed results  
        app.RESULTS(:,1) = t_failure;
        app.RESULTS(:,2) = z_failure;
        app.RESULTS(:,3) = IR;
        app.RESULTS(:,4) = Run;
        app.RESULTS(:,5) = accu_Run;
        app.RESULTS(:,6) = accu_IR;
        
        msgbox('Computation completed!')
%         save model_results_k2
 
      
        end

        % Button pushed function: LoadresultsButton
        function LoadresultsButtonPushed(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%loading regional results%%%%%%%%%%%%%%%%%%%
        
                  [filename, filepath] = uigetfile('*.mat', 'Select a mat file');

                    if isequal(filename,0)               
                       msgbox('Please load your results')
                    else
                      app.results_filepath_re = filepath;
                      app.resutls_re = filename;
                      load(eval('app.resutls_re'))
                      app.RESULTS = RESULTS;
                      app.Time_f = app.RESULTS(:,1);
                      app.Depth_f = app.RESULTS(:,2);
                      app.IR_re = app.RESULTS(:,3);
                      app.Run_re = app.RESULTS(:,4);
                      app.Accu_Run_re = app.RESULTS(:,5);
                      app.Accu_IR_re = app.RESULTS(:,6);
                      app.Accu_IR_re(isnan(app.Accu_IR_re)) = 0;
                      app.Accu_Run_re(isnan(app.Accu_Run_re)) = 0;
                       msgbox('Load results completed')
                    end
        end

        % Button pushed function: LoadresultsButton_2
        function LoadresultsButton_2Pushed(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%loading single slope results%%%%%%%%%%%%%%%%%%%            
%                     load model_results
                      [filename, filepath] = uigetfile('*.mat', 'Select a mat file');
                    
                    if isequal(filename,0)               
                       msgbox('Please load your results')
                    else
                      app.resutls_sin = filename;
                      load(eval('app.resutls_sin'))
                      
                    app.RESULTS = RESULTS;
                    app.FE_data = FE_data;
                    app.num_steps = num_steps;
                    app.output_times = output_times;
                    app.time_final = time_final;                        
                       msgbox('Load results completed')
                    end

        end

        % Button pushed function: TerminateButton
        function TerminateButtonPushed(app, event)
            closereq
        end

        % Button pushed function: SaveresultsButton
        function SaveresultsButtonPushed(app, event)
            RESULTS = app.RESULTS;
            saveData = {'RESULTS'};
            uisave (saveData,'model_results_regional')
        end

        % Button pushed function: TransfertorasterfileButton
        function TransfertorasterfileButtonPushed(app, event)
    
        switch app.transtoraster_flag
            case 1
                  [ZZ,ref] = ImportAsciiRaster(app.headername);
                    cd 'C:\convert'        
                        wb_output_1 = waitbar(0,'generating output raster files');                     
                        map_title = ('failure_time.asc');
                        export_map = reshape(app.Time_f,size(ZZ));
                        SaveAsciiRaster(export_map, ref, map_title);
                    cd ..   
                    
            case 2         
                  [ZZ,ref] = ImportAsciiRaster(app.headername);
                    cd 'C:\convert'        
                        wb_output_1 = waitbar(0,'generating output raster files');                     
                        map_title = ('failure_depth.asc');
                        export_map = reshape(app.Depth_f,size(ZZ));
                        SaveAsciiRaster(export_map, ref, map_title);
                    cd ..
                    
            case 3        
                  [ZZ,ref] = ImportAsciiRaster(app.headername);
                    cd 'C:\convert'        
                        wb_output_1 = waitbar(0,'generating output raster files');                     
                        map_title = ('cumu_IR.asc');
                        export_map = reshape(app.Accu_IR_re,size(ZZ));
                        SaveAsciiRaster(export_map, ref, map_title);
                    cd ..   
                    
            case 4         
                  [ZZ,ref] = ImportAsciiRaster(app.headername);
                    cd 'C:\convert'      
                        wb_output_1 = waitbar(0,'generating output raster files');                     
                        map_title = ('cumu_Run.asc');
                        export_map = reshape(app.Accu_Run_re,size(ZZ));
                        SaveAsciiRaster(export_map, ref, map_title);
                    cd ..                       
        end

            
        end

        % Callback function
        function Button2Pushed(app, event)
   
    cla(app.ViewDEM) 
   
                 ascii2xyz
                 
                A = [ans(:,1),ans(:,2),ans(:,3)];
                clear ans
                X_1 = A(:,1);
                Y_1 = A(:,2);
                Z_1 = A(:,3);
                scatter(app.ViewDEM,X_1,Y_1,200,Z_1,'s','filled') 
                c = gray;
                colormap(app.ViewDEM,c)
                d = colorbar(app.ViewDEM);
                d.Label.String = 'Elevation [m]';
                d.Label.FontSize = 10;
                hold on           
        end

        % Value changed function: VariablesDropDown_3
        function VariablesDropDown_3ValueChanged(app, event)
            value = app.VariablesDropDown_3.Value;
            switch value            
                case "water head_A"
                    app.hist_flag = 1;
                case "water head_B" 
                    app.hist_flag = 2;
                case "Infiltration rate" 
                    app.hist_flag = 6;   
                case "Runoff" 
                    app.hist_flag = 7;
                case "Cumulative infiltration" 
                    app.hist_flag = 8;  
                case "Cumulative Runoff" 
                    app.hist_flag = 9;                       
            end            
        end

        % Value changed function: VariablesDropDown_4
        function VariablesDropDown_4ValueChanged(app, event)
            value = app.VariablesDropDown_4.Value;
            switch value            
                case "water head_A"
                    app.hist_flag = 1;
                case "water head_B" 
                    app.hist_flag = 2;
                case "water head_C" 
                    app.hist_flag = 3;
                case "Infiltration rate" 
                    app.hist_flag = 6;   
                case "Runoff" 
                    app.hist_flag = 7;
                case "Cumulative infiltration" 
                    app.hist_flag = 8; 
                case "Cumulative Runoff" 
                    app.hist_flag = 9;                       
            end            
        end

        % Value changed function: VariablesDropDown_5
        function VariablesDropDown_5ValueChanged(app, event)
            value = app.VariablesDropDown_5.Value;
            switch value            
                case "water head_A"
                    app.hist_flag = 1;
                case "water head_B" 
                    app.hist_flag = 2;
                case "water head_C" 
                    app.hist_flag = 3;
                case "water head_D"
                    app.hist_flag = 4;
                case "Infiltration rate" 
                    app.hist_flag = 6;   
                case "Runoff" 
                    app.hist_flag = 7;
                case "Cumulative infiltration" 
                    app.hist_flag = 8;
                case "Cumulative Runoff" 
                    app.hist_flag = 9;                       
            end            
        end

        % Value changed function: VariablesDropDown_6
        function VariablesDropDown_6ValueChanged(app, event)
            value = app.VariablesDropDown_6.Value;
            switch value            
                case "water head_A"
                    app.hist_flag = 1;
                case "water head_B" 
                    app.hist_flag = 2;
                case "water head_C" 
                    app.hist_flag = 3;
                case "water head_D"
                    app.hist_flag = 4;
                case "water head_E" 
                    app.hist_flag = 5;
                case "Infiltration rate" 
                    app.hist_flag = 6;   
                case "Runoff" 
                    app.hist_flag = 7;
                case "Cumulative infiltration" 
                    app.hist_flag = 8; 
                case "Cumulative Runoff" 
                    app.hist_flag = 9;                       
            end            
        end

        % Button pushed function: ViewmapButton
        function ViewmapButtonPushed(app, event)
        cla(app.DEM,'reset') 
        A = app.map_flag;   
        switch app.map_flag    
            case 1
            if isempty(app.X_FT)
                msgbox('please load failure time')
            else
                ax = app.DEM;
                colormap(ax,[gray(64);cool(64)]);
                
                h(1) = scatter(ax,app.X_DEM,app.Y_DEM,2,app.Z_DEM,'s','filled'); 
                hold(ax)

                h(2) = scatter(ax,app.X_FT,app.Y_FT,1,app.Z_FT,'s','filled');      
                hold(ax)
              
                
                m = 64; % 64-elements is each colormap
                cmin = min(app.Z_DEM(:)); 
                cmax = max(app.Z_DEM(:)); 
                C1 = min(m,round((m-1)*(app.Z_DEM-cmin)/(cmax-cmin))+1);
                
                cmin = min(app.Z_FT(:)); 
                cmax = max(app.Z_FT(:)); 
                C2 = min(m,round((m-1)*(app.Z_FT-cmin)/(cmax-cmin))+1); 
                C2 = C2+m;
                
                set(h(1),'CData',C1); 
                set(h(2),'CData',C2); 
                
                caxis([min(C1(:)) max(C2(:))]);
%                 colorbar(ax,'YLim',[65,128],'YTickLabel',[0 48]) 

             d = colorbar(ax,'YLim',[65,128],'YTickLabel',[30 34 38 42 45 48]);
             set(get(d,'title'),'string','Failure time [hrs]', 'FontSize',10);     
            xlabel(app.DEM, 'X Coordinates [m]')
            ylabel(app.DEM, 'Y Coordinates [m]')

%              a = app.Time_f;
%                 [STATS] = plot_cdf(app.UIAxes,app.Time_f,'temporal evolution of normalized unstable area',0,48,1);


            cla(app.UIAxes,'reset') 
            cla(app.UIAxes2,'reset') 
            aux_variable = app.Time_f(~isnan(app.Time_f));            
            cdf_var = cdfplot(app.Time_f);
            STATS(:,1) = get(cdf_var,'XData')';
            STATS(:,2) = get(cdf_var,'YData')';
            set(cdf_var,'LineWidth',1,'Color','r')
            app.UIAxes.XLim = [0 50];
            app.UIAxes.YLabel.String = 'F(x)';     
            app.UIAxes.XLabel.String = 'Time [hrs]';              
            title(app.UIAxes,'PDF of the failure time')
            hold(app.UIAxes)
            edges = [0:1:48];
             h = hist(app.Time_f,edges);
            bar(app.UIAxes,edges,h./length(aux_variable))
            hold(app.UIAxes)
            plot(app.UIAxes2,STATS(:,1),STATS(:,2),'-')
            app.UIAxes2.YLabel.String = 'F(x)';
            app.UIAxes2.XLabel.String = 'Time [hrs]';              
            title(app.UIAxes2,'CDF of the failure time')
            app.UIAxes2.XLim = [0 50];
%             handle=figure;

            set(figure(1), 'visible', 'off');
                
            msgbox('Done!') 
            end
            
            case 2
            if isempty(app.X_FD)
                msgbox('please load failure depth')
            else                
                ax = app.DEM;
                colormap(ax,[gray(64);cool(64)]);
                
                h(1) = scatter(ax,app.X_DEM,app.Y_DEM,2,app.Z_DEM,'s','filled'); 
                hold(ax)

                h(2) = scatter(ax,app.X_FD,app.Y_FD,1,app.Z_FD,'s','filled');      
                hold(ax)
              
                
                m = 64; % 64-elements is each colormap
                cmin = min(app.Z_DEM(:)); 
                cmax = max(app.Z_DEM(:)); 
                C1 = min(m,round((m-1)*(app.Z_DEM-cmin)/(cmax-cmin))+1);
                A = app.Z_FD(:);
                cmin = min(app.Z_FD(:)); 
                cmax = max(app.Z_FD(:)); 
                C2 = min(m,round((m-1)*(app.Z_FD-cmin)/(cmax-cmin))+1); 
                C2 = C2+m;
                
                set(h(1),'CData',C1); 
                set(h(2),'CData',C2); 
                
                caxis([min(C1(:)) max(C2(:))]);

                 d = colorbar(ax,'YLim',[65,128],'YTickLabel',[0 0.4 0.8 1.2 1.6 2.0]);
                 set(get(d,'title'),'string','Failure depth [m]', 'FontSize',10);     
                xlabel(app.DEM, 'X Coordinates [m]')
                ylabel(app.DEM, 'Y Coordinates [m]')             
                                 
                cla(app.UIAxes,'reset') 
                cla(app.UIAxes2,'reset') 
                aux_variable = app.Depth_f(~isnan(app.Depth_f));            
                cdf_var = cdfplot(app.Depth_f);
                STATS(:,1) = get(cdf_var,'XData')';
                STATS(:,2) = get(cdf_var,'YData')';
                set(cdf_var,'LineWidth',1,'Color','r')
                app.UIAxes.XLim = [0 2];
                app.UIAxes.YLabel.String = 'F(x)';     
                app.UIAxes.XLabel.String = 'Depth [m]';              
                title(app.UIAxes,'PDF of the failure depth')
                hold(app.UIAxes)
                edges = [0:0.1:2];
                h = hist(app.Depth_f,edges);
                bar(app.UIAxes,edges,h./length(aux_variable))
                hold(app.UIAxes)
                plot(app.UIAxes2,STATS(:,1),STATS(:,2),'-')
                app.UIAxes2.YLabel.String = 'F(x)';
                app.UIAxes2.XLabel.String = 'Depth [m]';              
                title(app.UIAxes2,'CDF of the failure depth')
                app.UIAxes2.XLim = [0 2];
                set(figure(1), 'visible', 'off');                   
                msgbox('Done!') 
            end
          
            case 3
            if isempty(app.zz_cumu_IR)
                msgbox('please load cumulative infiltration')
            else                  
                ax = app.DEM;
                mapshow(ax,app.zz_cumu_IR,app.R1_cumu_IR,'DisplayType','contour','Fill','on','LineColor','none');
                colormap(ax,flipud(parula));  
                hold(ax)
                d = colorbar(ax,'FontSize',20);
                 caxis(ax,[0 0.5]);
                ax.FontSize = 20;                 
                 set(get(ax,'title'),'string','Cumulative Infiltration [m]', 'FontSize',25);     
                xlabel(app.DEM, 'X Coordinates [m]','FontSize',20)
                ylabel(app.DEM, 'Y Coordinates [m]','FontSize',20) 
                
                cla(app.UIAxes,'reset') 
                cla(app.UIAxes2,'reset') 
                aux_variable = app.Accu_IR_re(~isnan(app.Accu_IR_re));            
                cdf_var = cdfplot(app.Accu_IR_re);
                STATS(:,1) = get(cdf_var,'XData')';
                STATS(:,2) = get(cdf_var,'YData')';
                set(cdf_var,'LineWidth',1,'Color','r')
                app.UIAxes.XLim = [0 0.5];
                app.UIAxes.YLabel.String = 'F(x)';     
                app.UIAxes.XLabel.String = 'Depth [m]';              
                title(app.UIAxes,'PDF of the Cumulative Infiltration')
                hold(app.UIAxes)
                edges = [0:0.05:0.5];
                h = hist(app.Accu_IR_re,edges);
                bar(app.UIAxes,edges,h./length(aux_variable))
                hold(app.UIAxes)
                plot(app.UIAxes2,STATS(:,1),STATS(:,2),'-')
                app.UIAxes2.YLabel.String = 'F(x)';
                app.UIAxes2.XLabel.String = 'Depth [m]';              
                title(app.UIAxes2,'CDF of the Cumulative Infiltration')
                app.UIAxes2.XLim = [0 0.5];
                set(figure(1), 'visible', 'off');                 

                msgbox('Done!')   
            end
                
            case 4
            if isempty(app.zz_cumu_run)
                msgbox('please load cumulative runoff')
            else                 
                ax = app.DEM;
                mapshow(ax,app.zz_cumu_run,app.R1_cumu_run,'DisplayType','contour','Fill','on','LineColor','none');
                colormap(ax,flipud(winter));  
                hold(ax)
                d = colorbar(ax,'FontSize',20);
                caxis(ax,[0 0.2]);
                ax.FontSize = 20;
                set(get(ax,'title'),'string','Cumulative Runoff [m]', 'FontSize',25);
                xlabel(app.DEM, 'X Coordinates [m]','FontSize',20)
                ylabel(app.DEM, 'Y Coordinates [m]','FontSize',20)             
                
                
%                 cla(app.UIAxes,'reset') 
%                 cla(app.UIAxes2,'reset') 
%                 aux_variable = app.Accu_Run_re(~isnan(app.Accu_Run_re));            
%                 cdf_var = cdfplot(app.Accu_Run_re);
%                 STATS(:,1) = get(cdf_var,'XData')';
%                 STATS(:,2) = get(cdf_var,'YData')';
%                 set(cdf_var,'LineWidth',1,'Color','r')
%                 app.UIAxes.XLim = [0 0.2];
%                 app.UIAxes.YLabel.String = 'F(x)';     
%                 app.UIAxes.XLabel.String = 'Depth [m]';              
%                 title(app.UIAxes,'PDF of the Cumulative Runoff')
%                 hold(app.UIAxes)
%                 edges = [0:0.04:0.2];
%                 h = hist(app.Accu_Run_re,edges);
%                 bar(app.UIAxes,edges,h./length(aux_variable))
%                 hold(app.UIAxes)
%                 plot(app.UIAxes2,STATS(:,1),STATS(:,2),'-')
%                 app.UIAxes2.YLabel.String = 'F(x)';
%                 app.UIAxes2.XLabel.String = 'Depth [m]';              
%                 title(app.UIAxes2,'CDF of the Cumulative Runoff')
%                 app.UIAxes2.XLim = [0 0.2];
%                 set(figure(1), 'visible', 'off'); 
                
                msgbox('Done!')  
            end
            
        end

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton
                case app.FluxonthetopButton
                    app.BC_top_flag = 1;
                    app.prescribedwaterheadhw1EditField.Enable = "off";                      
                case app.PressureonthetopButton
                    app.BC_top_flag = 2;   
                    app.prescribedwaterheadhw1EditField.Enable = "on";                    
            end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            switch selectedButton
                case app.FluxinthebottomButton
                    app.BC_bottom_flag = 2;
                case app.PressureinthebottomButton
                    app.BC_bottom_flag = 1;                    
            end            
        end

        % Callback function
        function flowrateValueChanged(app, event)
            value = app.flowrate.Value;
            switch value
                case 'Off'
                    app.flow_flag = 0;
                  
                case 'On'
                    app.flow_flag = 1;
                   
            end
        end

        % Button pushed function: LoadDEMButton
        function LoadDEMButtonPushed(app, event)
                ascii2xyz                
                HS = [ans(:,1),ans(:,2),ans(:,3)];
                clear ans
                app.X_DEM = HS(:,1);
                app.Y_DEM = HS(:,2);
                app.Z_DEM = HS(:,3);
                msgbox('Load DEM data completed')
        end

        % Button pushed function: LoadFailuretimeButton
        function LoadFailuretimeButtonPushed(app, event)
                ascii2xyz                
                FT = [ans(:,1),ans(:,2),ans(:,3)];
                clear ans
                app.X_FT = FT(:,1);
                app.Y_FT = FT(:,2);
                app.Z_FT = FT(:,3); 
                msgbox('Load Failure time data completed')               
        end

        % Button pushed function: LoadsourceareaButton
        function LoadsourceareaButtonPushed(app, event)
            ascii2xyz

                Poly = [ans(:,1),ans(:,2),ans(:,3)];
                clear ans
                app.X_Poly = Poly(:,1);
                app.Y_Poly = Poly(:,2);
                app.Z_Poly = Poly(:,3);
                msgbox('Load source area completed')                
        end

        % Button pushed function: ExtractheaderButton
        function ExtractheaderButtonPushed(app, event)
               [filename, filepath] = uigetfile('*.asc', '*.txt','Select a mat file');
               app.headername = filename;
              if isequal(filename,0)               
               msgbox('Please input a file')
              else
               msgbox('Load header complete')
              end
        end

        % Button pushed function: LoadFailuredepthButton
        function LoadFailuredepthButtonPushed(app, event)
                ascii2xyz;
                
                FD = [ans(:,1),ans(:,2),ans(:,3)];
                clear ans
                app.X_FD = FD(:,1);
                app.Y_FD = FD(:,2);
                app.Z_FD = FD(:,3); 
                msgbox('Load Failure depth data completed')

        end

        % Value changed function: FileDropDown
        function FileDropDownValueChanged(app, event)
            value = app.FileDropDown.Value;
            switch value 
                case "Please choose a file"    
                    app.transtoraster_flag = 0;
                case "Failure time"
                    app.transtoraster_flag = 1; 
                case "Failure depth"    
                    app.transtoraster_flag = 2;
                case "Cumulative IR"
                    app.transtoraster_flag = 3;                       
                case "Cumulative Runoff"
                    app.transtoraster_flag = 4;      
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value 
                case "Please choose a map"    
                    app.map_flag = 0;
                case "Failure time"
                    app.map_flag = 1; 
                case "Failure depth"    
                    app.map_flag = 2;
                case "Cumulative IR"
                    app.map_flag = 3;                       
                case "Cumulative Runoff"
                    app.map_flag = 4;      
            end            
        end

        % Button pushed function: LoadCumu_IRButton
        function LoadCumu_IRButtonPushed(app, event)
                [filename, filepath] = uigetfile('*.asc', '*.txt','Select a mat file');
            if isequal(filename,0)               
               msgbox('Please input a file')
            else
                [zz,R1] = arcgridread (filename);
                app.zz_cumu_IR = zz;
                app.R1_cumu_IR = R1;
                msgbox('Load Cumulative Infiltration data completed')
            end
        end

        % Button pushed function: LoadCumu_RunoffButton
        function LoadCumu_RunoffButtonPushed(app, event)
                [filename, filepath] = uigetfile('*.asc', '*.txt','Select a mat file');

            if isequal(filename,0)               
               msgbox('Please input a file')
            else  
                [zz,R1] = arcgridread (filename);
                app.zz_cumu_run = zz;
                app.R1_cumu_run = R1;
                msgbox('Load Cumulative Runoff data completed')
            end
        end

        % Button pushed function: ClearfigureButton
        function ClearfigureButtonPushed(app, event)
                cla(app.Profile_variation) 
%                 cla(app.Time_hist)
%                 cla(app.FS)
        end

        % Button pushed function: ClearfigureButton_2
        function ClearfigureButton_2Pushed(app, event)
                cla(app.Profile_variation) 
                cla(app.Time_hist)
                cla(app.FS)            
        end

        % Button pushed function: ClearfigureButton_3
        function ClearfigureButton_3Pushed(app, event)
             cla(app.Time_hist,'reset')
             cla(app.Profile_variation,'reset')
        end

        % Button pushed function: ClearmapButton
        function ClearmapButtonPushed(app, event)
            cla(app.DEM,'reset')
            cla(app.UIAxes,'reset')
            cla(app.UIAxes2,'reset')            
        end

        % Value changed function: vegetation
        function vegetationValueChanged(app, event)
            value = app.vegetation.Value;
            switch value
                case 'On'
                   app.ST_flag = 1; 
                   app.h1EditField.Enable = 'on' ;
                   app.h2EditField.Enable = 'on' ;
                   app.h3EditField.Enable = 'on' ;                   
                   app.h4EditField.Enable = 'on' ;                   
                   app.d_rEditField.Enable = 'on' ;                   
                   app.T_pEditField.Enable = 'on' ; 
                   app.Image13.Enable = 'on' ;                   
                case 'Off'
                   app.ST_flag = 2; 
                   app.h1EditField.Enable = 'off' ;
                   app.h2EditField.Enable = 'off' ;
                   app.h3EditField.Enable = 'off' ;                   
                   app.h4EditField.Enable = 'off' ;                   
                   app.d_rEditField.Enable = 'off' ;                   
                   app.T_pEditField.Enable = 'off' ; 
                   app.Image13.Enable = 'off' ;                    
            end             
        end

        % Value changed function: flowrate
        function flowrateValueChanged2(app, event)
            value = app.flowrate.Value;
            switch value
                case 'Off'
                    app.flow_flag = 0;
                    app.Thresholdvaluehw2EditField.Enable = "off";                  
                case 'On'
                    app.flow_flag = 1;
                    app.Thresholdvaluehw2EditField.Enable = "on";                    
                   
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged2(app, event)
            selectedNode = app.Tree.SelectedNodes;
             % Enable form only when a child is selected    
           Items = {'Hydraulogic loading','Normal stress','Tangential stress','Constant_W','Time history_W','Constant_V','Time history_V','Constant_H','Time history_H'};
%            subItems = {'Constant_W','Time history_W','Constant_V','Time history_V','Constant_H','Time history_H'};
           Loading_Index_aux = find(strcmp(selectedNode.Text,Items),1);
           app.Loading_Index = Loading_Index_aux;

           switch Loading_Index_aux
               case 1 
                   disableForm1(app);
                   disableForm2(app);   
               case 2 
                   disableForm1(app);
                   disableForm2(app); 
               case 3 
                   disableForm1(app);
                   disableForm2(app);                    
               case 4 
                    app.RainfallIntensitymsEditField_2.Enable = 'on';
                    app.NormalStresskPaEditField.Enable = 'off'; 
                    app.ShearStresskPaEditField.Enable = 'off';   
                    disableForm2(app); 
               
                case 5 
                    enableForm2(app)
                    disableForm1(app); 
                    
                case 6                 
                    disableForm2(app); 
                    app.RainfallIntensitymsEditField_2.Enable = 'off';
                    app.NormalStresskPaEditField.Enable = 'on'; 
                    app.ShearStresskPaEditField.Enable = 'off';   
               case 7
                    enableForm2(app)
                    disableForm1(app); 
               case 8                 
                    disableForm2(app); 
                    app.RainfallIntensitymsEditField_2.Enable = 'off';
                    app.NormalStresskPaEditField.Enable = 'off'; 
                    app.ShearStresskPaEditField.Enable = 'on';   
               case 9
                    disableForm1(app); 
                    enableForm2(app)
           end


        end

        % Value changed function: LoadtimehistdataButton_2
        function LoadtimehistdataButton_2ValueChanged(app, event)

            [file, path] = uigetfile('*.xlsx');
            app.FEMUIFigure.Visible = 'off';
            app.FEMUIFigure.Visible = 'on';
            if isequal(file,0)               
               msgbox('Please input an Excel file')
            else
                t = readtable(fullfile(path, file));
              
                app.RI_Table2.Data = t;
                % Set column names as a pull down menu
                app.XDropDown.Items = t.Properties.VariableNames;
%                 app.YDropDown.Items = t.Properties.VariableNames;
                
%                 plot(app.Time_hist, app.UITable.data.(app.XDropDown.Value), app.UITable.Data.(app.YDropDown.Value));
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FEMUIFigure = uifigure('Visible', 'off');
            app.FEMUIFigure.Name = 'cLiNUs';
            app.TabGroup = uitabgroup(app.FEMUIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageSource = 'WeChat Image_20190912102947.png';
            app.FESettingsTab = uitab(app.TabGroup);
            app.FESettingsTab.Title = 'FE Settings';
            app.SingleslopePanel = uipanel(app.FESettingsTab);
            app.SingleslopePanel.Title = 'Single slope';
            app.NumberofnodesLabel = uilabel(app.SingleslopePanel);
            app.NumberofnodesLabel.Text = 'Number of nodes:';
            app.NumberofnodesEditField = uieditfield(app.SingleslopePanel, 'numeric');
            app.NumberofnodesEditField.Value = 21;
            app.NumberofstepsLabel = uilabel(app.SingleslopePanel);
            app.NumberofstepsLabel.Text = 'Number of steps:';
            app.NumberofstepsEditField = uieditfield(app.SingleslopePanel, 'numeric');
            app.NumberofstepsEditField.Value = 5000;
            app.TotaltimehrLabel = uilabel(app.SingleslopePanel);
            app.TotaltimehrLabel.Text = 'Total time (hr):';
            app.TotaltimehrEditField = uieditfield(app.SingleslopePanel, 'numeric');
            app.TotaltimehrEditField.Value = 2;
            app.NumberofoutputsEditFieldLabel = uilabel(app.SingleslopePanel);
            app.NumberofoutputsEditFieldLabel.Text = 'Number of outputs:';
            app.NumberofoutputsEditField = uieditfield(app.SingleslopePanel, 'numeric');
            app.NumberofoutputsEditField.Value = 10;
            app.NodesEditField = uieditfield(app.SingleslopePanel, 'numeric');
            app.NodesEditField.Value = 1;
            app.LocationButton = uibutton(app.SingleslopePanel, 'push');
            app.LocationButton.ButtonPushedFcn = createCallbackFcn(app, @LocationButtonPushed, true);
            app.LocationButton.Text = 'Location';
            app.EditField_6 = uieditfield(app.SingleslopePanel, 'numeric');
            app.Image3 = uiimage(app.SingleslopePanel);
            app.Image3.ImageSource = 'FE.png';
            app.NumberofobservationnodesLabel = uilabel(app.SingleslopePanel);
            app.NumberofobservationnodesLabel.Text = 'Number of observation nodes';
            app.NumberofobservationnodesKnob = uiknob(app.SingleslopePanel, 'discrete');
            app.NumberofobservationnodesKnob.Items = {'1', '2', '3', '4', '5'};
            app.NumberofobservationnodesKnob.ValueChangedFcn = createCallbackFcn(app, @NumberofobservationnodesKnobValueChanged, true);
            app.NumberofobservationnodesKnob.Value = '1';
            app.NodesLabel = uilabel(app.SingleslopePanel);
            app.NodesLabel.Text = 'Nodes';
            app.NodesEditField_2 = uieditfield(app.SingleslopePanel, 'numeric');
            app.NodesEditField_2.Value = 2;
            app.NodesEditField_3 = uieditfield(app.SingleslopePanel, 'numeric');
            app.NodesEditField_3.Value = 3;
            app.NodesEditField_4 = uieditfield(app.SingleslopePanel, 'numeric');
            app.NodesEditField_4.Value = 4;
            app.NodesEditField_5 = uieditfield(app.SingleslopePanel, 'numeric');
            app.NodesEditField_5.Value = 5;
            app.EditField_17 = uieditfield(app.SingleslopePanel, 'numeric');
            app.EditField_18 = uieditfield(app.SingleslopePanel, 'numeric');
            app.EditField_19 = uieditfield(app.SingleslopePanel, 'numeric');
            app.EditField_20 = uieditfield(app.SingleslopePanel, 'numeric');
            app.HeightmLabel = uilabel(app.SingleslopePanel);
            app.HeightmLabel.Text = 'Height (m):';
            app.HeightmEditField = uieditfield(app.SingleslopePanel, 'numeric');
            app.HeightmEditField.Value = 2;
            app.DistancetothesurfaceLabel = uilabel(app.SingleslopePanel);
            app.DistancetothesurfaceLabel.Text = '*Distance to the surface';
            app.mLabel = uilabel(app.SingleslopePanel);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.SingleslopePanel);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.SingleslopePanel);
            app.mLabel_3.Text = 'm';
            app.mLabel_4 = uilabel(app.SingleslopePanel);
            app.mLabel_4.Text = 'm';
            app.mLabel_5 = uilabel(app.SingleslopePanel);
            app.mLabel_5.Text = 'm';
            app.SlopeangleLabel = uilabel(app.SingleslopePanel);
            app.SlopeangleLabel.Text = 'Slope angle (°):';
            app.SlopeangleEditField = uieditfield(app.SingleslopePanel, 'numeric');
            app.Image4 = uiimage(app.SingleslopePanel);
            app.Image4.ImageSource = 'infintyslope.png';
            app.RegionalPanel = uipanel(app.FESettingsTab);
            app.RegionalPanel.Title = 'Regional';
            app.NumberofnodesEditField_2Label = uilabel(app.RegionalPanel);
            app.NumberofnodesEditField_2Label.Text = 'Number of nodes:';
            app.NumberofnodesEditField_2 = uieditfield(app.RegionalPanel, 'numeric');
            app.NumberofnodesEditField_2.Value = 11;
            app.NumberofstepsEditField_2Label = uilabel(app.RegionalPanel);
            app.NumberofstepsEditField_2Label.Text = 'Number of steps:';
            app.NumberofstepsEditField_2 = uieditfield(app.RegionalPanel, 'numeric');
            app.NumberofstepsEditField_2.Value = 10000;
            app.TotaltimehrEditField_2Label = uilabel(app.RegionalPanel);
            app.TotaltimehrEditField_2Label.Text = 'Total time (hr):';
            app.TotaltimehrEditField_2 = uieditfield(app.RegionalPanel, 'numeric');
            app.TotaltimehrEditField_2.Value = 480;
            app.InputfilenamePanel = uipanel(app.RegionalPanel);
            app.InputfilenamePanel.Title = 'Input filename';
            app.ThicknessButton = uibutton(app.InputfilenamePanel, 'push');
            app.ThicknessButton.ButtonPushedFcn = createCallbackFcn(app, @ThicknessButtonPushed, true);
            app.ThicknessButton.Text = 'Thickness';
            app.SlopeButton = uibutton(app.InputfilenamePanel, 'push');
            app.SlopeButton.ButtonPushedFcn = createCallbackFcn(app, @SlopeButtonPushed, true);
            app.SlopeButton.Text = 'Slope';
            app.RockButton = uibutton(app.InputfilenamePanel, 'push');
            app.RockButton.ButtonPushedFcn = createCallbackFcn(app, @RockButtonPushed, true);
            app.RockButton.Text = 'Rock';
            app.EditField_21 = uieditfield(app.InputfilenamePanel, 'numeric');
            app.EditField_22 = uieditfield(app.InputfilenamePanel, 'numeric');
            app.EditField_22.Value = 90;
            app.toLabel = uilabel(app.InputfilenamePanel);
            app.toLabel.Text = 'to';
            app.EditField_23 = uieditfield(app.InputfilenamePanel, 'numeric');
            app.EditField_23.Value = 2;
            app.DEMvisualizationOptionalPanel = uipanel(app.RegionalPanel);
            app.DEMvisualizationOptionalPanel.Title = 'DEM visualization (Optional)';
            app.ViewDEMButton = uibutton(app.DEMvisualizationOptionalPanel, 'push');
            app.ViewDEMButton.ButtonPushedFcn = createCallbackFcn(app, @ViewDEMButtonPushed2, true);
            app.ViewDEMButton.Text = 'View DEM';
            app.ViewDEM = uiaxes(app.DEMvisualizationOptionalPanel);
            title(app.ViewDEM, '')
            xlabel(app.ViewDEM, 'X Coordinates [m]')
            ylabel(app.ViewDEM, 'Y Coordinates [m]')
            app.ViewDEM.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Panel_11 = uipanel(app.FESettingsTab);
            app.Switch_6 = uiswitch(app.Panel_11, 'slider');
            app.Switch_6.Items = {'Single slope', 'Regional'};
            app.Switch_6.ValueChangedFcn = createCallbackFcn(app, @Switch_6ValueChanged, true);
            app.Switch_6.Value = 'Single slope';
            app.LayersTab = uitab(app.TabGroup);
            app.LayersTab.Title = 'Layers';
            app.LayersettingsPanel = uipanel(app.LayersTab);
            app.LayersettingsPanel.Title = 'Layer settings';
            app.CoordinateoflayersinputrequiredPanel = uipanel(app.LayersettingsPanel);
            app.CoordinateoflayersinputrequiredPanel.Title = 'Coordinate of layers (α input required)';
            app.FirstlayerEditFieldLabel = uilabel(app.CoordinateoflayersinputrequiredPanel);
            app.FirstlayerEditFieldLabel.Text = 'First layer';
            app.FirstlayerEditField = uieditfield(app.CoordinateoflayersinputrequiredPanel, 'numeric');
            app.SecondlayerEditFieldLabel = uilabel(app.CoordinateoflayersinputrequiredPanel);
            app.SecondlayerEditFieldLabel.Text = 'Second layer';
            app.SecondlayerEditField = uieditfield(app.CoordinateoflayersinputrequiredPanel, 'numeric');
            app.ThirdlayerEditFieldLabel = uilabel(app.CoordinateoflayersinputrequiredPanel);
            app.ThirdlayerEditFieldLabel.Text = 'Third layer';
            app.ThirdlayerEditField = uieditfield(app.CoordinateoflayersinputrequiredPanel, 'numeric');
            app.FourthlayerEditFieldLabel = uilabel(app.CoordinateoflayersinputrequiredPanel);
            app.FourthlayerEditFieldLabel.Text = 'Fourth layer';
            app.FourthlayerEditField = uieditfield(app.CoordinateoflayersinputrequiredPanel, 'numeric');
            app.FifthlayerEditFieldLabel = uilabel(app.CoordinateoflayersinputrequiredPanel);
            app.FifthlayerEditFieldLabel.Text = 'Fifth layer';
            app.FifthlayerEditField = uieditfield(app.CoordinateoflayersinputrequiredPanel, 'numeric');
            app.HeightLabel = uilabel(app.CoordinateoflayersinputrequiredPanel);
            app.HeightLabel.Text = '× Height';
            app.HeightLabel_2 = uilabel(app.CoordinateoflayersinputrequiredPanel);
            app.HeightLabel_2.Text = '× Height';
            app.HeightLabel_3 = uilabel(app.CoordinateoflayersinputrequiredPanel);
            app.HeightLabel_3.Text = '× Height';
            app.HeightLabel_4 = uilabel(app.CoordinateoflayersinputrequiredPanel);
            app.HeightLabel_4.Text = '× Height';
            app.HeightLabel_5 = uilabel(app.CoordinateoflayersinputrequiredPanel);
            app.HeightLabel_5.Text = '× Height';
            app.NumberoflayerKnobLabel = uilabel(app.LayersettingsPanel);
            app.NumberoflayerKnobLabel.Text = 'Number of layer ';
            app.NumberoflayerKnob = uiknob(app.LayersettingsPanel, 'discrete');
            app.NumberoflayerKnob.Items = {'1', '2', '3', '4', '5'};
            app.NumberoflayerKnob.ValueChangedFcn = createCallbackFcn(app, @NumberoflayerKnobValueChanged, true);
            app.NumberoflayerKnob.Value = '1';
            app.GeometryPanel = uipanel(app.LayersettingsPanel);
            app.GeometryPanel.Title = 'Geometry';
            app.Image8 = uiimage(app.GeometryPanel);
            app.Image8.ImageSource = '1layer.png';
            app.Image9 = uiimage(app.GeometryPanel);
            app.Image9.ImageSource = '2layer.png';
            app.Image10 = uiimage(app.GeometryPanel);
            app.Image10.ImageSource = '4layer_2.png';
            app.Image11 = uiimage(app.GeometryPanel);
            app.Image11.ImageSource = '5layer.png';
            app.Image12 = uiimage(app.GeometryPanel);
            app.Image12.ImageSource = '3layer.png';
            app.HomogeneoussoilbydefautLabel = uilabel(app.LayersettingsPanel);
            app.HomogeneoussoilbydefautLabel.Text = '(Homogeneous soil by defaut)';
            app.MaterialsTab = uitab(app.TabGroup);
            app.MaterialsTab.Title = 'Materials';
            app.TabGroup2 = uitabgroup(app.MaterialsTab);
            app.HydraulicsTab = uitab(app.TabGroup2);
            app.HydraulicsTab.Title = 'Hydraulics';
            app.Panel = uipanel(app.HydraulicsTab);
            app.stPanel = uipanel(app.Panel);
            app.stPanel.Title = '1 st';
            app.VGmodelPanel = uipanel(app.stPanel);
            app.VGmodelPanel.Title = 'VG model';
            app.theta_resEditFieldLabel = uilabel(app.VGmodelPanel);
            app.theta_resEditFieldLabel.Text = 'theta_res';
            app.theta_resEditField = uieditfield(app.VGmodelPanel, 'numeric');
            app.theta_satEditFieldLabel = uilabel(app.VGmodelPanel);
            app.theta_satEditFieldLabel.Text = 'theta_sat';
            app.theta_satEditField = uieditfield(app.VGmodelPanel, 'numeric');
            app.K_satEditFieldLabel = uilabel(app.VGmodelPanel);
            app.K_satEditFieldLabel.Text = 'K_sat';
            app.K_satEditField = uieditfield(app.VGmodelPanel, 'numeric');
            app.alpha_SWCCEditFieldLabel = uilabel(app.VGmodelPanel);
            app.alpha_SWCCEditFieldLabel.Text = 'alpha_SWCC';
            app.alpha_SWCCEditField = uieditfield(app.VGmodelPanel, 'numeric');
            app.alpha_HCFEditFieldLabel = uilabel(app.VGmodelPanel);
            app.alpha_HCFEditFieldLabel.Text = 'alpha_HCF';
            app.alpha_HCFEditField = uieditfield(app.VGmodelPanel, 'numeric');
            app.n_SWCCEditFieldLabel = uilabel(app.VGmodelPanel);
            app.n_SWCCEditFieldLabel.Text = 'n_SWCC';
            app.n_SWCCEditField = uieditfield(app.VGmodelPanel, 'numeric');
            app.n_HCFEditFieldLabel = uilabel(app.VGmodelPanel);
            app.n_HCFEditFieldLabel.Text = 'n_HCF';
            app.n_HCFEditField = uieditfield(app.VGmodelPanel, 'numeric');
            app.m_SWCCEditFieldLabel = uilabel(app.VGmodelPanel);
            app.m_SWCCEditFieldLabel.Text = 'm_SWCC';
            app.m_SWCCEditField = uieditfield(app.VGmodelPanel, 'numeric');
            app.m_HCFEditFieldLabel = uilabel(app.VGmodelPanel);
            app.m_HCFEditFieldLabel.Text = 'm_HCF';
            app.m_HCFEditField = uieditfield(app.VGmodelPanel, 'numeric');
            app.Image7 = uiimage(app.VGmodelPanel);
            app.Image7.ImageSource = 'VG.png';
            app.BCmodelPanel = uipanel(app.stPanel);
            app.BCmodelPanel.Title = 'BC model';
            app.theta_resEditField_2Label = uilabel(app.BCmodelPanel);
            app.theta_resEditField_2Label.Text = 'theta_res';
            app.theta_resEditField_2 = uieditfield(app.BCmodelPanel, 'numeric');
            app.theta_resEditField_2.Value = 0.08;
            app.theta_satEditField_2Label = uilabel(app.BCmodelPanel);
            app.theta_satEditField_2Label.Text = 'theta_sat';
            app.theta_satEditField_2 = uieditfield(app.BCmodelPanel, 'numeric');
            app.theta_satEditField_2.Value = 0.43;
            app.K_satEditField_2Label = uilabel(app.BCmodelPanel);
            app.K_satEditField_2Label.Text = 'K_sat';
            app.K_satEditField_2 = uieditfield(app.BCmodelPanel, 'numeric');
            app.K_satEditField_2.Value = 5.55e-06;
            app.lambdaEditFieldLabel = uilabel(app.BCmodelPanel);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.BCmodelPanel, 'numeric');
            app.lambdaEditField.Value = 0.53;
            app.hsEditFieldLabel = uilabel(app.BCmodelPanel);
            app.hsEditFieldLabel.Text = 'hs';
            app.hsEditField = uieditfield(app.BCmodelPanel, 'numeric');
            app.hsEditField.Value = -0.226;
            app.Image5 = uiimage(app.BCmodelPanel);
            app.Image5.ImageSource = 'BC.png';
            app.GardnermodelPanel = uipanel(app.stPanel);
            app.GardnermodelPanel.Title = 'Gardner model';
            app.theta_resEditField_3Label = uilabel(app.GardnermodelPanel);
            app.theta_resEditField_3Label.Text = 'theta_res';
            app.theta_resEditField_3 = uieditfield(app.GardnermodelPanel, 'numeric');
            app.theta_satEditField_3Label = uilabel(app.GardnermodelPanel);
            app.theta_satEditField_3Label.Text = 'theta_sat';
            app.theta_satEditField_3 = uieditfield(app.GardnermodelPanel, 'numeric');
            app.theta_satEditField_3.Value = 0.3;
            app.K_satEditField_3Label = uilabel(app.GardnermodelPanel);
            app.K_satEditField_3Label.Text = 'K_sat';
            app.K_satEditField_3 = uieditfield(app.GardnermodelPanel, 'numeric');
            app.K_satEditField_3.Value = 5e-06;
            app.alpha_SWCCEditField_2Label = uilabel(app.GardnermodelPanel);
            app.alpha_SWCCEditField_2Label.Text = 'alpha_SWCC';
            app.alpha_SWCCEditField_2 = uieditfield(app.GardnermodelPanel, 'numeric');
            app.alpha_SWCCEditField_2.Value = 1;
            app.alpha_HCFEditField_2Label = uilabel(app.GardnermodelPanel);
            app.alpha_HCFEditField_2Label.Text = 'alpha_HCF';
            app.alpha_HCFEditField_2 = uieditfield(app.GardnermodelPanel, 'numeric');
            app.alpha_HCFEditField_2.Value = 1;
            app.Image6 = uiimage(app.GardnermodelPanel);
            app.Image6.ImageSource = 'GN.png';
            app.HydraulicmodelDropDownLabel = uilabel(app.stPanel);
            app.HydraulicmodelDropDownLabel.Text = 'Hydraulic model';
            app.HydraulicmodelDropDown = uidropdown(app.stPanel);
            app.HydraulicmodelDropDown.Items = {'Please choose a model', 'Van Genuchten model', 'Gardner model', 'Brooks and Corey model'};
            app.HydraulicmodelDropDown.ValueChangedFcn = createCallbackFcn(app, @HydraulicmodelDropDownValueChanged2, true);
            app.HydraulicmodelDropDown.Value = 'Please choose a model';
            app.Image14 = uiimage(app.stPanel);
            app.Image14.ImageSource = '1_Layer.png';
            app.Image15 = uiimage(app.stPanel);
            app.Image15.ImageSource = '2_Layer_1.png';
            app.Image20 = uiimage(app.stPanel);
            app.Image20.ImageSource = '4_Layer_1.png';
            app.Image17 = uiimage(app.stPanel);
            app.Image17.ImageSource = '3_Layer_1.png';
            app.Image24 = uiimage(app.stPanel);
            app.Image24.ImageSource = '5_Layer_1.png';
            app.ndPanel = uipanel(app.Panel);
            app.ndPanel.Title = '2 nd';
            app.VGmodelPanel_2 = uipanel(app.ndPanel);
            app.VGmodelPanel_2.Title = 'VG model';
            app.theta_resEditField_4Label = uilabel(app.VGmodelPanel_2);
            app.theta_resEditField_4Label.Text = 'theta_res';
            app.theta_resEditField_4 = uieditfield(app.VGmodelPanel_2, 'numeric');
            app.theta_satEditField_4Label = uilabel(app.VGmodelPanel_2);
            app.theta_satEditField_4Label.Text = 'theta_sat';
            app.theta_satEditField_4 = uieditfield(app.VGmodelPanel_2, 'numeric');
            app.K_satEditField_4Label = uilabel(app.VGmodelPanel_2);
            app.K_satEditField_4Label.Text = 'K_sat';
            app.K_satEditField_4 = uieditfield(app.VGmodelPanel_2, 'numeric');
            app.alpha_SWCCEditField_3Label = uilabel(app.VGmodelPanel_2);
            app.alpha_SWCCEditField_3Label.Text = 'alpha_SWCC';
            app.alpha_SWCCEditField_3 = uieditfield(app.VGmodelPanel_2, 'numeric');
            app.alpha_HCFEditField_3Label = uilabel(app.VGmodelPanel_2);
            app.alpha_HCFEditField_3Label.Text = 'alpha_HCF';
            app.alpha_HCFEditField_3 = uieditfield(app.VGmodelPanel_2, 'numeric');
            app.n_SWCCEditField_2Label = uilabel(app.VGmodelPanel_2);
            app.n_SWCCEditField_2Label.Text = 'n_SWCC';
            app.n_SWCCEditField_2 = uieditfield(app.VGmodelPanel_2, 'numeric');
            app.n_HCFEditField_2Label = uilabel(app.VGmodelPanel_2);
            app.n_HCFEditField_2Label.Text = 'n_HCF';
            app.n_HCFEditField_2 = uieditfield(app.VGmodelPanel_2, 'numeric');
            app.m_SWCCEditField_2Label = uilabel(app.VGmodelPanel_2);
            app.m_SWCCEditField_2Label.Text = 'm_SWCC';
            app.m_SWCCEditField_2 = uieditfield(app.VGmodelPanel_2, 'numeric');
            app.m_HCFEditField_2Label = uilabel(app.VGmodelPanel_2);
            app.m_HCFEditField_2Label.Text = 'm_HCF';
            app.m_HCFEditField_2 = uieditfield(app.VGmodelPanel_2, 'numeric');
            app.Image7_2 = uiimage(app.VGmodelPanel_2);
            app.Image7_2.ImageSource = 'VG.png';
            app.BCmodelPanel_2 = uipanel(app.ndPanel);
            app.BCmodelPanel_2.Title = 'BC model';
            app.theta_resEditField_5Label = uilabel(app.BCmodelPanel_2);
            app.theta_resEditField_5Label.Text = 'theta_res';
            app.theta_resEditField_5 = uieditfield(app.BCmodelPanel_2, 'numeric');
            app.theta_resEditField_5.Value = 0.08;
            app.theta_satEditField_5Label = uilabel(app.BCmodelPanel_2);
            app.theta_satEditField_5Label.Text = 'theta_sat';
            app.theta_satEditField_5 = uieditfield(app.BCmodelPanel_2, 'numeric');
            app.theta_satEditField_5.Value = 0.43;
            app.K_satEditField_5Label = uilabel(app.BCmodelPanel_2);
            app.K_satEditField_5Label.Text = 'K_sat';
            app.K_satEditField_5 = uieditfield(app.BCmodelPanel_2, 'numeric');
            app.K_satEditField_5.Value = 5.55e-06;
            app.lambdaEditField_2Label = uilabel(app.BCmodelPanel_2);
            app.lambdaEditField_2Label.Text = 'lambda';
            app.lambdaEditField_2 = uieditfield(app.BCmodelPanel_2, 'numeric');
            app.lambdaEditField_2.Value = 0.53;
            app.hsEditField_2Label = uilabel(app.BCmodelPanel_2);
            app.hsEditField_2Label.Text = 'hs';
            app.hsEditField_2 = uieditfield(app.BCmodelPanel_2, 'numeric');
            app.hsEditField_2.Value = -0.226;
            app.Image5_2 = uiimage(app.BCmodelPanel_2);
            app.Image5_2.ImageSource = 'BC.png';
            app.GardnermodelPanel_2 = uipanel(app.ndPanel);
            app.GardnermodelPanel_2.Title = 'Gardner model';
            app.theta_resEditField_6Label = uilabel(app.GardnermodelPanel_2);
            app.theta_resEditField_6Label.Text = 'theta_res';
            app.theta_resEditField_6 = uieditfield(app.GardnermodelPanel_2, 'numeric');
            app.theta_resEditField_6.Value = 1;
            app.theta_satEditField_6Label = uilabel(app.GardnermodelPanel_2);
            app.theta_satEditField_6Label.Text = 'theta_sat';
            app.theta_satEditField_6 = uieditfield(app.GardnermodelPanel_2, 'numeric');
            app.theta_satEditField_6.Value = 1;
            app.K_satEditField_6Label = uilabel(app.GardnermodelPanel_2);
            app.K_satEditField_6Label.Text = 'K_sat';
            app.K_satEditField_6 = uieditfield(app.GardnermodelPanel_2, 'numeric');
            app.K_satEditField_6.Value = 1;
            app.alpha_SWCCEditField_4Label = uilabel(app.GardnermodelPanel_2);
            app.alpha_SWCCEditField_4Label.Text = 'alpha_SWCC';
            app.alpha_SWCCEditField_4 = uieditfield(app.GardnermodelPanel_2, 'numeric');
            app.alpha_SWCCEditField_4.Value = 1;
            app.alpha_HCFEditField_4Label = uilabel(app.GardnermodelPanel_2);
            app.alpha_HCFEditField_4Label.Text = 'alpha_HCF';
            app.alpha_HCFEditField_4 = uieditfield(app.GardnermodelPanel_2, 'numeric');
            app.alpha_HCFEditField_4.Value = 1;
            app.Image6_2 = uiimage(app.GardnermodelPanel_2);
            app.Image6_2.ImageSource = 'GN.png';
            app.HydraulicmodelDropDown_2Label = uilabel(app.ndPanel);
            app.HydraulicmodelDropDown_2Label.Text = 'Hydraulic model';
            app.HydraulicmodelDropDown_2 = uidropdown(app.ndPanel);
            app.HydraulicmodelDropDown_2.Items = {'Please choose a model', 'Van Genuchten model', 'Gardner model', 'Brooks and Corey model'};
            app.HydraulicmodelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @HydraulicmodelDropDown_2ValueChanged, true);
            app.HydraulicmodelDropDown_2.Value = 'Please choose a model';
            app.Image16 = uiimage(app.ndPanel);
            app.Image16.ImageSource = '2_Layer_2.png';
            app.Image18 = uiimage(app.ndPanel);
            app.Image18.ImageSource = '3_Layer_2.png';
            app.Image21 = uiimage(app.ndPanel);
            app.Image21.ImageSource = '4_Layer_2.png';
            app.Image25 = uiimage(app.ndPanel);
            app.Image25.ImageSource = '5_Layer_2.png';
            app.thPanel = uipanel(app.Panel);
            app.thPanel.Title = '4 th';
            app.VGmodelPanel_4 = uipanel(app.thPanel);
            app.VGmodelPanel_4.Title = 'VG model';
            app.theta_resEditField_10Label = uilabel(app.VGmodelPanel_4);
            app.theta_resEditField_10Label.Text = 'theta_res';
            app.theta_resEditField_10 = uieditfield(app.VGmodelPanel_4, 'numeric');
            app.theta_satEditField_10Label = uilabel(app.VGmodelPanel_4);
            app.theta_satEditField_10Label.Text = 'theta_sat';
            app.theta_satEditField_10 = uieditfield(app.VGmodelPanel_4, 'numeric');
            app.K_satEditField_10Label = uilabel(app.VGmodelPanel_4);
            app.K_satEditField_10Label.Text = 'K_sat';
            app.K_satEditField_10 = uieditfield(app.VGmodelPanel_4, 'numeric');
            app.alpha_SWCCEditField_7Label = uilabel(app.VGmodelPanel_4);
            app.alpha_SWCCEditField_7Label.Text = 'alpha_SWCC';
            app.alpha_SWCCEditField_7 = uieditfield(app.VGmodelPanel_4, 'numeric');
            app.alpha_HCFEditField_7Label = uilabel(app.VGmodelPanel_4);
            app.alpha_HCFEditField_7Label.Text = 'alpha_HCF';
            app.alpha_HCFEditField_7 = uieditfield(app.VGmodelPanel_4, 'numeric');
            app.n_SWCCEditField_4Label = uilabel(app.VGmodelPanel_4);
            app.n_SWCCEditField_4Label.Text = 'n_SWCC';
            app.n_SWCCEditField_4 = uieditfield(app.VGmodelPanel_4, 'numeric');
            app.n_HCFEditField_4Label = uilabel(app.VGmodelPanel_4);
            app.n_HCFEditField_4Label.Text = 'n_HCF';
            app.n_HCFEditField_4 = uieditfield(app.VGmodelPanel_4, 'numeric');
            app.m_SWCCEditField_4Label = uilabel(app.VGmodelPanel_4);
            app.m_SWCCEditField_4Label.Text = 'm_SWCC';
            app.m_SWCCEditField_4 = uieditfield(app.VGmodelPanel_4, 'numeric');
            app.m_HCFEditField_4Label = uilabel(app.VGmodelPanel_4);
            app.m_HCFEditField_4Label.Text = 'm_HCF';
            app.m_HCFEditField_4 = uieditfield(app.VGmodelPanel_4, 'numeric');
            app.Image7_4 = uiimage(app.VGmodelPanel_4);
            app.Image7_4.ImageSource = 'VG.png';
            app.BCmodelPanel_4 = uipanel(app.thPanel);
            app.BCmodelPanel_4.Title = 'BC model';
            app.theta_resEditField_11Label = uilabel(app.BCmodelPanel_4);
            app.theta_resEditField_11Label.Text = 'theta_res';
            app.theta_resEditField_11 = uieditfield(app.BCmodelPanel_4, 'numeric');
            app.theta_resEditField_11.Value = 0.08;
            app.theta_satEditField_11Label = uilabel(app.BCmodelPanel_4);
            app.theta_satEditField_11Label.Text = 'theta_sat';
            app.theta_satEditField_11 = uieditfield(app.BCmodelPanel_4, 'numeric');
            app.theta_satEditField_11.Value = 0.43;
            app.K_satEditField_11Label = uilabel(app.BCmodelPanel_4);
            app.K_satEditField_11Label.Text = 'K_sat';
            app.K_satEditField_11 = uieditfield(app.BCmodelPanel_4, 'numeric');
            app.K_satEditField_11.Value = 5.55e-06;
            app.lambdaEditField_4Label = uilabel(app.BCmodelPanel_4);
            app.lambdaEditField_4Label.Text = 'lambda';
            app.lambdaEditField_4 = uieditfield(app.BCmodelPanel_4, 'numeric');
            app.lambdaEditField_4.Value = 0.53;
            app.hsEditField_4Label = uilabel(app.BCmodelPanel_4);
            app.hsEditField_4Label.Text = 'hs';
            app.hsEditField_4 = uieditfield(app.BCmodelPanel_4, 'numeric');
            app.hsEditField_4.Value = -0.226;
            app.Image5_4 = uiimage(app.BCmodelPanel_4);
            app.Image5_4.ImageSource = 'BC.png';
            app.GardnermodelPanel_4 = uipanel(app.thPanel);
            app.GardnermodelPanel_4.Title = 'Gardner model';
            app.theta_resEditField_12Label = uilabel(app.GardnermodelPanel_4);
            app.theta_resEditField_12Label.Text = 'theta_res';
            app.theta_resEditField_12 = uieditfield(app.GardnermodelPanel_4, 'numeric');
            app.theta_resEditField_12.Value = 1;
            app.theta_satEditField_12Label = uilabel(app.GardnermodelPanel_4);
            app.theta_satEditField_12Label.Text = 'theta_sat';
            app.theta_satEditField_12 = uieditfield(app.GardnermodelPanel_4, 'numeric');
            app.theta_satEditField_12.Value = 1;
            app.K_satEditField_12Label = uilabel(app.GardnermodelPanel_4);
            app.K_satEditField_12Label.Text = 'K_sat';
            app.K_satEditField_12 = uieditfield(app.GardnermodelPanel_4, 'numeric');
            app.K_satEditField_12.Value = 1;
            app.alpha_SWCCEditField_8Label = uilabel(app.GardnermodelPanel_4);
            app.alpha_SWCCEditField_8Label.Text = 'alpha_SWCC';
            app.alpha_SWCCEditField_8 = uieditfield(app.GardnermodelPanel_4, 'numeric');
            app.alpha_SWCCEditField_8.Value = 1;
            app.alpha_HCFEditField_8Label = uilabel(app.GardnermodelPanel_4);
            app.alpha_HCFEditField_8Label.Text = 'alpha_HCF';
            app.alpha_HCFEditField_8 = uieditfield(app.GardnermodelPanel_4, 'numeric');
            app.alpha_HCFEditField_8.Value = 1;
            app.Image6_4 = uiimage(app.GardnermodelPanel_4);
            app.Image6_4.ImageSource = 'GN.png';
            app.HydraulicmodelDropDown_4Label = uilabel(app.thPanel);
            app.HydraulicmodelDropDown_4Label.Text = 'Hydraulic model';
            app.HydraulicmodelDropDown_4 = uidropdown(app.thPanel);
            app.HydraulicmodelDropDown_4.Items = {'Please choose a model', 'Van Genuchten model', 'Gardner model', 'Brooks and Corey model'};
            app.HydraulicmodelDropDown_4.ValueChangedFcn = createCallbackFcn(app, @HydraulicmodelDropDown_4ValueChanged, true);
            app.HydraulicmodelDropDown_4.Value = 'Please choose a model';
            app.Image23 = uiimage(app.thPanel);
            app.Image23.ImageSource = '4_Layer_4.png';
            app.Image27 = uiimage(app.thPanel);
            app.Image27.ImageSource = '5_Layer_4.png';
            app.thPanel_2 = uipanel(app.Panel);
            app.thPanel_2.Title = '5 th';
            app.VGmodelPanel_5 = uipanel(app.thPanel_2);
            app.VGmodelPanel_5.Title = 'VG model';
            app.theta_resEditField_13Label = uilabel(app.VGmodelPanel_5);
            app.theta_resEditField_13Label.Text = 'theta_res';
            app.theta_resEditField_13 = uieditfield(app.VGmodelPanel_5, 'numeric');
            app.theta_satEditField_13Label = uilabel(app.VGmodelPanel_5);
            app.theta_satEditField_13Label.Text = 'theta_sat';
            app.theta_satEditField_13 = uieditfield(app.VGmodelPanel_5, 'numeric');
            app.K_satEditField_13Label = uilabel(app.VGmodelPanel_5);
            app.K_satEditField_13Label.Text = 'K_sat';
            app.K_satEditField_13 = uieditfield(app.VGmodelPanel_5, 'numeric');
            app.alpha_SWCCEditField_9Label = uilabel(app.VGmodelPanel_5);
            app.alpha_SWCCEditField_9Label.Text = 'alpha_SWCC';
            app.alpha_SWCCEditField_9 = uieditfield(app.VGmodelPanel_5, 'numeric');
            app.alpha_HCFEditField_9Label = uilabel(app.VGmodelPanel_5);
            app.alpha_HCFEditField_9Label.Text = 'alpha_HCF';
            app.alpha_HCFEditField_9 = uieditfield(app.VGmodelPanel_5, 'numeric');
            app.n_SWCCEditField_5Label = uilabel(app.VGmodelPanel_5);
            app.n_SWCCEditField_5Label.Text = 'n_SWCC';
            app.n_SWCCEditField_5 = uieditfield(app.VGmodelPanel_5, 'numeric');
            app.n_HCFEditField_5Label = uilabel(app.VGmodelPanel_5);
            app.n_HCFEditField_5Label.Text = 'n_HCF';
            app.n_HCFEditField_5 = uieditfield(app.VGmodelPanel_5, 'numeric');
            app.m_SWCCEditField_5Label = uilabel(app.VGmodelPanel_5);
            app.m_SWCCEditField_5Label.Text = 'm_SWCC';
            app.m_SWCCEditField_5 = uieditfield(app.VGmodelPanel_5, 'numeric');
            app.m_HCFEditField_5Label = uilabel(app.VGmodelPanel_5);
            app.m_HCFEditField_5Label.Text = 'm_HCF';
            app.m_HCFEditField_5 = uieditfield(app.VGmodelPanel_5, 'numeric');
            app.Image7_5 = uiimage(app.VGmodelPanel_5);
            app.Image7_5.ImageSource = 'VG.png';
            app.BCmodelPanel_5 = uipanel(app.thPanel_2);
            app.BCmodelPanel_5.Title = 'BC model';
            app.theta_resEditField_14Label = uilabel(app.BCmodelPanel_5);
            app.theta_resEditField_14Label.Text = 'theta_res';
            app.theta_resEditField_14 = uieditfield(app.BCmodelPanel_5, 'numeric');
            app.theta_resEditField_14.Value = 0.08;
            app.theta_satEditField_14Label = uilabel(app.BCmodelPanel_5);
            app.theta_satEditField_14Label.Text = 'theta_sat';
            app.theta_satEditField_14 = uieditfield(app.BCmodelPanel_5, 'numeric');
            app.theta_satEditField_14.Value = 0.43;
            app.K_satEditField_14Label = uilabel(app.BCmodelPanel_5);
            app.K_satEditField_14Label.Text = 'K_sat';
            app.K_satEditField_14 = uieditfield(app.BCmodelPanel_5, 'numeric');
            app.K_satEditField_14.Value = 5.55e-06;
            app.lambdaEditField_5Label = uilabel(app.BCmodelPanel_5);
            app.lambdaEditField_5Label.Text = 'lambda';
            app.lambdaEditField_5 = uieditfield(app.BCmodelPanel_5, 'numeric');
            app.lambdaEditField_5.Value = 0.53;
            app.hsEditField_5Label = uilabel(app.BCmodelPanel_5);
            app.hsEditField_5Label.Text = 'hs';
            app.hsEditField_5 = uieditfield(app.BCmodelPanel_5, 'numeric');
            app.hsEditField_5.Value = -0.226;
            app.Image5_5 = uiimage(app.BCmodelPanel_5);
            app.Image5_5.ImageSource = 'BC.png';
            app.GardnermodelPanel_5 = uipanel(app.thPanel_2);
            app.GardnermodelPanel_5.Title = 'Gardner model';
            app.theta_resEditField_15Label = uilabel(app.GardnermodelPanel_5);
            app.theta_resEditField_15Label.Text = 'theta_res';
            app.theta_resEditField_15 = uieditfield(app.GardnermodelPanel_5, 'numeric');
            app.theta_resEditField_15.Value = 1;
            app.theta_satEditField_15Label = uilabel(app.GardnermodelPanel_5);
            app.theta_satEditField_15Label.Text = 'theta_sat';
            app.theta_satEditField_15 = uieditfield(app.GardnermodelPanel_5, 'numeric');
            app.theta_satEditField_15.Value = 1;
            app.K_satEditField_15Label = uilabel(app.GardnermodelPanel_5);
            app.K_satEditField_15Label.Text = 'K_sat';
            app.K_satEditField_15 = uieditfield(app.GardnermodelPanel_5, 'numeric');
            app.K_satEditField_15.Value = 1;
            app.alpha_SWCCEditField_10Label = uilabel(app.GardnermodelPanel_5);
            app.alpha_SWCCEditField_10Label.Text = 'alpha_SWCC';
            app.alpha_SWCCEditField_10 = uieditfield(app.GardnermodelPanel_5, 'numeric');
            app.alpha_SWCCEditField_10.Value = 1;
            app.alpha_HCFEditField_10Label = uilabel(app.GardnermodelPanel_5);
            app.alpha_HCFEditField_10Label.Text = 'alpha_HCF';
            app.alpha_HCFEditField_10 = uieditfield(app.GardnermodelPanel_5, 'numeric');
            app.alpha_HCFEditField_10.Value = 1;
            app.Image6_5 = uiimage(app.GardnermodelPanel_5);
            app.Image6_5.ImageSource = 'GN.png';
            app.HydraulicmodelDropDown_5Label = uilabel(app.thPanel_2);
            app.HydraulicmodelDropDown_5Label.Text = 'Hydraulic model';
            app.HydraulicmodelDropDown_5 = uidropdown(app.thPanel_2);
            app.HydraulicmodelDropDown_5.Items = {'Please choose a model', 'Van Genuchten model', 'Gardner model', 'Brooks and Corey model'};
            app.HydraulicmodelDropDown_5.ValueChangedFcn = createCallbackFcn(app, @HydraulicmodelDropDown_5ValueChanged, true);
            app.HydraulicmodelDropDown_5.Value = 'Please choose a model';
            app.Image28 = uiimage(app.thPanel_2);
            app.Image28.ImageSource = '5_Layer_5.png';
            app.rdPanel = uipanel(app.Panel);
            app.rdPanel.Title = '3 rd';
            app.VGmodelPanel_3 = uipanel(app.rdPanel);
            app.VGmodelPanel_3.Title = 'VG model';
            app.theta_resEditField_7Label = uilabel(app.VGmodelPanel_3);
            app.theta_resEditField_7Label.Text = 'theta_res';
            app.theta_resEditField_7 = uieditfield(app.VGmodelPanel_3, 'numeric');
            app.theta_satEditField_7Label = uilabel(app.VGmodelPanel_3);
            app.theta_satEditField_7Label.Text = 'theta_sat';
            app.theta_satEditField_7 = uieditfield(app.VGmodelPanel_3, 'numeric');
            app.K_satEditField_7Label = uilabel(app.VGmodelPanel_3);
            app.K_satEditField_7Label.Text = 'K_sat';
            app.K_satEditField_7 = uieditfield(app.VGmodelPanel_3, 'numeric');
            app.alpha_SWCCEditField_5Label = uilabel(app.VGmodelPanel_3);
            app.alpha_SWCCEditField_5Label.Text = 'alpha_SWCC';
            app.alpha_SWCCEditField_5 = uieditfield(app.VGmodelPanel_3, 'numeric');
            app.alpha_HCFEditField_5Label = uilabel(app.VGmodelPanel_3);
            app.alpha_HCFEditField_5Label.Text = 'alpha_HCF';
            app.alpha_HCFEditField_5 = uieditfield(app.VGmodelPanel_3, 'numeric');
            app.n_SWCCEditField_3Label = uilabel(app.VGmodelPanel_3);
            app.n_SWCCEditField_3Label.Text = 'n_SWCC';
            app.n_SWCCEditField_3 = uieditfield(app.VGmodelPanel_3, 'numeric');
            app.n_HCFEditField_3Label = uilabel(app.VGmodelPanel_3);
            app.n_HCFEditField_3Label.Text = 'n_HCF';
            app.n_HCFEditField_3 = uieditfield(app.VGmodelPanel_3, 'numeric');
            app.m_SWCCEditField_3Label = uilabel(app.VGmodelPanel_3);
            app.m_SWCCEditField_3Label.Text = 'm_SWCC';
            app.m_SWCCEditField_3 = uieditfield(app.VGmodelPanel_3, 'numeric');
            app.m_HCFEditField_3Label = uilabel(app.VGmodelPanel_3);
            app.m_HCFEditField_3Label.Text = 'm_HCF';
            app.m_HCFEditField_3 = uieditfield(app.VGmodelPanel_3, 'numeric');
            app.Image7_3 = uiimage(app.VGmodelPanel_3);
            app.Image7_3.ImageSource = 'VG.png';
            app.BCmodelPanel_3 = uipanel(app.rdPanel);
            app.BCmodelPanel_3.Title = 'BC model';
            app.theta_resEditField_8Label = uilabel(app.BCmodelPanel_3);
            app.theta_resEditField_8Label.Text = 'theta_res';
            app.theta_resEditField_8 = uieditfield(app.BCmodelPanel_3, 'numeric');
            app.theta_resEditField_8.Value = 0.08;
            app.theta_satEditField_8Label = uilabel(app.BCmodelPanel_3);
            app.theta_satEditField_8Label.Text = 'theta_sat';
            app.theta_satEditField_8 = uieditfield(app.BCmodelPanel_3, 'numeric');
            app.theta_satEditField_8.Value = 0.43;
            app.K_satEditField_8Label = uilabel(app.BCmodelPanel_3);
            app.K_satEditField_8Label.Text = 'K_sat';
            app.K_satEditField_8 = uieditfield(app.BCmodelPanel_3, 'numeric');
            app.K_satEditField_8.Value = 5.55e-06;
            app.lambdaEditField_3Label = uilabel(app.BCmodelPanel_3);
            app.lambdaEditField_3Label.Text = 'lambda';
            app.lambdaEditField_3 = uieditfield(app.BCmodelPanel_3, 'numeric');
            app.lambdaEditField_3.Value = 0.53;
            app.hsEditField_3Label = uilabel(app.BCmodelPanel_3);
            app.hsEditField_3Label.Text = 'hs';
            app.hsEditField_3 = uieditfield(app.BCmodelPanel_3, 'numeric');
            app.hsEditField_3.Value = -0.226;
            app.Image5_3 = uiimage(app.BCmodelPanel_3);
            app.Image5_3.ImageSource = 'BC.png';
            app.GardnermodelPanel_3 = uipanel(app.rdPanel);
            app.GardnermodelPanel_3.Title = 'Gardner model';
            app.theta_resEditField_9Label = uilabel(app.GardnermodelPanel_3);
            app.theta_resEditField_9Label.Text = 'theta_res';
            app.theta_resEditField_9 = uieditfield(app.GardnermodelPanel_3, 'numeric');
            app.theta_resEditField_9.Value = 1;
            app.theta_satEditField_9Label = uilabel(app.GardnermodelPanel_3);
            app.theta_satEditField_9Label.Text = 'theta_sat';
            app.theta_satEditField_9 = uieditfield(app.GardnermodelPanel_3, 'numeric');
            app.theta_satEditField_9.Value = 1;
            app.K_satEditField_9Label = uilabel(app.GardnermodelPanel_3);
            app.K_satEditField_9Label.Text = 'K_sat';
            app.K_satEditField_9 = uieditfield(app.GardnermodelPanel_3, 'numeric');
            app.K_satEditField_9.Value = 1;
            app.alpha_SWCCEditField_6Label = uilabel(app.GardnermodelPanel_3);
            app.alpha_SWCCEditField_6Label.Text = 'alpha_SWCC';
            app.alpha_SWCCEditField_6 = uieditfield(app.GardnermodelPanel_3, 'numeric');
            app.alpha_SWCCEditField_6.Value = 1;
            app.alpha_HCFEditField_6Label = uilabel(app.GardnermodelPanel_3);
            app.alpha_HCFEditField_6Label.Text = 'alpha_HCF';
            app.alpha_HCFEditField_6 = uieditfield(app.GardnermodelPanel_3, 'numeric');
            app.alpha_HCFEditField_6.Value = 1;
            app.Image6_3 = uiimage(app.GardnermodelPanel_3);
            app.Image6_3.ImageSource = 'GN.png';
            app.HydraulicmodelDropDown_3Label = uilabel(app.rdPanel);
            app.HydraulicmodelDropDown_3Label.Text = 'Hydraulic model';
            app.HydraulicmodelDropDown_3 = uidropdown(app.rdPanel);
            app.HydraulicmodelDropDown_3.Items = {'Please choose a model', 'Van Genuchten model', 'Gardner model', 'Brooks and Corey model'};
            app.HydraulicmodelDropDown_3.ValueChangedFcn = createCallbackFcn(app, @HydraulicmodelDropDown_3ValueChanged, true);
            app.HydraulicmodelDropDown_3.Value = 'Please choose a model';
            app.Image19 = uiimage(app.rdPanel);
            app.Image19.ImageSource = '3_Layer_3.png';
            app.Image26 = uiimage(app.rdPanel);
            app.Image26.ImageSource = '5_Layer_3.png';
            app.Image22 = uiimage(app.rdPanel);
            app.Image22.ImageSource = '4_Layer_3.png';
            app.AssignpropertytolayersButtonGroup = uibuttongroup(app.Panel);
            app.AssignpropertytolayersButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AssignpropertytolayersButtonGroupSelectionChanged, true);
            app.AssignpropertytolayersButtonGroup.Title = 'Assign property to layers';
            app.Layer1Button = uiradiobutton(app.AssignpropertytolayersButtonGroup);
            app.Layer1Button.Text = 'Layer 1';
            app.Layer1Button.Value = true;
            app.Layer2Button = uiradiobutton(app.AssignpropertytolayersButtonGroup);
            app.Layer2Button.Text = 'Layer 2';
            app.Layer3Button = uiradiobutton(app.AssignpropertytolayersButtonGroup);
            app.Layer3Button.Text = 'Layer 3';
            app.Layer4Button = uiradiobutton(app.AssignpropertytolayersButtonGroup);
            app.Layer4Button.Text = 'Layer 4';
            app.Layer5Button = uiradiobutton(app.AssignpropertytolayersButtonGroup);
            app.Layer5Button.Text = 'Layer 5';
            app.RootWaterUptakeModelPanel = uipanel(app.Panel);
            app.RootWaterUptakeModelPanel.Title = 'Root Water Uptake Model';
            app.h1EditFieldLabel = uilabel(app.RootWaterUptakeModelPanel);
            app.h1EditFieldLabel.Text = 'h1';
            app.h1EditField = uieditfield(app.RootWaterUptakeModelPanel, 'numeric');
            app.h1EditField.Value = -0.034;
            app.h2EditFieldLabel = uilabel(app.RootWaterUptakeModelPanel);
            app.h2EditFieldLabel.Text = 'h2';
            app.h2EditField = uieditfield(app.RootWaterUptakeModelPanel, 'numeric');
            app.h2EditField.Value = -1.79;
            app.h3EditFieldLabel = uilabel(app.RootWaterUptakeModelPanel);
            app.h3EditFieldLabel.Text = 'h3';
            app.h3EditField = uieditfield(app.RootWaterUptakeModelPanel, 'numeric');
            app.h3EditField.Value = -2.51;
            app.h4EditFieldLabel = uilabel(app.RootWaterUptakeModelPanel);
            app.h4EditFieldLabel.Text = 'h4';
            app.h4EditField = uieditfield(app.RootWaterUptakeModelPanel, 'numeric');
            app.h4EditField.Value = -10;
            app.d_rEditFieldLabel = uilabel(app.RootWaterUptakeModelPanel);
            app.d_rEditFieldLabel.Text = 'd_r';
            app.d_rEditField = uieditfield(app.RootWaterUptakeModelPanel, 'numeric');
            app.d_rEditField.Value = 0.5;
            app.T_pEditFieldLabel = uilabel(app.RootWaterUptakeModelPanel);
            app.T_pEditFieldLabel.Text = 'T_p';
            app.T_pEditField = uieditfield(app.RootWaterUptakeModelPanel, 'numeric');
            app.T_pEditField.Value = 5.78704e-07;
            app.Image13 = uiimage(app.RootWaterUptakeModelPanel);
            app.Image13.ImageSource = 'Sink_term.png';
            app.prescribedwaterheadLabel = uilabel(app.Panel);
            app.prescribedwaterheadLabel.Text = '*prescribed water head ';
            app.Thresholdvaluehw2EditFieldLabel = uilabel(app.Panel);
            app.Thresholdvaluehw2EditFieldLabel.Text = 'Threshold value,hw2';
            app.Thresholdvaluehw2EditField = uieditfield(app.Panel, 'numeric');
            app.flowrate = uiswitch(app.Panel, 'slider');
            app.flowrate.ValueChangedFcn = createCallbackFcn(app, @flowrateValueChanged2, true);
            app.CouplingSwitchLabel_4 = uilabel(app.Panel);
            app.CouplingSwitchLabel_4.Text = 'Flow rate';
            app.MechanicsTab = uitab(app.TabGroup2);
            app.MechanicsTab.Title = 'Mechanics';
            app.Panel_10 = uipanel(app.MechanicsTab);
            app.AssignpropertytolayersButtonGroup_2 = uibuttongroup(app.Panel_10);
            app.AssignpropertytolayersButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @AssignpropertytolayersButtonGroup_2SelectionChanged, true);
            app.AssignpropertytolayersButtonGroup_2.Title = 'Assign property to layers';
            app.Layer1Button_2 = uiradiobutton(app.AssignpropertytolayersButtonGroup_2);
            app.Layer1Button_2.Text = 'Layer 1';
            app.Layer1Button_2.Value = true;
            app.Layer2Button_2 = uiradiobutton(app.AssignpropertytolayersButtonGroup_2);
            app.Layer2Button_2.Text = 'Layer 2';
            app.Layer3Button_2 = uiradiobutton(app.AssignpropertytolayersButtonGroup_2);
            app.Layer3Button_2.Text = 'Layer 3';
            app.Layer4Button_2 = uiradiobutton(app.AssignpropertytolayersButtonGroup_2);
            app.Layer4Button_2.Text = 'Layer 4';
            app.Layer5Button_2 = uiradiobutton(app.AssignpropertytolayersButtonGroup_2);
            app.Layer5Button_2.Text = 'Layer 5';
            app.Image14_2 = uiimage(app.Panel_10);
            app.Image14_2.ImageSource = '1_Layer.png';
            app.Image15_2 = uiimage(app.Panel_10);
            app.Image15_2.ImageSource = '2_Layer_1.png';
            app.Image16_2 = uiimage(app.Panel_10);
            app.Image16_2.ImageSource = '2_Layer_2.png';
            app.Image17_2 = uiimage(app.Panel_10);
            app.Image17_2.ImageSource = '3_Layer_1.png';
            app.Image18_2 = uiimage(app.Panel_10);
            app.Image18_2.ImageSource = '3_Layer_2.png';
            app.Image19_2 = uiimage(app.Panel_10);
            app.Image19_2.ImageSource = '3_Layer_3.png';
            app.Image20_2 = uiimage(app.Panel_10);
            app.Image20_2.ImageSource = '4_Layer_1.png';
            app.Image21_2 = uiimage(app.Panel_10);
            app.Image21_2.ImageSource = '4_Layer_2.png';
            app.Image22_2 = uiimage(app.Panel_10);
            app.Image22_2.ImageSource = '4_Layer_3.png';
            app.Image23_2 = uiimage(app.Panel_10);
            app.Image23_2.ImageSource = '4_Layer_4.png';
            app.Image24_2 = uiimage(app.Panel_10);
            app.Image24_2.ImageSource = '5_Layer_1.png';
            app.Image25_2 = uiimage(app.Panel_10);
            app.Image25_2.ImageSource = '5_Layer_2.png';
            app.Image26_2 = uiimage(app.Panel_10);
            app.Image26_2.ImageSource = '5_Layer_3.png';
            app.Image27_2 = uiimage(app.Panel_10);
            app.Image27_2.ImageSource = '5_Layer_4.png';
            app.Image28_2 = uiimage(app.Panel_10);
            app.Image28_2.ImageSource = '5_Layer_5.png';
            app.stPanel_2 = uipanel(app.Panel_10);
            app.stPanel_2.Title = '1 st';
            app.StressapproachPanel = uipanel(app.stPanel_2);
            app.StressapproachPanel.Title = 'Stress approach';
            app.Switch = uiswitch(app.StressapproachPanel, 'slider');
            app.Switch.Items = {'Effective Stress', 'Independent Variables'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Effective Stress';
            app.EffectivestressparameterButtonGroup = uibuttongroup(app.StressapproachPanel);
            app.EffectivestressparameterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EffectivestressparameterButtonGroupSelectionChanged, true);
            app.EffectivestressparameterButtonGroup.Title = 'Effective stress parameter, χ';
            app.Option1Button = uiradiobutton(app.EffectivestressparameterButtonGroup);
            app.Option1Button.Text = 'Option 1';
            app.Option1Button.Value = true;
            app.Option2Button = uiradiobutton(app.EffectivestressparameterButtonGroup);
            app.Option2Button.Text = 'Option 2';
            app.Option3Button = uiradiobutton(app.EffectivestressparameterButtonGroup);
            app.Option3Button.Text = 'Option 3';
            app.EditField_12 = uieditfield(app.EffectivestressparameterButtonGroup, 'numeric');
            app.Image29 = uiimage(app.StressapproachPanel);
            app.Image29.ImageSource = 'effectivestress.png';
            app.AirentrysuctionSeEditFieldLabel = uilabel(app.StressapproachPanel);
            app.AirentrysuctionSeEditFieldLabel.Text = 'Air entry suction, Se';
            app.AirentrysuctionSeEditField = uieditfield(app.StressapproachPanel, 'numeric');
            app.PaLabel_4 = uilabel(app.StressapproachPanel);
            app.PaLabel_4.Text = '(Pa)';
            app.ElasticmodulusPanel = uipanel(app.stPanel_2);
            app.ElasticmodulusPanel.Title = 'Elastic modulus';
            app.EEditFieldLabel = uilabel(app.ElasticmodulusPanel);
            app.EEditFieldLabel.Text = 'E';
            app.EEditField = uieditfield(app.ElasticmodulusPanel, 'numeric');
            app.GEditFieldLabel = uilabel(app.ElasticmodulusPanel);
            app.GEditFieldLabel.Text = 'G';
            app.GEditField = uieditfield(app.ElasticmodulusPanel, 'numeric');
            app.FEditFieldLabel = uilabel(app.ElasticmodulusPanel);
            app.FEditFieldLabel.Text = 'F';
            app.FEditField = uieditfield(app.ElasticmodulusPanel, 'numeric');
            app.PaLabel = uilabel(app.ElasticmodulusPanel);
            app.PaLabel.Text = '(Pa)';
            app.PaLabel_2 = uilabel(app.ElasticmodulusPanel);
            app.PaLabel_2.Text = '(Pa)';
            app.PaLabel_3 = uilabel(app.ElasticmodulusPanel);
            app.PaLabel_3.Text = '(Pa)';
            app.StrengthparametersPanel = uipanel(app.stPanel_2);
            app.StrengthparametersPanel.Title = 'Strength parameters';
            app.cLabel = uilabel(app.StrengthparametersPanel);
            app.cLabel.Text = 'c''';
            app.cEditField = uieditfield(app.StrengthparametersPanel, 'numeric');
            app.Label = uilabel(app.StrengthparametersPanel);
            app.Label.Text = 'φ''';
            app.EditField_7 = uieditfield(app.StrengthparametersPanel, 'numeric');
            app.bEditFieldLabel = uilabel(app.StrengthparametersPanel);
            app.bEditFieldLabel.Text = 'φ_b';
            app.bEditField = uieditfield(app.StrengthparametersPanel, 'numeric');
            app.PaLabel_8 = uilabel(app.StrengthparametersPanel);
            app.PaLabel_8.Text = '(Pa)';
            app.Label_2 = uilabel(app.StrengthparametersPanel);
            app.Label_2.Text = '(°)';
            app.Label_3 = uilabel(app.StrengthparametersPanel);
            app.Label_3.Text = '(°)';
            app.UnitweightofsoilPanel = uipanel(app.stPanel_2);
            app.UnitweightofsoilPanel.Title = 'Unit weight of soil  ';
            app.sNm3EditFieldLabel = uilabel(app.UnitweightofsoilPanel);
            app.sNm3EditFieldLabel.Text = 'γ_s (N/m^3)';
            app.sNm3EditField = uieditfield(app.UnitweightofsoilPanel, 'numeric');
            app.ndPanel_2 = uipanel(app.Panel_10);
            app.ndPanel_2.Title = '2 nd';
            app.StressapproachPanel_2 = uipanel(app.ndPanel_2);
            app.StressapproachPanel_2.Title = 'Stress approach';
            app.Switch_2 = uiswitch(app.StressapproachPanel_2, 'slider');
            app.Switch_2.Items = {'Effective Stress', 'Independent Variables'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Effective Stress';
            app.EffectivestressparameterButtonGroup_2 = uibuttongroup(app.StressapproachPanel_2);
            app.EffectivestressparameterButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @EffectivestressparameterButtonGroup_2SelectionChanged, true);
            app.EffectivestressparameterButtonGroup_2.Title = 'Effective stress parameter, χ';
            app.Option1Button_2 = uiradiobutton(app.EffectivestressparameterButtonGroup_2);
            app.Option1Button_2.Text = 'Option 1';
            app.Option1Button_2.Value = true;
            app.Option2Button_2 = uiradiobutton(app.EffectivestressparameterButtonGroup_2);
            app.Option2Button_2.Text = 'Option 2';
            app.Option3Button_2 = uiradiobutton(app.EffectivestressparameterButtonGroup_2);
            app.Option3Button_2.Text = 'Option 3';
            app.EditField_13 = uieditfield(app.EffectivestressparameterButtonGroup_2, 'numeric');
            app.Image29_2 = uiimage(app.StressapproachPanel_2);
            app.Image29_2.ImageSource = 'effectivestress.png';
            app.AirentrysuctionSeEditField_2Label = uilabel(app.StressapproachPanel_2);
            app.AirentrysuctionSeEditField_2Label.Text = 'Air entry suction, Se';
            app.AirentrysuctionSeEditField_2 = uieditfield(app.StressapproachPanel_2, 'numeric');
            app.PaLabel_21 = uilabel(app.StressapproachPanel_2);
            app.PaLabel_21.Text = '(Pa)';
            app.ElasticmodulusPanel_2 = uipanel(app.ndPanel_2);
            app.ElasticmodulusPanel_2.Title = 'Elastic modulus';
            app.EEditField_2Label = uilabel(app.ElasticmodulusPanel_2);
            app.EEditField_2Label.Text = 'E';
            app.EEditField_2 = uieditfield(app.ElasticmodulusPanel_2, 'numeric');
            app.GEditField_2Label = uilabel(app.ElasticmodulusPanel_2);
            app.GEditField_2Label.Text = 'G';
            app.GEditField_2 = uieditfield(app.ElasticmodulusPanel_2, 'numeric');
            app.FEditField_2Label = uilabel(app.ElasticmodulusPanel_2);
            app.FEditField_2Label.Text = 'F';
            app.FEditField_2 = uieditfield(app.ElasticmodulusPanel_2, 'numeric');
            app.PaLabel_12 = uilabel(app.ElasticmodulusPanel_2);
            app.PaLabel_12.Text = '(Pa)';
            app.PaLabel_13 = uilabel(app.ElasticmodulusPanel_2);
            app.PaLabel_13.Text = '(Pa)';
            app.PaLabel_14 = uilabel(app.ElasticmodulusPanel_2);
            app.PaLabel_14.Text = '(Pa)';
            app.StrengthparametersPanel_2 = uipanel(app.ndPanel_2);
            app.StrengthparametersPanel_2.Title = 'Strength parameters';
            app.cEditField_2Label = uilabel(app.StrengthparametersPanel_2);
            app.cEditField_2Label.Text = 'c''';
            app.cEditField_2 = uieditfield(app.StrengthparametersPanel_2, 'numeric');
            app.EditField_8Label = uilabel(app.StrengthparametersPanel_2);
            app.EditField_8Label.Text = 'φ''';
            app.EditField_8 = uieditfield(app.StrengthparametersPanel_2, 'numeric');
            app.bEditField_2Label = uilabel(app.StrengthparametersPanel_2);
            app.bEditField_2Label.Text = 'φ_b';
            app.bEditField_2 = uieditfield(app.StrengthparametersPanel_2, 'numeric');
            app.PaLabel_25 = uilabel(app.StrengthparametersPanel_2);
            app.PaLabel_25.Text = '(Pa)';
            app.Label_4 = uilabel(app.StrengthparametersPanel_2);
            app.Label_4.Text = '(°)';
            app.Label_5 = uilabel(app.StrengthparametersPanel_2);
            app.Label_5.Text = '(°)';
            app.UnitweightofsoilPanel_2 = uipanel(app.ndPanel_2);
            app.UnitweightofsoilPanel_2.Title = 'Unit weight of soil  ';
            app.sNm3EditField_2Label = uilabel(app.UnitweightofsoilPanel_2);
            app.sNm3EditField_2Label.Text = 'γ_s (N/m^3)';
            app.sNm3EditField_2 = uieditfield(app.UnitweightofsoilPanel_2, 'numeric');
            app.rdPanel_2 = uipanel(app.Panel_10);
            app.rdPanel_2.Title = '3 rd';
            app.StressapproachPanel_3 = uipanel(app.rdPanel_2);
            app.StressapproachPanel_3.Title = 'Stress approach';
            app.Switch_3 = uiswitch(app.StressapproachPanel_3, 'slider');
            app.Switch_3.Items = {'Effective Stress', 'Independent Variables'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = 'Effective Stress';
            app.EffectivestressparameterButtonGroup_3 = uibuttongroup(app.StressapproachPanel_3);
            app.EffectivestressparameterButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @EffectivestressparameterButtonGroup_3SelectionChanged, true);
            app.EffectivestressparameterButtonGroup_3.Title = 'Effective stress parameter, χ';
            app.Option1Button_3 = uiradiobutton(app.EffectivestressparameterButtonGroup_3);
            app.Option1Button_3.Text = 'Option 1';
            app.Option1Button_3.Value = true;
            app.Option2Button_3 = uiradiobutton(app.EffectivestressparameterButtonGroup_3);
            app.Option2Button_3.Text = 'Option 2';
            app.Option3Button_3 = uiradiobutton(app.EffectivestressparameterButtonGroup_3);
            app.Option3Button_3.Text = 'Option 3';
            app.EditField_14 = uieditfield(app.EffectivestressparameterButtonGroup_3, 'numeric');
            app.Image29_3 = uiimage(app.StressapproachPanel_3);
            app.Image29_3.ImageSource = 'effectivestress.png';
            app.AirentrysuctionSeEditField_3Label = uilabel(app.StressapproachPanel_3);
            app.AirentrysuctionSeEditField_3Label.Text = 'Air entry suction, Se';
            app.AirentrysuctionSeEditField_3 = uieditfield(app.StressapproachPanel_3, 'numeric');
            app.PaLabel_22 = uilabel(app.StressapproachPanel_3);
            app.PaLabel_22.Text = '(Pa)';
            app.ElasticmodulusPanel_3 = uipanel(app.rdPanel_2);
            app.ElasticmodulusPanel_3.Title = 'Elastic modulus';
            app.EEditField_3Label = uilabel(app.ElasticmodulusPanel_3);
            app.EEditField_3Label.Text = 'E';
            app.EEditField_3 = uieditfield(app.ElasticmodulusPanel_3, 'numeric');
            app.GEditField_3Label = uilabel(app.ElasticmodulusPanel_3);
            app.GEditField_3Label.Text = 'G';
            app.GEditField_3 = uieditfield(app.ElasticmodulusPanel_3, 'numeric');
            app.FEditField_3Label = uilabel(app.ElasticmodulusPanel_3);
            app.FEditField_3Label.Text = 'F';
            app.FEditField_3 = uieditfield(app.ElasticmodulusPanel_3, 'numeric');
            app.PaLabel_15 = uilabel(app.ElasticmodulusPanel_3);
            app.PaLabel_15.Text = '(Pa)';
            app.PaLabel_16 = uilabel(app.ElasticmodulusPanel_3);
            app.PaLabel_16.Text = '(Pa)';
            app.PaLabel_17 = uilabel(app.ElasticmodulusPanel_3);
            app.PaLabel_17.Text = '(Pa)';
            app.StrengthparametersPanel_3 = uipanel(app.rdPanel_2);
            app.StrengthparametersPanel_3.Title = 'Strength parameters';
            app.cEditField_3Label = uilabel(app.StrengthparametersPanel_3);
            app.cEditField_3Label.Text = 'c''';
            app.cEditField_3 = uieditfield(app.StrengthparametersPanel_3, 'numeric');
            app.EditField_9Label = uilabel(app.StrengthparametersPanel_3);
            app.EditField_9Label.Text = 'φ''';
            app.EditField_9 = uieditfield(app.StrengthparametersPanel_3, 'numeric');
            app.bEditField_3Label = uilabel(app.StrengthparametersPanel_3);
            app.bEditField_3Label.Text = 'φ_b';
            app.bEditField_3 = uieditfield(app.StrengthparametersPanel_3, 'numeric');
            app.PaLabel_26 = uilabel(app.StrengthparametersPanel_3);
            app.PaLabel_26.Text = '(Pa)';
            app.Label_6 = uilabel(app.StrengthparametersPanel_3);
            app.Label_6.Text = '(°)';
            app.Label_7 = uilabel(app.StrengthparametersPanel_3);
            app.Label_7.Text = '(°)';
            app.UnitweightofsoilPanel_3 = uipanel(app.rdPanel_2);
            app.UnitweightofsoilPanel_3.Title = 'Unit weight of soil ';
            app.sNm3EditField_3Label = uilabel(app.UnitweightofsoilPanel_3);
            app.sNm3EditField_3Label.Text = 'γ_s (N/m^3)';
            app.sNm3EditField_3 = uieditfield(app.UnitweightofsoilPanel_3, 'numeric');
            app.thPanel_3 = uipanel(app.Panel_10);
            app.thPanel_3.Title = '4 th';
            app.StressapproachPanel_4 = uipanel(app.thPanel_3);
            app.StressapproachPanel_4.Title = 'Stress approach';
            app.Switch_4 = uiswitch(app.StressapproachPanel_4, 'slider');
            app.Switch_4.Items = {'Effective Stress', 'Independent Variables'};
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Switch_4.Value = 'Effective Stress';
            app.EffectivestressparameterButtonGroup_4 = uibuttongroup(app.StressapproachPanel_4);
            app.EffectivestressparameterButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @EffectivestressparameterButtonGroup_4SelectionChanged, true);
            app.EffectivestressparameterButtonGroup_4.Title = 'Effective stress parameter, χ';
            app.Option1Button_4 = uiradiobutton(app.EffectivestressparameterButtonGroup_4);
            app.Option1Button_4.Text = 'Option 1';
            app.Option1Button_4.Value = true;
            app.Option2Button_4 = uiradiobutton(app.EffectivestressparameterButtonGroup_4);
            app.Option2Button_4.Text = 'Option 2';
            app.Option3Button_4 = uiradiobutton(app.EffectivestressparameterButtonGroup_4);
            app.Option3Button_4.Text = 'Option 3';
            app.EditField_15 = uieditfield(app.EffectivestressparameterButtonGroup_4, 'numeric');
            app.Image29_4 = uiimage(app.StressapproachPanel_4);
            app.Image29_4.ImageSource = 'effectivestress.png';
            app.AirentrysuctionSeEditField_4Label = uilabel(app.StressapproachPanel_4);
            app.AirentrysuctionSeEditField_4Label.Text = 'Air entry suction, Se';
            app.AirentrysuctionSeEditField_4 = uieditfield(app.StressapproachPanel_4, 'numeric');
            app.PaLabel_23 = uilabel(app.StressapproachPanel_4);
            app.PaLabel_23.Text = '(Pa)';
            app.ElasticmodulusPanel_4 = uipanel(app.thPanel_3);
            app.ElasticmodulusPanel_4.Title = 'Elastic modulus';
            app.EEditField_4Label = uilabel(app.ElasticmodulusPanel_4);
            app.EEditField_4Label.Text = 'E';
            app.EEditField_4 = uieditfield(app.ElasticmodulusPanel_4, 'numeric');
            app.GEditField_4Label = uilabel(app.ElasticmodulusPanel_4);
            app.GEditField_4Label.Text = 'G';
            app.GEditField_4 = uieditfield(app.ElasticmodulusPanel_4, 'numeric');
            app.FEditField_4Label = uilabel(app.ElasticmodulusPanel_4);
            app.FEditField_4Label.Text = 'F';
            app.FEditField_4 = uieditfield(app.ElasticmodulusPanel_4, 'numeric');
            app.PaLabel_18 = uilabel(app.ElasticmodulusPanel_4);
            app.PaLabel_18.Text = '(Pa)';
            app.PaLabel_19 = uilabel(app.ElasticmodulusPanel_4);
            app.PaLabel_19.Text = '(Pa)';
            app.PaLabel_20 = uilabel(app.ElasticmodulusPanel_4);
            app.PaLabel_20.Text = '(Pa)';
            app.StrengthparametersPanel_4 = uipanel(app.thPanel_3);
            app.StrengthparametersPanel_4.Title = 'Strength parameters';
            app.cEditField_4Label = uilabel(app.StrengthparametersPanel_4);
            app.cEditField_4Label.Text = 'c''';
            app.cEditField_4 = uieditfield(app.StrengthparametersPanel_4, 'numeric');
            app.EditField_10Label = uilabel(app.StrengthparametersPanel_4);
            app.EditField_10Label.Text = 'φ''';
            app.EditField_10 = uieditfield(app.StrengthparametersPanel_4, 'numeric');
            app.bEditField_4Label = uilabel(app.StrengthparametersPanel_4);
            app.bEditField_4Label.Text = 'φ_b';
            app.bEditField_4 = uieditfield(app.StrengthparametersPanel_4, 'numeric');
            app.PaLabel_27 = uilabel(app.StrengthparametersPanel_4);
            app.PaLabel_27.Text = '(Pa)';
            app.Label_8 = uilabel(app.StrengthparametersPanel_4);
            app.Label_8.Text = '(°)';
            app.Label_9 = uilabel(app.StrengthparametersPanel_4);
            app.Label_9.Text = '(°)';
            app.UnitweightofsoilPanel_4 = uipanel(app.thPanel_3);
            app.UnitweightofsoilPanel_4.Title = 'Unit weight of soil ';
            app.sNm3EditField_4Label = uilabel(app.UnitweightofsoilPanel_4);
            app.sNm3EditField_4Label.Text = 'γ_s (N/m^3)';
            app.sNm3EditField_4 = uieditfield(app.UnitweightofsoilPanel_4, 'numeric');
            app.thPanel_4 = uipanel(app.Panel_10);
            app.thPanel_4.Title = '5 th';
            app.StressapproachPanel_5 = uipanel(app.thPanel_4);
            app.StressapproachPanel_5.Title = 'Stress approach';
            app.Switch_5 = uiswitch(app.StressapproachPanel_5, 'slider');
            app.Switch_5.Items = {'Effective Stress', 'Independent Variables'};
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.Switch_5.Value = 'Effective Stress';
            app.EffectivestressparameterButtonGroup_5 = uibuttongroup(app.StressapproachPanel_5);
            app.EffectivestressparameterButtonGroup_5.SelectionChangedFcn = createCallbackFcn(app, @EffectivestressparameterButtonGroup_5SelectionChanged, true);
            app.EffectivestressparameterButtonGroup_5.Title = 'Effective stress parameter, χ';
            app.Option1Button_5 = uiradiobutton(app.EffectivestressparameterButtonGroup_5);
            app.Option1Button_5.Text = 'Option 1';
            app.Option1Button_5.Value = true;
            app.Option2Button_5 = uiradiobutton(app.EffectivestressparameterButtonGroup_5);
            app.Option2Button_5.Text = 'Option 2';
            app.Option3Button_5 = uiradiobutton(app.EffectivestressparameterButtonGroup_5);
            app.Option3Button_5.Text = 'Option 3';
            app.EditField_16 = uieditfield(app.EffectivestressparameterButtonGroup_5, 'numeric');
            app.Image29_5 = uiimage(app.StressapproachPanel_5);
            app.Image29_5.ImageSource = 'effectivestress.png';
            app.AirentrysuctionSeEditField_5Label = uilabel(app.StressapproachPanel_5);
            app.AirentrysuctionSeEditField_5Label.Text = 'Air entry suction, Se';
            app.AirentrysuctionSeEditField_5 = uieditfield(app.StressapproachPanel_5, 'numeric');
            app.PaLabel_24 = uilabel(app.StressapproachPanel_5);
            app.PaLabel_24.Text = '(Pa)';
            app.ElasticmodulusPanel_5 = uipanel(app.thPanel_4);
            app.ElasticmodulusPanel_5.Title = 'Elastic modulus';
            app.EEditField_5Label = uilabel(app.ElasticmodulusPanel_5);
            app.EEditField_5Label.Text = 'E';
            app.EEditField_5 = uieditfield(app.ElasticmodulusPanel_5, 'numeric');
            app.GEditField_5Label = uilabel(app.ElasticmodulusPanel_5);
            app.GEditField_5Label.Text = 'G';
            app.GEditField_5 = uieditfield(app.ElasticmodulusPanel_5, 'numeric');
            app.FEditField_5Label = uilabel(app.ElasticmodulusPanel_5);
            app.FEditField_5Label.Text = 'F';
            app.FEditField_5 = uieditfield(app.ElasticmodulusPanel_5, 'numeric');
            app.PaLabel_9 = uilabel(app.ElasticmodulusPanel_5);
            app.PaLabel_9.Text = '(Pa)';
            app.PaLabel_10 = uilabel(app.ElasticmodulusPanel_5);
            app.PaLabel_10.Text = '(Pa)';
            app.PaLabel_11 = uilabel(app.ElasticmodulusPanel_5);
            app.PaLabel_11.Text = '(Pa)';
            app.StrengthparametersPanel_5 = uipanel(app.thPanel_4);
            app.StrengthparametersPanel_5.Title = 'Strength parameters';
            app.cEditField_5Label = uilabel(app.StrengthparametersPanel_5);
            app.cEditField_5Label.Text = 'c''';
            app.cEditField_5 = uieditfield(app.StrengthparametersPanel_5, 'numeric');
            app.EditField_11Label = uilabel(app.StrengthparametersPanel_5);
            app.EditField_11Label.Text = 'φ''';
            app.EditField_11 = uieditfield(app.StrengthparametersPanel_5, 'numeric');
            app.bEditField_5Label = uilabel(app.StrengthparametersPanel_5);
            app.bEditField_5Label.Text = 'φ_b';
            app.bEditField_5 = uieditfield(app.StrengthparametersPanel_5, 'numeric');
            app.PaLabel_28 = uilabel(app.StrengthparametersPanel_5);
            app.PaLabel_28.Text = '(Pa)';
            app.Label_10 = uilabel(app.StrengthparametersPanel_5);
            app.Label_10.Text = '(°)';
            app.Label_11 = uilabel(app.StrengthparametersPanel_5);
            app.Label_11.Text = '(°)';
            app.UnitweightofsoilPanel_5 = uipanel(app.thPanel_4);
            app.UnitweightofsoilPanel_5.Title = 'Unit weight of soil  ';
            app.sNm3EditField_5Label = uilabel(app.UnitweightofsoilPanel_5);
            app.sNm3EditField_5Label.Text = 'γ_s (N/m^3)';
            app.sNm3EditField_5 = uieditfield(app.UnitweightofsoilPanel_5, 'numeric');
            app.SimulationConditionsTab = uitab(app.TabGroup);
            app.SimulationConditionsTab.Title = 'Simulation Conditions';
            app.InitialconditionwaterheadprofileButtonGroup = uibuttongroup(app.SimulationConditionsTab);
            app.InitialconditionwaterheadprofileButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InitialconditionwaterheadprofileButtonGroupSelectionChanged, true);
            app.InitialconditionwaterheadprofileButtonGroup.Title = 'Initial condition (water head profile)';
            app.FunctionButton = uiradiobutton(app.InitialconditionwaterheadprofileButtonGroup);
            app.FunctionButton.Text = 'Function';
            app.ConstantButton = uiradiobutton(app.InitialconditionwaterheadprofileButtonGroup);
            app.ConstantButton.Text = 'Constant';
            app.ConstantButton.Value = true;
            app.constantEditField = uieditfield(app.InitialconditionwaterheadprofileButtonGroup, 'text');
            app.constantEditField.Value = '-1';
            app.functionEditField = uieditfield(app.InitialconditionwaterheadprofileButtonGroup, 'text');
            app.functionEditField.Value = '-(0.4111*Z + 0.5035)';
            app.ViewinitialconditonPanel = uipanel(app.SimulationConditionsTab);
            app.ViewinitialconditonPanel.Title = 'View initial conditon';
            app.PlotInitialButton = uibutton(app.ViewinitialconditonPanel, 'push');
            app.PlotInitialButton.ButtonPushedFcn = createCallbackFcn(app, @PlotInitialButtonPushed, true);
            app.PlotInitialButton.Text = 'Plot';
            app.UIInitial = uiaxes(app.ViewinitialconditonPanel);
            title(app.UIInitial, 'Initial condition')
            xlabel(app.UIInitial, 'Water head hw/m')
            ylabel(app.UIInitial, 'Elevation Z/m')
            app.FlowBCButtonGroup = uibuttongroup(app.SimulationConditionsTab);
            app.FlowBCButtonGroup.Title = 'Flow BC';
            app.ButtonGroup = uibuttongroup(app.FlowBCButtonGroup);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.FluxonthetopButton = uiradiobutton(app.ButtonGroup);
            app.FluxonthetopButton.Text = 'Flux on the top';
            app.FluxonthetopButton.Value = true;
            app.PressureonthetopButton = uiradiobutton(app.ButtonGroup);
            app.PressureonthetopButton.Text = 'Pressure on the top';
            app.ButtonGroup_2 = uibuttongroup(app.FlowBCButtonGroup);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.FluxinthebottomButton = uiradiobutton(app.ButtonGroup_2);
            app.FluxinthebottomButton.Text = 'Flux in the bottom';
            app.PressureinthebottomButton = uiradiobutton(app.ButtonGroup_2);
            app.PressureinthebottomButton.Text = 'Pressure in the bottom';
            app.PressureinthebottomButton.Value = true;
            app.Image30 = uiimage(app.FlowBCButtonGroup);
            app.Image30.ImageSource = 'BC_Top.png';
            app.Image31 = uiimage(app.FlowBCButtonGroup);
            app.Image31.ImageSource = 'BC_Bottom.png';
            app.prescribedwaterheadhw1EditFieldLabel = uilabel(app.FlowBCButtonGroup);
            app.prescribedwaterheadhw1EditFieldLabel.Text = 'prescribed water head,hw1';
            app.prescribedwaterheadhw1EditField = uieditfield(app.FlowBCButtonGroup, 'numeric');
            app.StressBCPanel = uipanel(app.FlowBCButtonGroup);
            app.StressBCPanel.Title = 'Stress BC';
            app.Tree = uitree(app.StressBCPanel);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged2, true);
            app.HydraulogicloadingNode = uitreenode(app.Tree);
            app.HydraulogicloadingNode.Text = 'Hydraulogic loading';
            app.Constant_WNode = uitreenode(app.HydraulogicloadingNode);
            app.Constant_WNode.Text = 'Constant_W';
            app.Timehistory_WNode = uitreenode(app.HydraulogicloadingNode);
            app.Timehistory_WNode.Text = 'Time history_W';
            app.NormalstressNode = uitreenode(app.Tree);
            app.NormalstressNode.Text = 'Normal stress';
            app.Constant_VNode = uitreenode(app.NormalstressNode);
            app.Constant_VNode.Text = 'Constant_V';
            app.Timehistory_VNode = uitreenode(app.NormalstressNode);
            app.Timehistory_VNode.Text = 'Time history_V';
            app.TangentialstressNode = uitreenode(app.Tree);
            app.TangentialstressNode.Text = 'Tangential stress';
            app.Constant_HNode = uitreenode(app.TangentialstressNode);
            app.Constant_HNode.Text = 'Constant_H';
            app.Timehistory_HNode_2 = uitreenode(app.TangentialstressNode);
            app.Timehistory_HNode_2.Text = 'Time history_H';
            app.LoadingtypeLabel = uilabel(app.StressBCPanel);
            app.LoadingtypeLabel.Text = 'Loading type';
            app.InputloadingLabel = uilabel(app.StressBCPanel);
            app.InputloadingLabel.Text = 'Input loading';
            app.ConstantloadingPanel = uipanel(app.StressBCPanel);
            app.ConstantloadingPanel.Title = 'Constant loading';
            app.RainfallIntensitymsEditField_3Label = uilabel(app.ConstantloadingPanel);
            app.RainfallIntensitymsEditField_3Label.Text = 'Rainfall Intensity (m/s)';
            app.RainfallIntensitymsEditField_2 = uieditfield(app.ConstantloadingPanel, 'numeric');
            app.NormalStresskPaEditField_2Label = uilabel(app.ConstantloadingPanel);
            app.NormalStresskPaEditField_2Label.Text = 'Normal Stress (kPa)';
            app.NormalStresskPaEditField = uieditfield(app.ConstantloadingPanel, 'numeric');
            app.ShearStresskPaEditField_2Label = uilabel(app.ConstantloadingPanel);
            app.ShearStresskPaEditField_2Label.Text = 'Shear Stress (kPa)';
            app.ShearStresskPaEditField = uieditfield(app.ConstantloadingPanel, 'numeric');
            app.TimehistoryloadingPanel = uipanel(app.StressBCPanel);
            app.TimehistoryloadingPanel.Title = 'Time history loading';
            app.LoadtimehistdataButton_2 = uibutton(app.TimehistoryloadingPanel, 'state');
            app.LoadtimehistdataButton_2.ValueChangedFcn = createCallbackFcn(app, @LoadtimehistdataButton_2ValueChanged, true);
            app.LoadtimehistdataButton_2.Text = 'Load time-hist data';
            app.RI_Table2 = uitable(app.TimehistoryloadingPanel);
            app.RI_Table2.ColumnName = {'Time'; 'Intensity'};
            app.RI_Table2.RowName = {};
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.SingleslopePanel_2 = uipanel(app.ResultsTab);
            app.SingleslopePanel_2.Title = 'Single slope';
            app.CouplingSwitchLabel = uilabel(app.SingleslopePanel_2);
            app.CouplingSwitchLabel.Text = 'Coupling effects';
            app.Tree2 = uitree(app.SingleslopePanel_2);
            app.Tree2.SelectionChangedFcn = createCallbackFcn(app, @Tree2SelectionChanged, true);
            app.LoadNode = uitreenode(app.Tree2);
            app.LoadNode.Text = 'Load';
            app.ProfilevariationNode = uitreenode(app.Tree2);
            app.ProfilevariationNode.Text = 'Profile variation';
            app.TimehistoryNode = uitreenode(app.Tree2);
            app.TimehistoryNode.Text = 'Time history';
            app.FactorofsafetyNode = uitreenode(app.Tree2);
            app.FactorofsafetyNode.Text = 'Factor of safety';
            app.CouplingSwitch = uiswitch(app.SingleslopePanel_2, 'slider');
            app.CouplingSwitch.ValueChangedFcn = createCallbackFcn(app, @CouplingSwitchValueChanged, true);
            app.Panel_13 = uipanel(app.SingleslopePanel_2);

            app.PlotButton = uibutton(app.Panel_13, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton.Text = 'Plot';
            app.VariablesDropDown_3 = uidropdown(app.Panel_13);
            app.VariablesDropDown_3.Items = {'water head_A', 'water head_B', 'Infiltration rate', 'Runoff', 'Cumulative infiltration', 'Cumulative Runoff'};
            app.VariablesDropDown_3.ValueChangedFcn = createCallbackFcn(app, @VariablesDropDown_3ValueChanged, true);
            app.VariablesDropDown_3.Value = 'water head_A';
            app.VariablesDropDown_4 = uidropdown(app.Panel_13);
            app.VariablesDropDown_4.Items = {'water head_A', 'water head_B', 'water head_C', 'Infiltration rate', 'Runoff', 'Cumulative infiltration', 'Cumulative Runoff'};
            app.VariablesDropDown_4.ValueChangedFcn = createCallbackFcn(app, @VariablesDropDown_4ValueChanged, true);
            app.VariablesDropDown_4.Value = 'water head_A';
            app.VariablesDropDown_5 = uidropdown(app.Panel_13);
            app.VariablesDropDown_5.Items = {'water head_A', 'water head_B', 'water head_C', 'water head_D', 'Infiltration rate', 'Runoff', 'Cumulative infiltration', 'Cumulative Runoff'};
            app.VariablesDropDown_5.ValueChangedFcn = createCallbackFcn(app, @VariablesDropDown_5ValueChanged, true);
            app.VariablesDropDown_5.Value = 'water head_A';
            app.VariablesDropDown_6 = uidropdown(app.Panel_13);
            app.VariablesDropDown_6.Items = {'water head_A', 'water head_B', 'water head_C', 'water head_D', 'water head_E', 'Infiltration rate', 'Runoff', 'Cumulative infiltration', 'Cumulative Runoff'};
            app.VariablesDropDown_6.ValueChangedFcn = createCallbackFcn(app, @VariablesDropDown_6ValueChanged, true);
            app.VariablesDropDown_6.Value = 'water head_A';
            app.VariablesDropDownLabel = uilabel(app.Panel_13);
            app.VariablesDropDownLabel.Text = 'Variables';
            app.VariablesDropDown = uidropdown(app.Panel_13);
            app.VariablesDropDown.Items = {'water head_A', 'Infiltration rate', 'Runoff', 'Cumulative infiltration', 'Cumulative Runoff'};
            app.VariablesDropDown.ValueChangedFcn = createCallbackFcn(app, @VariablesDropDownValueChanged, true);
            app.VariablesDropDown.Value = 'water head_A';
            app.Time_hist = uiaxes(app.Panel_13);
            title(app.Time_hist, '')
            xlabel(app.Time_hist, 'X')
            ylabel(app.Time_hist, 'Y')
            app.ClearfigureButton_3 = uibutton(app.Panel_13, 'push');
            app.ClearfigureButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearfigureButton_3Pushed, true);
            app.ClearfigureButton_3.Text = 'Clear figure';
            app.SaveresultsasmfileButton = uibutton(app.SingleslopePanel_2, 'push');
            app.SaveresultsasmfileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultsasmfileButtonPushed, true);
            app.SaveresultsasmfileButton.Text = 'Save results (as .m file)';
            app.Panel_14 = uipanel(app.SingleslopePanel_2);
            app.FS = uiaxes(app.Panel_14);
            title(app.FS, 'Factor of safety')
            xlabel(app.FS, 'X')
            ylabel(app.FS, 'Y')
            app.PlotButtonFS_SLIP = uibutton(app.Panel_14, 'push');
            app.PlotButtonFS_SLIP.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonFS_SLIPPushed, true);
            app.PlotButtonFS_SLIP.Text = 'Plot';
            app.ClearfigureButton_2 = uibutton(app.Panel_14, 'push');
            app.ClearfigureButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearfigureButton_2Pushed, true);
            app.ClearfigureButton_2.Text = 'Clear figure';
            app.Panel_15 = uipanel(app.SingleslopePanel_2);
            app.PlotButton_2 = uibutton(app.Panel_15, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.Rainfall = uiaxes(app.Panel_15);
            title(app.Rainfall, 'Rainfall')
            xlabel(app.Rainfall, 'X')
            ylabel(app.Rainfall, 'Y')
            app.Panel_16 = uipanel(app.SingleslopePanel_2);
            app.Profile_variation = uiaxes(app.Panel_16);
            title(app.Profile_variation, '')
            xlabel(app.Profile_variation, 'XLabel')
            ylabel(app.Profile_variation, 'Elevation(m)')
            app.PlotButton_3 = uibutton(app.Panel_16, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton_3.Text = 'Plot';
            app.VariablesDropDown_2Label = uilabel(app.Panel_16);
            app.VariablesDropDown_2Label.Text = 'Variables';
            app.VariablesDropDown_2 = uidropdown(app.Panel_16);
            app.VariablesDropDown_2.Items = {'Water head ', 'Degree of saturation', ''};
            app.VariablesDropDown_2.ValueChangedFcn = createCallbackFcn(app, @VariablesDropDown_2ValueChanged, true);
            app.VariablesDropDown_2.Value = 'Water head ';
            app.ClearfigureButton = uibutton(app.Panel_16, 'push');
            app.ClearfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ClearfigureButtonPushed, true);
            app.ClearfigureButton.Text = 'Clear figure';
            app.Panel_17 = uipanel(app.SingleslopePanel_2);
            app.LoaddataButton = uibutton(app.Panel_17, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.UITable = uitable(app.Panel_17);
            app.UITable.ColumnName = {'x1'; 'y1'; 'x2'; 'y2'; 'x3'; 'y3'; 'x4'; 'y4'};
            app.UITable.RowName = {};
            app.XDropDownLabel = uilabel(app.Panel_17);
            app.XDropDownLabel.Text = 'X';
            app.XDropDown = uidropdown(app.Panel_17);
            app.XDropDown.Items = {};
            app.XDropDown.ValueChangedFcn = createCallbackFcn(app, @XDropDownValueChanged, true);
            app.XDropDown.Value = {};
            app.YDropDownLabel = uilabel(app.Panel_17);
            app.YDropDownLabel.Text = 'Y';
            app.YDropDown = uidropdown(app.Panel_17);
            app.YDropDown.Items = {};
            app.YDropDown.ValueChangedFcn = createCallbackFcn(app, @YDropDownValueChanged, true);
            app.YDropDown.Value = {};
            app.FiguresDropDown = uidropdown(app.Panel_17);
            app.FiguresDropDown.Items = {'Which figure to plot data', 'Profile variation', 'Time history'};
            app.FiguresDropDown.ValueChangedFcn = createCallbackFcn(app, @FiguresDropDownValueChanged, true);
            app.FiguresDropDown.Value = 'Which figure to plot data';
            app.ClicktostartButton = uibutton(app.SingleslopePanel_2, 'push');
            app.ClicktostartButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktostartButtonPushed2, true);
            app.ClicktostartButton.Text = 'Click to start';
            app.LoadresultsButton_2 = uibutton(app.SingleslopePanel_2, 'push');
            app.LoadresultsButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadresultsButton_2Pushed, true);
            app.LoadresultsButton_2.Text = 'Load results';
            app.TerminateButton = uibutton(app.SingleslopePanel_2, 'push');
            app.TerminateButton.ButtonPushedFcn = createCallbackFcn(app, @TerminateButtonPushed, true);
            app.TerminateButton.Text = 'Terminate';
            app.CouplingSwitchLabel_3 = uilabel(app.SingleslopePanel_2);
            app.CouplingSwitchLabel_3.Text = 'Sink term';
            app.vegetation = uiswitch(app.SingleslopePanel_2, 'slider');
            app.vegetation.ValueChangedFcn = createCallbackFcn(app, @vegetationValueChanged, true);
            app.RegionalPanel_2 = uipanel(app.ResultsTab);
            app.RegionalPanel_2.Title = 'Regional';
            app.DEM = uiaxes(app.RegionalPanel_2);
            title(app.DEM, '')
            xlabel(app.DEM, 'X')
            ylabel(app.DEM, 'Y')
            app.Panel_18 = uipanel(app.RegionalPanel_2);
            app.ClicktostartButton_2 = uibutton(app.Panel_18, 'push');
            app.ClicktostartButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClicktostartButton_2Pushed, true);
            app.ClicktostartButton_2.Text = 'Click to start';
            app.LoadresultsButton = uibutton(app.Panel_18, 'push');
            app.LoadresultsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadresultsButtonPushed, true);
            app.LoadresultsButton.Text = 'Load results';
            app.SaveresultsButton = uibutton(app.Panel_18, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultsButtonPushed, true);
            app.SaveresultsButton.Text = 'Save results';
            app.Panel_19 = uipanel(app.Panel_18);
            app.TransfertorasterfileButton = uibutton(app.Panel_19, 'push');
            app.TransfertorasterfileButton.ButtonPushedFcn = createCallbackFcn(app, @TransfertorasterfileButtonPushed, true);
            app.TransfertorasterfileButton.Text = 'Transfer to .raster file';
            app.ExtractheaderButton = uibutton(app.Panel_19, 'push');
            app.ExtractheaderButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractheaderButtonPushed, true);
            app.ExtractheaderButton.Text = 'Extract header';
            app.ConvertasciitorasterLabel = uilabel(app.Panel_19);
            app.ConvertasciitorasterLabel.Text = 'Convert .ascii to .raster';
            app.FileDropDownLabel = uilabel(app.Panel_19);
            app.FileDropDownLabel.Text = 'File';
            app.FileDropDown = uidropdown(app.Panel_19);
            app.FileDropDown.Items = {'Please choose a file', 'Failure time', 'Failure depth', 'Cumulative IR', 'Cumulative Runoff'};
            app.FileDropDown.ValueChangedFcn = createCallbackFcn(app, @FileDropDownValueChanged, true);
            app.FileDropDown.Value = 'Please choose a file';
            app.Panel_20 = uipanel(app.Panel_18);
            app.LoadDEMButton = uibutton(app.Panel_20, 'push');
            app.LoadDEMButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDEMButtonPushed, true);
            app.LoadDEMButton.Text = 'Load DEM ';
            app.LoadsourceareaButton = uibutton(app.Panel_20, 'push');
            app.LoadsourceareaButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsourceareaButtonPushed, true);
            app.LoadsourceareaButton.Text = 'Load source area';
            app.LoadFailuretimeButton = uibutton(app.Panel_20, 'push');
            app.LoadFailuretimeButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFailuretimeButtonPushed, true);
            app.LoadFailuretimeButton.Text = 'Load Failure time';
            app.LoadFailuredepthButton = uibutton(app.Panel_20, 'push');
            app.LoadFailuredepthButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFailuredepthButtonPushed, true);
            app.LoadFailuredepthButton.Text = 'Load Failure depth';
            app.LoadgeorasterLabel = uilabel(app.Panel_20);
            app.LoadgeorasterLabel.Text = 'Load geo-raster ';
            app.LoadCumu_IRButton = uibutton(app.Panel_20, 'push');
            app.LoadCumu_IRButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCumu_IRButtonPushed, true);
            app.LoadCumu_IRButton.Text = 'Load Cumu_IR';
            app.LoadCumu_RunoffButton = uibutton(app.Panel_20, 'push');
            app.LoadCumu_RunoffButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCumu_RunoffButtonPushed, true);
            app.LoadCumu_RunoffButton.Text = 'Load Cumu_Runoff';
            app.ViewmapButton = uibutton(app.Panel_18, 'push');
            app.ViewmapButton.ButtonPushedFcn = createCallbackFcn(app, @ViewmapButtonPushed, true);
            app.ViewmapButton.Text = 'View map';
            app.CalculationLabel = uilabel(app.Panel_18);
            app.CalculationLabel.Text = 'Calculation';
            app.VisualizationLabel = uilabel(app.Panel_18);
            app.VisualizationLabel.Text = 'Visualization';
            app.DropDownLabel = uilabel(app.Panel_18);
            app.DropDownLabel.Text = '';
            app.DropDown = uidropdown(app.Panel_18);
            app.DropDown.Items = {'Please choose maps', 'Failure time', 'Failure depth', 'Cumulative IR', 'Cumulative Runoff'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Please choose maps';
            app.ClearmapButton = uibutton(app.Panel_18, 'push');
            app.ClearmapButton.ButtonPushedFcn = createCallbackFcn(app, @ClearmapButtonPushed, true);
            app.ClearmapButton.Text = 'Clear map';
            app.UIAxes = uiaxes(app.RegionalPanel_2);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.RegionalPanel_2);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.FEMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_v16

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FEMUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FEMUIFigure)
        end
    end
end